classdef particleSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LocationYEditField              matlab.ui.control.NumericEditField
        LocationYEditFieldLabel         matlab.ui.control.Label
        LocationXEditField              matlab.ui.control.NumericEditField
        LocationXEditFieldLabel         matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Z2AGauge                        matlab.ui.control.LinearGauge
        Z2AGaugeLabel                   matlab.ui.control.Label
        Z1AGauge                        matlab.ui.control.LinearGauge
        Z1AGaugeLabel                   matlab.ui.control.Label
        Y2AGauge                        matlab.ui.control.LinearGauge
        Y2AGaugeLabel                   matlab.ui.control.Label
        Y1AGauge                        matlab.ui.control.LinearGauge
        Y1AGaugeLabel                   matlab.ui.control.Label
        X2AGauge                        matlab.ui.control.LinearGauge
        X2AGaugeLabel                   matlab.ui.control.Label
        X1AGauge                        matlab.ui.control.LinearGauge
        X1AGaugeLabel                   matlab.ui.control.Label
        ZCoilsSwitch                    matlab.ui.control.Switch
        ZCoilsSwitchLabel               matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        YCoilsSwitch                    matlab.ui.control.Switch
        YCoilsSwitchLabel               matlab.ui.control.Label
        XCoilsSwitch                    matlab.ui.control.Switch
        XCoilsSwitchLabel               matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        particleFunctions
        simulationTimerProperty
        drawTimerProperty
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        testfunc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            numParticles = 10;
            %general simulation timer
            app.simulationTimerProperty = timer('Period',0.005,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
            app.drawTimerProperty = timer('Period',0.5,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
            app.particleArrayLocation = (-0.25 * app.UIAxes.XLim(2)) + (0.5 * app.UIAxes.XLim(2) * rand(numParticles, 2)); %start in the middle - remove the 0.25 bit for whole space
            app.particleArrayVelocity = zeros(numParticles, 2);
            app.particleArrayForce = zeros(numParticles, 2);
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
            app.lastUpdate = 86400 * now;
            app.fileID = fopen('test1.csv','w');
            app.testfunc = @(x,y) (y>(x + 1));
            disp(pwd + "\n");
            fprintf(app.fileID,'%s\n',pwd);
            
            start(app.simulationTimerProperty)
            start(app.drawTimerProperty)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'q'
                    if(app.XCoilsSwitch.Value == '1')
                        app.X1AGauge.Value = app.X1AGauge.Value + 0.05;
                    end
                case 'w'
                    if(app.XCoilsSwitch.Value == '1')
                        app.X1AGauge.Value = app.X1AGauge.Value - 0.05;
                    end
                case 'e'
                    if(app.XCoilsSwitch.Value == '1')
                        app.X2AGauge.Value = app.X2AGauge.Value + 0.05;
                    end
                case 'r'
                    if(app.XCoilsSwitch.Value == '1')
                        app.X2AGauge.Value = app.X2AGauge.Value - 0.05;
                    end
                case 'u'
                    if(app.YCoilsSwitch.Value == '1')
                        app.Y1AGauge.Value = app.Y1AGauge.Value + 0.05;
                    end
                case 'i'
                    if(app.YCoilsSwitch.Value == '1')
                        app.Y1AGauge.Value = app.Y1AGauge.Value - 0.05;
                    end
                case 'o'
                    if(app.YCoilsSwitch.Value == '1')
                        app.Y2AGauge.Value = app.Y2AGauge.Value + 0.05;
                    end
                case 'p'
                    if(app.YCoilsSwitch.Value == '1')
                        app.Y2AGauge.Value = app.Y2AGauge.Value - 0.05;
                    end
                case 'y'
                    app.X1AGauge.Value = 0;
                    app.X2AGauge.Value = 0;
                    app.Y1AGauge.Value = 0;
                    app.Y1AGauge.Value = 0;
                    app.Z1AGauge.Value = 0;
                    app.Z2AGauge.Value = 0;
                    app.particleArray.particleLocation = [0 0];
                    app.particleArray.particleVelocity = [0 0];
                    close all;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.simulationTimerProperty)
            stop(app.drawTimerProperty)
            fclose(app.fileID);
            close all;
            delete(app)
            disp("App Stopped")
        end

        % Value changed function: XCoilsSwitch
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1AGauge.Value = 0;
                app.X2AGauge.Value = 0;
            end
        end

        % Value changed function: YCoilsSwitch
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1AGauge.Value = 0;
                app.Y2AGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {167, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.XCoilsSwitchLabel = uilabel(app.LeftPanel);
            app.XCoilsSwitchLabel.Text = 'X Coils';
            app.XCoilsSwitch = uiswitch(app.LeftPanel, 'slider');
            app.XCoilsSwitch.Items = {'0', '1'};
            app.XCoilsSwitch.ValueChangedFcn = createCallbackFcn(app, @XCoilsSwitchValueChanged, true);
            app.XCoilsSwitch.Value = '1';
            app.YCoilsSwitchLabel = uilabel(app.LeftPanel);
            app.YCoilsSwitchLabel.Text = 'Y Coils';
            app.YCoilsSwitch = uiswitch(app.LeftPanel, 'slider');
            app.YCoilsSwitch.Items = {'0', '1'};
            app.YCoilsSwitch.ValueChangedFcn = createCallbackFcn(app, @YCoilsSwitchValueChanged, true);
            app.YCoilsSwitch.Value = '1';
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Value = 5e-07;
            app.ZCoilsSwitchLabel = uilabel(app.LeftPanel);
            app.ZCoilsSwitchLabel.Enable = 'off';
            app.ZCoilsSwitchLabel.Text = 'Z Coils';
            app.ZCoilsSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ZCoilsSwitch.Items = {'0', '1'};
            app.ZCoilsSwitch.Enable = 'off';
            app.ZCoilsSwitch.Value = '1';
            app.X1AGaugeLabel = uilabel(app.LeftPanel);
            app.X1AGaugeLabel.Text = 'X1 (A)';
            app.X1AGauge = uigauge(app.LeftPanel, 'linear');
            app.X1AGauge.Limits = [-1 1];
            app.X2AGaugeLabel = uilabel(app.LeftPanel);
            app.X2AGaugeLabel.Text = 'X2 (A)';
            app.X2AGauge = uigauge(app.LeftPanel, 'linear');
            app.X2AGauge.Limits = [-1 1];
            app.Y1AGaugeLabel = uilabel(app.LeftPanel);
            app.Y1AGaugeLabel.Text = 'Y1 (A)';
            app.Y1AGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1AGauge.Limits = [-1 1];
            app.Y2AGaugeLabel = uilabel(app.LeftPanel);
            app.Y2AGaugeLabel.Text = 'Y2 (A)';
            app.Y2AGauge = uigauge(app.LeftPanel, 'linear');
            app.Y2AGauge.Limits = [-1 1];
            app.Z1AGaugeLabel = uilabel(app.LeftPanel);
            app.Z1AGaugeLabel.Enable = 'off';
            app.Z1AGaugeLabel.Text = 'Z1 (A)';
            app.Z1AGauge = uigauge(app.LeftPanel, 'linear');
            app.Z1AGauge.Limits = [-1 1];
            app.Z1AGauge.Enable = 'off';
            app.Z2AGaugeLabel = uilabel(app.LeftPanel);
            app.Z2AGaugeLabel.Enable = 'off';
            app.Z2AGaugeLabel.Text = 'Z2 (A)';
            app.Z2AGauge = uigauge(app.LeftPanel, 'linear');
            app.Z2AGauge.Limits = [-1 1];
            app.Z2AGauge.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Value = 0.008;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Value = 1e-09;
            app.LocationXEditFieldLabel = uilabel(app.LeftPanel);
            app.LocationXEditFieldLabel.Text = 'LocationX';
            app.LocationXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LocationYEditFieldLabel = uilabel(app.LeftPanel);
            app.LocationYEditFieldLabel.Text = 'LocationY';
            app.LocationYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0101 0.0101];
            app.UIAxes.YLim = [-0.0101 0.0101];
            app.UIAxes.ZLim = [-0.0101 0.0101];
            app.UIAxes.Tag = 'particleWorskace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end