classdef particleSimulationParticipant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        X1AGaugeLabel                   matlab.ui.control.Label
        X1AGauge                        matlab.ui.control.LinearGauge
        X2AGaugeLabel                   matlab.ui.control.Label
        X2AGauge                        matlab.ui.control.LinearGauge
        Y1AGaugeLabel                   matlab.ui.control.Label
        Y1AGauge                        matlab.ui.control.LinearGauge
        Y2AGaugeLabel                   matlab.ui.control.Label
        Y2AGauge                        matlab.ui.control.LinearGauge
        Z1AGaugeLabel                   matlab.ui.control.Label
        Z1AGauge                        matlab.ui.control.LinearGauge
        Z2AGaugeLabel                   matlab.ui.control.Label
        Z2AGauge                        matlab.ui.control.LinearGauge
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ScenarioDropDownLabel           matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        PlayPauseButton                 matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        particleFunctions
        simulationTimerProperty
        drawTimerProperty
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        lineSegments
        polyLine
        endLine;
        startLine; %This could be removed if feedback shows it is confusing
        particlePoints
        loopComplete
        tMax
        fd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            numParticles = 50;
            %general simulation timer
            app.simulationTimerProperty = timer('Period',0.05,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
            app.drawTimerProperty = timer('Period',0.5,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = 86400 * now;
            app.fileID = fopen('test1.csv','w');
            disp(pwd + "\n");
            fprintf(app.fileID,'%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.lineSegments = LineSegments(app);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, numParticles);
            app.particleArrayVelocity = zeros(numParticles, 2);
            app.particleArrayForce = zeros(numParticles, 2); 
                        
            %TODO REMOVE THIS LINE
            app.polygon = app.polygon.change(3);
            
            hold (app.UIAxes, 'on');
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            app.startLine = plot(app.UIAxes,app.polygon.currentStartZone(:,1),app.polygon.currentStartZone(:,2), 'Color','y');
            
            app.fd = FlowData();
            
            count = 0;
            if(true) %set to false to prevent re-calculation of this each time
                point1s = app.polygon.currentPolyFlows(:,1:2);                
                point2s = app.polygon.currentPolyFlows(:,3:4);
                for i = 1:size(app.polygon.currentPolyFlows,1)
                    pointxs = [point1s(i,1);point2s(i,1)];
                    pointys = [point1s(i,2);point2s(i,2)];
                    plot(app.UIAxes,pointxs,pointys, "Color",'green');
                end
                flowMatrix = CreateFlow(app); %need to pass it the correct polygon?
                szfm = size(flowMatrix);      
                flowCount = 0;
                for i = 1:szfm(1)
                    for j = 1:szfm(2)
                        if(any(flowMatrix(i,j,:)))
                            flowCount = flowCount + 1;
                            location = [(i / szfm(1) * app.UIAxes.XLim(2) * 2) - app.UIAxes.XLim(2)  (j / szfm(1) * app.UIAxes.XLim(2) * 2) - app.UIAxes.XLim(2)];
                            plot(app.UIAxes, location(1), location(2), '.', "Color",'red');
                            count = count + 1;
                        end
                    end
                end
            end
            
            %start(app.simulationTimerProperty)
            %start(app.drawTimerProperty)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'q'
                        app.X1AGauge.Value = app.X1AGauge.Value + 0.05;
                case 'w'
                        app.X1AGauge.Value = app.X1AGauge.Value - 0.05;
                case 'e'
                        app.X2AGauge.Value = app.X2AGauge.Value + 0.05;
                case 'r'
                        app.X2AGauge.Value = app.X2AGauge.Value - 0.05;
                case 'u'
                        app.Y1AGauge.Value = app.Y1AGauge.Value + 0.05;
                case 'i'
                        app.Y1AGauge.Value = app.Y1AGauge.Value - 0.05;
                case 'o'
                        app.Y2AGauge.Value = app.Y2AGauge.Value + 0.05;
                case 'p'
                        app.Y2AGauge.Value = app.Y2AGauge.Value - 0.05;
                case 'y'
                    app.X1AGauge.Value = 0;
                    app.X2AGauge.Value = 0;
                    app.Y1AGauge.Value = 0;
                    app.Y1AGauge.Value = 0;
                    app.Z1AGauge.Value = 0;
                    app.Z2AGauge.Value = 0;
                    %app.particleArray.particleLocation = [0 0]; % TODO reset here....
                    %app.particleArray.particleVelocity = [0 0];
                    close all;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.simulationTimerProperty)
            stop(app.drawTimerProperty)
            fclose(app.fileID);
            close all;
            delete(app)
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1AGauge.Value = 0;
                app.X2AGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1AGauge.Value = 0;
                app.Y2AGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            %stop(app.simulationTimerProperty);
            %stop(app.drawTimerProperty);
            app.PlayPauseButton.Value = 0;
            app.PlayPauseButtonValueChanged([]);
            value = app.ScenarioDropDown.Value;
            if(value == "Experiment 1")
                app.polygon = app.polygon.change(2);
            else 
                if(value == "Experiment 2")
                    app.polygon = app.polygon.change(3);
                else 
                    if(value == "Experiment 3")
                        app.polygon = app.polygon.change(4);
                    else
                        app.polygon = app.polygon.change(1);
                    end
                end
            end
            delete(app.polyLine);
            delete(app.endLine);
            delete(app.startLine);
            hold (app.UIAxes, 'on');            
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            app.startLine = plot(app.UIAxes,app.polygon.currentStartZone(:,1),app.polygon.currentStartZone(:,2), 'Color','y');
            %reset particle position & velocity to start area
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, length(app.particleArrayLocation));
            app.particleArrayVelocity = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayForce = zeros(length(app.particleArrayLocation), 2);
            %start(app.simulationTimerProperty);
            %start(app.drawTimerProperty);
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if(value)
                set(app.PlayPauseButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = 86400 * now;
                start(app.simulationTimerProperty);
                start(app.drawTimerProperty);
            else
                set(app.PlayPauseButton,'BackgroundColor',[1,0.7,0.7]);
                stop(app.simulationTimerProperty);
                stop(app.drawTimerProperty);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Enable = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Enable = 'off';
            app.ParticleDiametermEditField.Value = 5e-07;
            app.X1AGaugeLabel = uilabel(app.LeftPanel);
            app.X1AGaugeLabel.Text = 'X1 (A)';
            app.X1AGauge = uigauge(app.LeftPanel, 'linear');
            app.X1AGauge.Limits = [-1 1];
            app.X2AGaugeLabel = uilabel(app.LeftPanel);
            app.X2AGaugeLabel.Text = 'X2 (A)';
            app.X2AGauge = uigauge(app.LeftPanel, 'linear');
            app.X2AGauge.Limits = [-1 1];
            app.Y1AGaugeLabel = uilabel(app.LeftPanel);
            app.Y1AGaugeLabel.Text = 'Y1 (A)';
            app.Y1AGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1AGauge.Limits = [-1 1];
            app.Y2AGaugeLabel = uilabel(app.LeftPanel);
            app.Y2AGaugeLabel.Text = 'Y2 (A)';
            app.Y2AGauge = uigauge(app.LeftPanel, 'linear');
            app.Y2AGauge.Limits = [-1 1];
            app.Z1AGaugeLabel = uilabel(app.LeftPanel);
            app.Z1AGaugeLabel.Enable = 'off';
            app.Z1AGaugeLabel.Text = 'Z1 (A)';
            app.Z1AGauge = uigauge(app.LeftPanel, 'linear');
            app.Z1AGauge.Limits = [-1 1];
            app.Z1AGauge.Enable = 'off';
            app.Z2AGaugeLabel = uilabel(app.LeftPanel);
            app.Z2AGaugeLabel.Enable = 'off';
            app.Z2AGaugeLabel.Text = 'Z2 (A)';
            app.Z2AGauge = uigauge(app.LeftPanel, 'linear');
            app.Z2AGauge.Limits = [-1 1];
            app.Z2AGauge.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Enable = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Enable = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Enable = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Enable = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Enable = 'off';
            app.FluidViscocityEditField.Value = 0.008;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Enable = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Enable = 'off';
            app.ParticleMasskgEditField.Value = 4e-09;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training', 'Experiment 1', 'Experiment 2', 'Experiment 3'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training';
            app.PlayPauseButton = uibutton(app.LeftPanel, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Tooltip = {'Play Pause Button'; 'Use to halt and restart the simulator'};
            app.PlayPauseButton.Text = 'Play / Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0101 0.0101];
            app.UIAxes.YLim = [-0.0101 0.0101];
            app.UIAxes.ZLim = [-0.0101 0.0101];
            app.UIAxes.Tag = 'particleWorskace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationParticipant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end