classdef particleSimulationParticipantSharedControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HapticForceSlider               matlab.ui.control.Slider
        HapticForceSliderLabel          matlab.ui.control.Label
        StartTrainingButton             matlab.ui.control.Button
        ScenarioEditField               matlab.ui.control.EditField
        ScenarioEditFieldLabel          matlab.ui.control.Label
        CentreMassViewCheckBox          matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ControllerButton                matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        KeyboardButton                  matlab.ui.control.RadioButton
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField            matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel       matlab.ui.control.Label
        MagForceRestrictMAM2EditField   matlab.ui.control.NumericEditField
        MagForceRestrictMAM2EditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TimeremainingsLabel             matlab.ui.control.Label
        TimeRemainingsEditField         matlab.ui.control.NumericEditField
        PercentageinGoalEditField       matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        simulationTimerProperty
        drawTimerProperty
        hapticFeedbackIdleProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        hapticFeedback
        testOrder
        maxForce
        QuestionaireFID
        writeToFile
        UseNetworkForHaptic
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rng(1000,"twister");
            app.writeToFile = true;
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05; %0.05 %Draw was 0.3
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 2.25;%1.6;
            app.printCounter = 0;
%              fourPolygonLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
%             fourMeshValuesLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\BenResult0_02.csv";
% %             fourPolygonLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
% %             fourMeshValuesLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\BenResult0_01.csv";
%             [locX,locY,flowX,flowY] = ReadInFlowDataFromComsolCSV(fourPolygonLocation, fourMeshValuesLocation);
%             saveME = [locX;locY]';
%             writematrix(saveME,'M.csv') 
        %   app.fd = FlowData([locX; locY]', [flowX; flowY]');
            app.fd = FlowData05();
            %general simulation timer
            app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
            app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
            app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            %app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "Controller";
            InitHaptic();
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0);
            app.timeLimit = 1;%120;
            app.testNumber = 0;
            app.rotation = 0;
            app.hapticFeedback = [0,0,0];
            %50 tests
            app.testOrder = 1:120;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.testOrder = [app.testOrder,length(app.testOrder)+1];
            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);
            
            app.UseNetworkForHaptic = true;

            %Now load up the python NN
            terminate(pyenv)
            try
                pyenv("Version","C:\Users\beno1\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
            catch
                try
                    pyenv("Version","C:\Users\bj20907\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                catch
                    pyenv("Version","C:\Users\Rumi\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                end
            end
            if count(py.sys.path,'') == 0
                insert(py.sys.path,int32(0),'');
            end
            pyrun("from pythonNNContstructor import *")
            pyrun("random.seed(1000)");
            %TODO do this bit by loading in a network instead - do we need
            %to make a new object, or can python just return one from
            %loading the network?
            nnnn = pyrun("NNet = keras.models.load_model('C:\\Users\\bj20907\\Documents\\GitHub\\SwarmSimulation\\BestModel')","NNet")
             %pyrun("NNet = keras.models.load_model('C:\\Users\\Rumi\\Documents\\GitHub\\SwarmSimulation\\BestModel')")
             %pyrun("NNet = IRLClass(numStates,numActions,hiddenLayers)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers);

%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
    %        app.mesh =  alphaShape(app.fd.FlowLocations{1});
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            if(false) %Set to true if needed.
                app.polygon = app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
            prompt = {'Age','Gender','Corrected Eyesight','Right/Left Handed'};
            dlgtitle = 'Statistical questions';
            answer = inputdlg(prompt,dlgtitle);
            app.QuestionaireFID = fopen("Questionaire" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(app.QuestionaireFID,strcat('\r\n Age,',answer{1}));
            fprintf(app.QuestionaireFID,strcat('\r\n Gender,',answer{2}));
            fprintf(app.QuestionaireFID,strcat('\r\n Corrected Eyesight,',answer{3}));
            fprintf(app.QuestionaireFID,strcat('\r\n Right/Left Handed,',answer{4}));
            %fclose(app.QuestionaireFID);

            %Reset the field positions because MATLAB can't seem to keep
            %them in the right place.
            pause(0.5);
            app.TimeRemainingsEditField.Position = [68,136,32,20];
            app.TimeRemainingsEditFieldLabel.Position = [68,95,85,42];
            app.PercentageinGoalEditField.Position = [68,684,40,20];
            app.TimeremainingsLabel.Position = [68,643,74,42];
            pause(0.5);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            value = app.MagForceRestrictMAM2EditField.Value;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                stop(app.hapticFeedbackIdleProperty);
                set(app.StartTrainingButton,'Text',"Running...");
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                start(app.simulationTimerProperty);
                start(app.drawTimerProperty);
                app.StartTrainingButton.Enable = false;
            else
                addValue = 0.1;
                maxValue = 2.3;
                if value ~= 0
                    addValue = value;
                    maxValue = addValue;
                end
                maxValueNegative = maxValue * -1;
                switch key
                    case 'q'
                        if(app.X1MAGauge.Value < maxValue)
                            app.X1MAGauge.Value = app.X1MAGauge.Value + addValue;
                        end
                    case 'w'
                        if(app.X1MAGauge.Value > maxValueNegative)
                            app.X1MAGauge.Value = app.X1MAGauge.Value - addValue;
                        end
                    case 'e'
                        if(app.X2MAGauge.Value < maxValue)
                            app.X2MAGauge.Value = app.X2MAGauge.Value + addValue;
                        end
                    case 'r'
                        if(app.X2MAGauge.Value > maxValueNegative)
                            app.X2MAGauge.Value = app.X2MAGauge.Value - addValue;
                        end
                    case 'u'
                        if(app.Y1MAGauge.Value < maxValue)
                            app.Y1MAGauge.Value = app.Y1MAGauge.Value + addValue;
                        end
                    case 'i'
                        if(app.Y1MAGauge.Value > maxValueNegative)
                            app.Y1MAGauge.Value = app.Y1MAGauge.Value - addValue;
                        end
                    case 'o'
                        if(app.Y2MAGauge.Value < maxValue)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value + addValue;
                        end
                    case 'p'
                        if(app.Y2MAGauge.Value > maxValueNegative)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value - addValue;
                        end
                    case 'y'
                        app.X1MAGauge.Value = 0;
                        app.X2MAGauge.Value = 0;
                        app.Y1MAGauge.Value = 0;
                        app.Y1MAGauge.Value = 0;
                        app.Z1MAGauge.Value = 0;
                        app.Z2MAGauge.Value = 0;
                        close all;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.hapticFeedbackIdleProperty)
            stop(app.simulationTimerProperty)
            stop(app.drawTimerProperty)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
               CloseHaptic();
            end
            fclose(app.fileID);
            close all;

            prompt = {'Did you understand the task? 1(Not at all) - 5(Completely)','How difficult was the experiment? 1(Easy)-5(Difficult)','Did you have good control over where the majority of particles went? 1(None)-5(Complete)','Any other comments?'};
            dlgtitle = 'Statistical questions';
            answer = inputdlg(prompt,dlgtitle);
            fprintf(app.QuestionaireFID,strcat('\r\n Did you understand the task? 1(Not at all) - 5(Completely),',answer{1}));
            fprintf(app.QuestionaireFID,strcat('\r\n How difficult was the experiment? 1(Easy)-5(Difficult),',answer{1}));
            fprintf(app.QuestionaireFID,strcat('\r\n Did you have good control over where the majority of particles went? 1(None)-5(Complete),',answer{1}));
            fprintf(app.QuestionaireFID,strcat('\r\n Any other comments?,',answer{1}));
            fclose(app.QuestionaireFID);

            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
%             app.StartTrainingButton.Value = 0;
%             app.StartTrainingButtonValueChanged([]);
%             delete(app.particlePoints);
%             value = app.ScenarioDropDown.Value;
%             app.MagForceRestrictMAM2EditField.Value = 0;
%             app.NumberofParticlesEditField.Value = 10;
%             app.FluidFlowmsEditField.Value = 0;
%             app.MagForceRestrictMAM2EditField.Value = 0;
%             fclose(app.fileID);
%             switch(value)
%                 case("Training 1")
%                     app.polygon = app.polygon.change(1);
%                     app.PercentageinGoalEditField.Value = 120;
%                 case("Training 2")
%                     app.polygon = app.polygon.change(2);
%                     app.PercentageinGoalEditField.Value = 120;
%                 case("Test 1")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 40;
%                     app.FluidFlowmsEditField.Value = 0.005;
%                     app.PercentageinGoalEditField.Value = 60;
%                     app.MagForceRestrictMAM2EditField.Value = 0;
%                 case("Test 2")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.01;
%                     app.PercentageinGoalEditField.Value = 45;
%                     app.MagForceRestrictMAM2EditField.Value = 0;
%                 case("Test 3")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 40;
%                     app.FluidFlowmsEditField.Value = 0.01;
%                     app.PercentageinGoalEditField.Value = 45;
%                     app.MagForceRestrictMAM2EditField.Value = 0;
%                 case("Test 4")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.timeLimit = 30;
%                     app.MagForceRestrictMAM2EditField.Value = 1.5;
%                 case("Test 5")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.PercentageinGoalEditField.Value = 15;
%                     app.MagForceRestrictMAM2EditField.Value = 0;
%                 case("Test 6")
%                     app.polygon = app.polygon.change(2);
%                     app.MagForceRestrictMAM2EditField.Value = 0.75;
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.PercentageinGoalEditField.Value = 15;
%                     app.MagForceRestrictMAM2EditField.Value = 1.5;
%                 case("Test 7")
%                     app.polygon = app.polygon.change(2);
%                     app.MagForceRestrictMAM2EditField.Value = 1.5;
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.PercentageinGoalEditField.Value = 15;
%                     app.MagForceRestrictMAM2EditField.Value = 1;
%                 otherwise
%                     app.polygon = app.polygon.change(1);
%             end
% 
%             app.timeLimit = app.PercentageinGoalEditField.Value;
%             app.numParticles = app.NumberofParticlesEditField.Value;
%             app.previousMagforce = 0;
%             app.lastUpdate = clock;
%             newFileName = value + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
%             app.fileID = fopen(newFileName,'w');
%             app.loopComplete = true;            
%             app.particlePoints = plot(app.UIAxes,0,0);
%             app.tMax = 1;
%             app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
%             app.particleArrayVelocity = zeros(app.numParticles, 2);
%             app.particleArrayForce = zeros(app.numParticles, 2);
%             app.particleArrayPreviousLocation = app.particleArrayLocation;
%             app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
%             app.particleArrayBaseVelocityStartTime = clock;
%             app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
%             app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
%             hold (app.UIAxes, 'on');
%             delete(app.polyLine);
%             delete(app.endLine);
%             app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
%             app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(1,:,1),app.polygon.currentEndZone(1,:,2), 'Color','g');
%             app.fd = FlowData();
%             app.haltParticlesInEndZone = zeros(app.numParticles,1);
%             app.currentlyDoingWorkSemaphore = false;
%             app.timePassed = 0;
%             app.timestep = 0;
%             app.timeLag = 0;
%             app.mousePosition = [0 0];
%             app.magLine = plot(app.UIAxes,0,0);
% 
%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingOLDButtonValueChanged(app, event)
           % value = app.StartTrainingButton.Value;
            %delete(app.magLine);
           % if(value)
                stop(app.hapticFeedbackIdleProperty);
                set(app.StartTrainingButton,'Text',"Running...");
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                start(app.simulationTimerProperty);
                start(app.drawTimerProperty);
                app.StartTrainingButton.Enable = false;
           % else
%                 stop(app.simulationTimerProperty);
%                 stop(app.drawTimerProperty);
%                 set(app.StartTrainingButton,'Text',"Start Next Level");
%                 set(app.StartTrainingButton,'BackgroundColor',[1,0.7,0.7]);
%                 app.testNumber = app.testNumber + 1;
%                 NextLevel(app);
           % end
        end

        % Value changed function: FluidFlowmsEditField
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData();
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Value changed function: MagForceRestrictMAM2EditField
        function MagForceRestrictMAM2EditFieldValueChanged(app, event)
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            CloseHaptic();
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
                    InitHaptic();
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
              if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictMAM2EditField.Value > 0 && app.MagForceRestrictMAM2EditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictMAM2EditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1MAGauge.Value = app.mousePosition(1) .* 250;
                app.Y1MAGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Value changed function: HapticForceSlider
        function HapticForceSliderValueChanged(app, event)
            %value = app.HapticForceSlider.Value;
            app.HapticForceSlider.Value = round(app.HapticForceSlider.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Visible = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Editable = 'off';
            app.ParticleDiametermEditField.Visible = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-1.75 1.75];
            app.X1MAGauge.MajorTicks = [-1.75 0 1.75];
            app.X1MAGauge.MajorTickLabels = {'-1.75', '.', '1.75'};
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-1.75 1.75];
            app.Y1MAGauge.MajorTicks = [-1.75 0 1.75];
            app.Y1MAGauge.MajorTickLabels = {'-1.75', '.', '1.75'};
            app.Y1MAGauge.MinorTicks = [-1.75 -1.5 -1 -0.5 0 0.5 1 1.5 1.75];
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Editable = 'off';
            app.StaticFrictionCoefficientEditField.Visible = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Visible = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Editable = 'off';
            app.MotionFrictionCoefficientEditField.Visible = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Visible = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Visible = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Visible = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Editable = 'off';
            app.ParticleMasskgEditField.Visible = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.MagForceRestrictMAM2EditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictMAM2EditFieldLabel.Enable = 'off';
            app.MagForceRestrictMAM2EditFieldLabel.Text = 'MagForceRestrict (MA/M^2)';
            app.MagForceRestrictMAM2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictMAM2EditField.ValueChangedFcn = createCallbackFcn(app, @MagForceRestrictMAM2EditFieldValueChanged, true);
            app.MagForceRestrictMAM2EditField.Editable = 'off';
            app.MagForceRestrictMAM2EditField.Enable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Enable = 'off';
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowmsEditFieldValueChanged, true);
            app.FluidFlowmsEditField.Editable = 'off';
            app.FluidFlowmsEditField.Enable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofParticlesEditFieldLabel.Enable = 'off';
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Enable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.LeftPanel);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.HapticForceSliderLabel = uilabel(app.LeftPanel);
            app.HapticForceSliderLabel.Enable = 'off';
            app.HapticForceSliderLabel.Visible = 'off';
            app.HapticForceSliderLabel.Text = 'Haptic Force';
            app.HapticForceSlider = uislider(app.LeftPanel);
            app.HapticForceSlider.Limits = [1 15];
            app.HapticForceSlider.MajorTicks = [1 2 3 4];
            app.HapticForceSlider.ValueChangedFcn = createCallbackFcn(app, @HapticForceSliderValueChanged, true);
            app.HapticForceSlider.MinorTicks = [1 2 3 4];
            app.HapticForceSlider.Enable = 'off';
            app.HapticForceSlider.Visible = 'off';
            app.HapticForceSlider.Value = 9;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationParticipantSharedControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end