classdef particleSimulationREPLAYParticipant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FluidViscocityEditField        matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel   matlab.ui.control.Label
        MagneticFieldmTEditField       matlab.ui.control.NumericEditField
        MagneticFieldmTEditFieldLabel  matlab.ui.control.Label
        ParticleDensitygmlEditField    matlab.ui.control.NumericEditField
        ParticleDensitygmlEditFieldLabel  matlab.ui.control.Label
        IndividualDiameterEditField    matlab.ui.control.NumericEditField
        IndividualDiameterEditFieldLabel  matlab.ui.control.Label
        ChainLengthEditField           matlab.ui.control.NumericEditField
        ChainLengthEditFieldLabel      matlab.ui.control.Label
        HapticForceSlider              matlab.ui.control.Slider
        HapticForceSliderLabel         matlab.ui.control.Label
        PausedButton                   matlab.ui.control.Button
        ScenarioEditField              matlab.ui.control.EditField
        ScenarioEditFieldLabel         matlab.ui.control.Label
        CentreMassViewCheckBox         matlab.ui.control.CheckBox
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ControllerButton               matlab.ui.control.RadioButton
        MouseButton                    matlab.ui.control.RadioButton
        KeyboardButton                 matlab.ui.control.RadioButton
        NumberofParticlesEditField     matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField           matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel      matlab.ui.control.Label
        MagForceRestrictTmEditField    matlab.ui.control.NumericEditField
        MagForceRestrictTmEditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown               matlab.ui.control.DropDown
        ScenarioDropDownLabel          matlab.ui.control.Label
        Y1TmGauge                      matlab.ui.control.LinearGauge
        Y1TmGaugeLabel                 matlab.ui.control.Label
        X1TmGauge                      matlab.ui.control.LinearGauge
        X1TmGaugeLabel                 matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TimeremainingsLabel            matlab.ui.control.Label
        TimeRemainingsEditField        matlab.ui.control.NumericEditField
        PercentageinGoalEditField      matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)  
        simulationTimerProperty
        drawTimerProperty 
        hapticFeedbackIdleProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime 
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate 
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh 
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        hapticFeedback
        testOrder
        maxForce
        QuestionaireFID
        writeToFile
        UseNetworkForHaptic
        MSat
        CeffMotion
        CeffStat 
        EquivalentDiameter
        askQuestionaire
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Perform setup of global vars
            app.askQuestionaire = false; %Set to true for actual exp
            app.testOrder = 1:240; %35 of each test

            rng(1000,"twister");
            app.writeToFile = true;
            app.NumberofParticlesEditField.Value = 500;
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05;
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 1;%2.25;%1.6;
            app.printCounter = 0;
            app.fd = FlowData05();

            app.ParticleDensitygmlEditField.Value = 4.8;
            app.IndividualDiameterEditField.Value = 75 *10^-9;
            app.CeffMotion = 0.00003;
            app.CeffStat = 0.000135;

            %general simulation timer
           app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
           app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
           app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.MagneticFieldmTEditField.Value, app.IndividualDiameterEditField.Value, app.ParticleDensitygmlEditField.Value, app.ChainLengthEditField.Value, app.FluidViscocityEditField.Value, app.CeffStat, app.CeffMotion, app.UIAxes.XLim(2));
            app.lastUpdate = clock;
       %     newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
       %     app.fileID = fopen(newFileName,'w');
            app.fileID = -1;
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "Controller";
            InitHaptic(); 
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0); 
            app.timeLimit = 1;%120; 
            app.testNumber = 0;
            app.rotation = 0;
            app.hapticFeedback = [0,0,0];            

            %Uncomment for random order!
            app.testOrder = app.testOrder(randperm(length(app.testOrder)));

            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);
            app.UseNetworkForHaptic = false;
            %Mesh used for fluid flow
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});


            %fluid flow mesh written out to file - only do if required.
            if(false) %Set to true if needed.
                app.polygon = app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
             

            if(app.askQuestionaire)
                %Prompt the user for statistical information
                prompt = {'Age','Gender','Corrected Eyesight','Right/Left Handed'};
                dlgtitle = 'Statistical questions';
                answer = inputdlg(prompt,dlgtitle);
                app.QuestionaireFID = fopen("Questionaire" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
                fprintf(app.QuestionaireFID,strcat('\r\n Age,',answer{1}));
                fprintf(app.QuestionaireFID,strcat('\r\n Gender,',answer{2}));
                fprintf(app.QuestionaireFID,strcat('\r\n Corrected Eyesight,',answer{3}));
                fprintf(app.QuestionaireFID,strcat('\r\n Right/Left Handed,',answer{4}));
                %fclose(app.QuestionaireFID);
            end

            %Reset the field positions because MATLAB can't seem to keep
            %them in the right place.
            pause(0.5);
            app.TimeRemainingsEditField.Position = [68,136,32,20];
            app.TimeRemainingsEditFieldLabel.Position = [68,95,85,42];
            app.PercentageinGoalEditField.Position = [68,684,40,20];
            app.TimeremainingsLabel.Position = [68,643,74,42];
            pause(0.5);

            %start(app.hapticFeedbackIdleProperty)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %Keyboard input. This is limited to space to start the level,
            %or movement keys if that control method is selected
            key = event.Key;
            value = app.MagForceRestrictTmEditField.Value;
             if((key == 'space') & (app.PausedButton.Text == "Paused"))
                 % if(app.testNumber == 0)
                 %     %Set this value to start from the middle of the test
                 %     %app.testNumber = 80;
                 %     app.testNumber = app.testNumber + 1;
                 %     NextLevel(app);
                 % end
                %Prepare and start the first level. "NextLevel" sorts the
                %changes from this point on. 
               stop(app.hapticFeedbackIdleProperty);
                set(app.PausedButton,'Text',"Running...");
                app.startTime = clock;
                set( app.PausedButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
             %   start(app.simulationTimerProperty);
             %   start(app.drawTimerProperty);
                app.PausedButton.Enable = false;    


                %Reset
                app.controlMethod = "TrainingModel";
                rng(1000,"twister");
                delete(app.particlePoints);
                if(app.fileID ~= -1)
                    fclose(app.fileID);
                end
                app.goalIndex = 4;
                app.rotation = 0; 
                app.NumberofParticlesEditField.Value = 500; %Sets the visual number
                app.numParticles = app.NumberofParticlesEditField.Value; %Set using the visual number
                app.MagForceRestrictTmEditField.Value = 1;
                app.hapticFeedback = [0,0,0];
                app.slowDown = 1;
                app.PercentageinGoalEditField.Value = 0;
                
                %Now need to set fluid velocity, find DataStoreLocation (File to be used).
                %Locate FileToUse
                DataStoreLocation = "F:\OneDrive - University of Essex\ExperimentalData\Feb24\results\NormalSorted\FD10\Test0010_14_12_results.csv";
                if(DataStoreLocation == "")
                    "Choose a Data File to read"
                    [DataStoreFileName,DataStoreLocation] = uigetfile('*.csv');
                    DataStoreLocation = fullfile(DataStoreLocation,DataStoreFileName);
                end
                flowRate = (split(string(DataStoreLocation),'\'));
                flowag = flowRate(end-1);
                if(strlength(flowag)>1)
                    flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
                else
                    flowRateNormalised = str2double(flowag);
                end
                if(isnan(flowRateNormalised))
                    flowRateNormalised = realmin.*6;
                    "There was some error with reading the flow rate - this data is bad..."
                end
                flowRateNormalised = flowRateNormalised./60; %Check this translates between matlab code and actual data - determines the flow velocities used to train with
                switch(flowRateNormalised)
                    case(1/6)
                        app.fd = FlowData10();
                    case(2/6)
                        app.fd = FlowData20();
                    case(3/6)
                        app.fd = FlowData30();
                    case(4/6)
                        app.fd = FlowData40();
                    case(5/6)
                        app.fd = FlowData50();
                    case(6/6)
                        app.fd = FlowData60();
                end

                %Now can continue with reset
                app.FluidFlowmsEditField.Value = 10;
                app.MagForceRestrictTmEditField.Value = 0.5;%1-(scenario./10);    
                app.ChainLengthEditField.Value = 12 .* app.MagForceRestrictTmEditField.Value.*1000 + 5000;
                app.polygon = app.polygon.change(4,app.fd);
            
                %file operations and reset runtime vars
                minTimeToTravel = 10;
                app.TimeRemainingsEditField.Value = minTimeToTravel .* 5;
                app.timeLimit = app.TimeRemainingsEditField.Value;    
                app.previousMagforce = 0;
                app.lastUpdate = clock;
                paddedTestNumber = sprintf( '%04d', app.testNumber);
                newFileName = "Test" + paddedTestNumber + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
                app.fileID = fopen(newFileName,'w');
                app.loopComplete = true;             
                app.tMax = 1;
                app.mesh = delaunayTriangulation(app.polygon.currentFlowLocations);

                %Now do particleLocations:
                %collect particleLocations
                fileToUse = fopen(DataStoreLocation);
                tline = fgetl(fileToUse);
                AllData = split(tline,',');
                positions = AllData(5);
                positions = strip(positions,'[');
                positions = strip(positions,']');
                allPositions = split(positions,';');
                yPos = str2double(split(allPositions," "));
                %Set locations and continue as normal
                app.particleArrayLocation = yPos;
                app.particleArrayVelocity = zeros(app.numParticles, 2);
                app.particleArrayForce = zeros(app.numParticles, 2);
                app.particleArrayPreviousLocation = app.particleArrayLocation;
                app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
                app.particleArrayBaseVelocityStartTime = clock;
                app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
                app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
                app.bouncedLastLoop = zeros(app.numParticles,1); 
                hold (app.UIAxes, 'on');
                delete(app.polyLine);
                delete(app.endLine);
                delete(app.wrongEndLine);
                rotateMatrix = [cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)];
                rotatedOutline = (rotateMatrix * app.polygon.currentPoly')';
                app.polyLine = plot(app.UIAxes, rotatedOutline(:,1), rotatedOutline(:,2), 'Color','b');
                app.UIAxes.XLim = [min(rotatedOutline(:,1)) + (min(rotatedOutline(:,1))/20), max(rotatedOutline(:,1)) + (max(rotatedOutline(:,1))/20)];
                app.UIAxes.YLim = [min(rotatedOutline(:,2)) + (min(rotatedOutline(:,2))/20), max(rotatedOutline(:,2)) + (max(rotatedOutline(:,2))/20)];

                %Now redo particleFunctions - this line's location is important
                app.particleFunctions = app.particleFunctions.ChangeMetaValues(1.25663706e-6, app.MagneticFieldmTEditField.Value, app.IndividualDiameterEditField.Value, app.ParticleDensitygmlEditField.Value, app.ChainLengthEditField.Value, app.FluidViscocityEditField.Value, app.CeffStat, app.CeffMotion, app.UIAxes.XLim(2));

                %EndZones - can't see a reason why these can't go higher up?
                for(lineCount = 1:length(app.polygon.currentEndZone))
                    rotatedEndZone = (rotateMatrix * squeeze(app.polygon.currentEndZone(lineCount,:,:))')';
                    app.wrongEndLine(lineCount) = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','r');
                    if(lineCount == app.goalIndex)
                        app.endLine = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','g');
                    end
                end 
                app.previousMagforce = 0;
                app.haltParticlesInEndZone = zeros(app.numParticles,1);
                app.currentlyDoingWorkSemaphore = false;
                app.timePassed = 0;
                app.timestep = 0;
                app.timeLag = 0; 
                app.mousePosition = [0 0];
                app.magLine = plot(app.UIAxes,0,0);


                %paddedTestNumber = sprintf( '%04d', app.testNumber);
                %newFileName = "Test" + paddedTestNumber + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
                %app.fileID = fopen(newFileName,'w');

                % hold (app.UIAxes, 'on');
                % delete(app.polyLine);
                % delete(app.endLine);
                % delete(app.wrongEndLine);      
                % 
                % 
                % 
                % 
                % %More re-setting after polygon
                % app.polygon = app.polygon.change(4,app.fd); 
                % app.mesh = delaunayTriangulation(app.polygon.currentFlowLocations);
                % rotateMatrix = [cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)];
                % rotatedOutline = (rotateMatrix * app.polygon.currentPoly')';
                % app.polyLine = plot(app.UIAxes, rotatedOutline(:,1), rotatedOutline(:,2), 'Color','b');
                % app.UIAxes.XLim = [min(rotatedOutline(:,1)) + (min(rotatedOutline(:,1))/20), max(rotatedOutline(:,1)) + (max(rotatedOutline(:,1))/20)];
                % app.UIAxes.YLim = [min(rotatedOutline(:,2)) + (min(rotatedOutline(:,2))/20), max(rotatedOutline(:,2)) + (max(rotatedOutline(:,2))/20)];
                % for(lineCount = 1:length(app.polygon.currentEndZone))
                %     rotatedEndZone = (rotateMatrix * squeeze(app.polygon.currentEndZone(lineCount,:,:))')';
                %     app.wrongEndLine(lineCount) = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','r');
                %     if(lineCount == app.goalIndex)
                %         app.endLine = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','g');
                %     end
                % end 
                % app.mousePosition = [0 0];
                % app.magLine = plot(app.UIAxes,0,0);
                % minTimeToTravel = 10;
                % app.TimeRemainingsEditField.Value = minTimeToTravel .* 5;
                % app.timeLimit = app.TimeRemainingsEditField.Value; 
                % 
                % %Now Loop
                % fileToUse = fopen(DataStoreLocation);
                % tline = fgetl(fileToUse);
                % %Set particleLocations
                % AllData = split(tline,',');
                % positions = AllData(5);
                % positions = strip(positions,'[');
                % positions = strip(positions,']');
                % allPositions = split(positions,';');
                % yPos = str2double(split(allPositions," "));
                % app.particleArrayLocation = yPos;
                % app.particleArrayVelocity = zeros(app.numParticles, 2);
                % app.particleArrayForce = zeros(app.numParticles, 2);
                % app.particleArrayPreviousLocation = app.particleArrayLocation;
                % app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
                % app.particleArrayBaseVelocityStartTime = clock;
                % app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
                % app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
                % app.bouncedLastLoop = zeros(app.numParticles,1); 


                %Now do the looping
                drawTimerCallback(app); %Draw it (or don't)
                finished = false; 
                lineCount=0;
                %Start on the second line - particles need to 'get' to the
                %first position within the first step, which was the first line...
                tline = fgetl(fileToUse); %In short, this makes it work.
                while(ischar(tline) && ~finished) %(app.timePassed <= app.timeLimit)
                    lineCount = lineCount + 1;
                    datas = split(tline,',');
                    time = datas(1);
                    magForce = datas(2);
                    goalPercentage = datas(3);
                    velocities = datas(4);
                    positions = datas(5);
                    goalPositions = datas(6);
                    
                    allMag = strip(magForce,'[');
                    allMag = strip(allMag,']');
                    allMag = split(allMag,' ');
                    magx = str2double(allMag(1));
                    magy = str2double(allMag(2));

                    app.X1TmGauge.Value = magx;%.*2.5;
                    app.Y1TmGauge.Value = magy;%.*2.5;
    
                    timerCallback(app); %Do the action

                    % positions = strip(positions,'[');
                    % positions = strip(positions,']');
                    % allPositions = split(positions,';');
                    % positionArray = [];
                    % 
                    % goals = strip(goalPositions,'[');
                    % goals = strip(goals,']'); 
                    % goals = split(goals,';');

                    % for(lineIndex = 1:length(allPositions))
                    %     yPos = split(allPositions(lineIndex)," ");
                    %     xPos = str2double(yPos(1));
                    %     yPos = str2double(yPos(2));
                    %     allGoals = split(goals(lineIndex), " ");
                    %     g1 = str2double(allGoals(1));
                    %     g2 = str2double(allGoals(2));
                    %     g3 = str2double(allGoals(3));
                    %     g4 = str2double(allGoals(4));
                    %     g5 = str2double(allGoals(5));
                    %     positionArray(end+1,:) = [xPos.*100, yPos.*100,flowRateNormalised,g1,g2,g3,g4,g5];
                    % end


                    tline = fgetl(fileToUse);
                   % if(draw)
                        drawTimerCallback(app); %Draw it (or don't)
                        drawnow limitrate
                        pause(0.05);
                  %  end
                end
                "Finish"

            else
                addValue = 0.1; 
                maxValue = 2.3;
                if value ~= 0
                    addValue = value;
                    maxValue = addValue;
                end
                maxValueNegative = maxValue * -1;
                switch key
                    case 'q'
                        if(app.X1TmGauge.Value < maxValue)
                            app.X1TmGauge.Value = app.X1TmGauge.Value + addValue;
                        end
                    case 'w'
                        if(app.X1TmGauge.Value > maxValueNegative)
                            app.X1TmGauge.Value = app.X1TmGauge.Value - addValue;
                        end
                    case 'e'
                        if(app.X2MAGauge.Value < maxValue)
                            app.X2MAGauge.Value = app.X2MAGauge.Value + addValue;
                        end
                    case 'r'
                        if(app.X2MAGauge.Value > maxValueNegative)
                            app.X2MAGauge.Value = app.X2MAGauge.Value - addValue;
                        end
                    case 'u'
                        if(app.Y1TmGauge.Value < maxValue)
                            app.Y1TmGauge.Value = app.Y1TmGauge.Value + addValue;
                        end
                    case 'i'
                        if(app.Y1TmGauge.Value > maxValueNegative)
                            app.Y1TmGauge.Value = app.Y1TmGauge.Value - addValue;
                        end
                    case 'o'
                        if(app.Y2MAGauge.Value < maxValue)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value + addValue;
                        end
                    case 'p'
                        if(app.Y2MAGauge.Value > maxValueNegative)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value - addValue;
                        end
                    case 'y'
                        app.X1TmGauge.Value = 0;
                        app.X2MAGauge.Value = 0;
                        app.Y1TmGauge.Value = 0;
                        app.Y1TmGauge.Value = 0;
                        app.Z1MAGauge.Value = 0;
                        app.Z2MAGauge.Value = 0;
                        close all;
                end
             end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.hapticFeedbackIdleProperty)
             stop(app.simulationTimerProperty)
            stop(app.drawTimerProperty)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
               CloseHaptic();
            end
            if(app.fileID ~= -1)
                fclose(app.fileID);
            end
            close all; 

            if(app.askQuestionaire)
                %Prompt user for debrief questions
                prompt = {'Did you understand the task? 1(Not at all) - 5(Completely)','How difficult was the experiment? 1(Easy)-5(Difficult)','Did you have good control over where the majority of particles went? 1(None)-5(Complete)','Any other comments?'};
                dlgtitle = 'Statistical questions';
                answer = inputdlg(prompt,dlgtitle);
                fprintf(app.QuestionaireFID,strcat('\r\n Did you understand the task? 1(Not at all) - 5(Completely),',answer{1}));
                fprintf(app.QuestionaireFID,strcat('\r\n How difficult was the experiment? 1(Easy)-5(Difficult),',answer{2}));
                fprintf(app.QuestionaireFID,strcat('\r\n Did you have good control over where the majority of particles went? 1(None)-5(Complete),',answer{3}));
                fprintf(app.QuestionaireFID,strcat('\r\n Any other comments?,',answer{4}));
                fclose(app.QuestionaireFID);
            end
            
            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1TmGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1TmGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end        
        end

        function pF = ChangeMetaValues(app)
            app.particleFunctions = app.particleFunctions.ChangeMetaValues(1.25663706e-6, app.MagneticFieldmTEditField.Value, app.IndividualDiameterEditField.Value, app.ParticleDensitygmlEditField.Value, app.ChainLengthEditField.Value, app.FluidViscocityEditField.Value, app.CeffStat, app.CeffMotion, app.UIAxes.XLim(2));
        end

        % Callback function
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = ChangeMetaValues(app);
        end

        % Callback function
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = ChangeMetaValues(app);
        end

        % Callback function
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = ChangeMetaValues(app);
        end

        % Callback function
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = ChangeMetaValues(app);
        end

        % Callback function
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = ChangeMetaValues(app);
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
%             app.PausedButton.Value = 0;
%             app.StartTrainingButtonValueChanged([]);
%             delete(app.particlePoints);
%             value = app.ScenarioDropDown.Value;
%             app.MagForceRestrictTmEditField.Value = 0;
%             app.NumberofParticlesEditField.Value = 10;
%             app.FluidFlowmsEditField.Value = 0;
%             app.MagForceRestrictTmEditField.Value = 0;
%             fclose(app.fileID);
%             switch(value)
%                 case("Training 1")
%                     app.polygon = app.polygon.change(1);
%                     app.PercentageinGoalEditField.Value = 120;
%                 case("Training 2")
%                     app.polygon = app.polygon.change(2);
%                     app.PercentageinGoalEditField.Value = 120;
%                 case("Test 1")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 40;
%                     app.FluidFlowmsEditField.Value = 0.005;
%                     app.PercentageinGoalEditField.Value = 60;
%                     app.MagForceRestrictTmEditField.Value = 0;
%                 case("Test 2")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.01;
%                     app.PercentageinGoalEditField.Value = 45;
%                     app.MagForceRestrictTmEditField.Value = 0;
%                 case("Test 3")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 40;
%                     app.FluidFlowmsEditField.Value = 0.01;
%                     app.PercentageinGoalEditField.Value = 45;
%                     app.MagForceRestrictTmEditField.Value = 0;
%                 case("Test 4")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.timeLimit = 30;
%                     app.MagForceRestrictTmEditField.Value = 1.5;
%                 case("Test 5")
%                     app.polygon = app.polygon.change(2);
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.PercentageinGoalEditField.Value = 15;
%                     app.MagForceRestrictTmEditField.Value = 0;
%                 case("Test 6")
%                     app.polygon = app.polygon.change(2);
%                     app.MagForceRestrictTmEditField.Value = 0.75;
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.PercentageinGoalEditField.Value = 15;
%                     app.MagForceRestrictTmEditField.Value = 1.5;
%                 case("Test 7")
%                     app.polygon = app.polygon.change(2);
%                     app.MagForceRestrictTmEditField.Value = 1.5;
%                     app.NumberofParticlesEditField.Value = 80;
%                     app.FluidFlowmsEditField.Value = 0.025;
%                     app.PercentageinGoalEditField.Value = 15;
%                     app.MagForceRestrictTmEditField.Value = 1;
%                 otherwise
%                     app.polygon = app.polygon.change(1);
%             end
% 
%             app.timeLimit = app.PercentageinGoalEditField.Value;
%             app.numParticles = app.NumberofParticlesEditField.Value;
%             app.previousMagforce = 0;
%             app.lastUpdate = clock;
%             newFileName = value + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
%             app.fileID = fopen(newFileName,'w');
%             app.loopComplete = true;            
%             app.particlePoints = plot(app.UIAxes,0,0);
%             app.tMax = 1;
%             app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
%             app.particleArrayVelocity = zeros(app.numParticles, 2);
%             app.particleArrayForce = zeros(app.numParticles, 2);
%             app.particleArrayPreviousLocation = app.particleArrayLocation;
%             app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
%             app.particleArrayBaseVelocityStartTime = clock;
%             app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
%             app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
%             hold (app.UIAxes, 'on');
%             delete(app.polyLine);
%             delete(app.endLine);
%             app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
%             app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(1,:,1),app.polygon.currentEndZone(1,:,2), 'Color','g');
%             app.fd = FlowData();
%             app.haltParticlesInEndZone = zeros(app.numParticles,1);
%             app.currentlyDoingWorkSemaphore = false;
%             app.timePassed = 0;
%             app.timestep = 0;
%             app.timeLag = 0;
%             app.mousePosition = [0 0];
%             app.magLine = plot(app.UIAxes,0,0);
% 
%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
        end

        % Button pushed function: PausedButton
        function StartTrainingOLDButtonValueChanged(app, event)
           % value = app.PausedButton.Value;
            %delete(app.magL ine);
           % if(value)
                stop(app.hapticFeedbackIdleProperty);
                set(app.PausedButton,'Text',"Running...");
                app.startTime = clock;
                set(app.PausedButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                start(app.simulationTimerProperty);
                start(app.drawTimerProperty);
                app.PausedButton.Enable = false;
           % else
%                 stop(app.simulationTimerProperty);
%                 stop(app.drawTimerProperty);
%                 set(app.PausedButton,'Text',"Start Next Level");
%                 set(app.PausedButton,'BackgroundColor',[1,0.7,0.7]);
%                 app.testNumber = app.testNumber + 1;
%                 NextLevel(app);
           % end
        end

        % Value changed function: FluidFlowmsEditField
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData(); 
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Value changed function: MagForceRestrictTmEditField
        function MagForceRestrictTmEditFieldValueChanged(app, event)
            app.X1TmGauge.Value = 0;
            app.Y1TmGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            CloseHaptic();
            app.X1TmGauge.Value = 0;
            app.Y1TmGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
                    InitHaptic();
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
               if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictTmEditField.Value > 0 && app.MagForceRestrictTmEditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictTmEditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1TmGauge.Value = app.mousePosition(1) .* 250;
                app.Y1TmGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Value changed function: HapticForceSlider
        function HapticForceSliderValueChanged(app, event)
            %value = app.HapticForceSlider.Value;
            app.HapticForceSlider.Value = round(app.HapticForceSlider.Value);
        end

        % Value changed function: MagneticFieldmTEditField
        function MagneticFieldmTEditFieldValueChanged(app, event)
            magneticField = app.MagneticFieldmTEditField.Value;
            app.MSat = 1 + 19 * (magneticField .* 10).^(0.16);            
        end

        % Value changed function: IndividualDiameterEditField
        function IndividualDiameterEditFieldValueChanged(app, event)
            value = app.IndividualDiameterEditField.Value;
            
        end

        % Value changed function: ParticleDensitygmlEditField
        function ParticleDensitygmlEditFieldValueChanged(app, event)
            value = app.ParticleDensitygmlEditField.Value;
            
        end

        % Value changed function: ChainLengthEditField
        function ChainLengthEditFieldValueChanged(app, event)
            value = app.ChainLengthEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.X1TmGaugeLabel = uilabel(app.LeftPanel);
            app.X1TmGaugeLabel.Text = 'X1 (T/m)';
            app.X1TmGauge = uigauge(app.LeftPanel, 'linear');
            app.X1TmGauge.Limits = [-1 1];
            app.X1TmGauge.MajorTicks = [-1 0 1];
            app.X1TmGauge.MajorTickLabels = {'-1', '.', '1'};
            app.X1TmGauge.MinorTicks = [-0.75 -0.25 0.25 0.75];
            app.Y1TmGaugeLabel = uilabel(app.LeftPanel);
            app.Y1TmGaugeLabel.Text = 'Y1 (T/m)';
            app.Y1TmGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1TmGauge.Limits = [-1 1];
            app.Y1TmGauge.MajorTicks = [-1 0 1];
            app.Y1TmGauge.MajorTickLabels = {'-1', '.', '1'};
            app.Y1TmGauge.MinorTicks = [-0.75 -0.25 0.25 0.75];
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.MagForceRestrictTmEditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictTmEditFieldLabel.Enable = 'off';
            app.MagForceRestrictTmEditFieldLabel.Text = 'MagForceRestrict (T/m)';
            app.MagForceRestrictTmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictTmEditField.ValueChangedFcn = createCallbackFcn(app, @MagForceRestrictTmEditFieldValueChanged, true);
            app.MagForceRestrictTmEditField.Editable = 'off';
            app.MagForceRestrictTmEditField.Enable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Enable = 'off';
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowmsEditFieldValueChanged, true);
            app.FluidFlowmsEditField.Editable = 'off';
            app.FluidFlowmsEditField.Enable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofParticlesEditFieldLabel.Enable = 'off';
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Enable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.LeftPanel);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.PausedButton = uibutton(app.LeftPanel, 'push');
            app.PausedButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.PausedButton.Enable = 'off';
            app.PausedButton.Text = 'Paused';
            app.HapticForceSliderLabel = uilabel(app.LeftPanel);
            app.HapticForceSliderLabel.Enable = 'off';
            app.HapticForceSliderLabel.Visible = 'off';
            app.HapticForceSliderLabel.Text = 'Haptic Force';
            app.HapticForceSlider = uislider(app.LeftPanel);
            app.HapticForceSlider.Limits = [1 4];
            app.HapticForceSlider.MajorTicks = [1 2 3 4];
            app.HapticForceSlider.ValueChangedFcn = createCallbackFcn(app, @HapticForceSliderValueChanged, true);
            app.HapticForceSlider.MinorTicks = [1 2 3 4];
            app.HapticForceSlider.Enable = 'off';
            app.HapticForceSlider.Visible = 'off';
            app.HapticForceSlider.Value = 3;
            app.ChainLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.ChainLengthEditFieldLabel.Enable = 'off';
            app.ChainLengthEditFieldLabel.Text = 'Chain Length';
            app.ChainLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ChainLengthEditField.ValueChangedFcn = createCallbackFcn(app, @ChainLengthEditFieldValueChanged, true);
            app.ChainLengthEditField.Editable = 'off';
            app.ChainLengthEditField.Enable = 'off';
            app.ChainLengthEditField.Value = 12000;
            app.IndividualDiameterEditFieldLabel = uilabel(app.LeftPanel);
            app.IndividualDiameterEditFieldLabel.Enable = 'off';
            app.IndividualDiameterEditFieldLabel.Text = 'Individual Diameter';
            app.IndividualDiameterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IndividualDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @IndividualDiameterEditFieldValueChanged, true);
            app.IndividualDiameterEditField.Editable = 'off';
            app.IndividualDiameterEditField.Enable = 'off';
            app.IndividualDiameterEditField.Value = 7.5e-08;
            app.ParticleDensitygmlEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDensitygmlEditFieldLabel.Enable = 'off';
            app.ParticleDensitygmlEditFieldLabel.Text = 'Particle Density (g/ml)';
            app.ParticleDensitygmlEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDensitygmlEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDensitygmlEditFieldValueChanged, true);
            app.ParticleDensitygmlEditField.Editable = 'off';
            app.ParticleDensitygmlEditField.Enable = 'off';
            app.ParticleDensitygmlEditField.Value = 4.8;
            app.MagneticFieldmTEditFieldLabel = uilabel(app.LeftPanel);
            app.MagneticFieldmTEditFieldLabel.Enable = 'off';
            app.MagneticFieldmTEditFieldLabel.Text = 'Magnetic Field (mT)';
            app.MagneticFieldmTEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagneticFieldmTEditField.ValueChangedFcn = createCallbackFcn(app, @MagneticFieldmTEditFieldValueChanged, true);
            app.MagneticFieldmTEditField.Editable = 'off';
            app.MagneticFieldmTEditField.Enable = 'off';
            app.MagneticFieldmTEditField.Value = 3;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Enable = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Enable = 'off';
            app.FluidViscocityEditField.Value = 0.001;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationREPLAYParticipant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end