classdef particleSimulationTrainModeFFPlayFirstHomePC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StartTrainingButton            matlab.ui.control.Button
        ScenarioEditField              matlab.ui.control.EditField
        ScenarioEditFieldLabel         matlab.ui.control.Label
        ScenarioDropDown               matlab.ui.control.DropDown
        ScenarioDropDownLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        PercentageinGoalEditField      matlab.ui.control.NumericEditField
        TimeremainingsLabel            matlab.ui.control.Label
        TimeRemainingsEditField        matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        MagForceRestrictTmEditField    matlab.ui.control.NumericEditField
        FluidViscocityEditField        matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel   matlab.ui.control.Label
        MagneticFieldmTEditField       matlab.ui.control.NumericEditField
        MagneticFieldmTEditFieldLabel  matlab.ui.control.Label
        ParticleDensitygmlEditField    matlab.ui.control.NumericEditField
        ParticleDensitygmlEditFieldLabel  matlab.ui.control.Label
        IndividualDiameterEditField    matlab.ui.control.NumericEditField
        IndividualDiameterEditFieldLabel  matlab.ui.control.Label
        ChainLengthEditField           matlab.ui.control.NumericEditField
        ChainLengthEditFieldLabel      matlab.ui.control.Label
        NumberofParticlesEditField     matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField           matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel      matlab.ui.control.Label
        Y1TmGauge                      matlab.ui.control.LinearGauge
        Y1TmGaugeLabel                 matlab.ui.control.Label
        X1TmGauge                      matlab.ui.control.LinearGauge
        X1TmGaugeLabel                 matlab.ui.control.Label
        HapticForceSlider              matlab.ui.control.Slider
        CentreMassViewCheckBox         matlab.ui.control.CheckBox
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ControllerButton               matlab.ui.control.RadioButton
        MouseButton                    matlab.ui.control.RadioButton
        KeyboardButton                 matlab.ui.control.RadioButton
        MagForceRestrictTmEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        simulationTimerProperty
        drawTimerProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        testOrder
        maxForce
        QuestionaireFID
        DataStoreLocation
        dataFileNameList
        writeToFile
        MSat
        CeffMotion
        CeffStat 
        EquivalentDiameter
        askQuestionaire
        ParticleLocationMesh
        DebugPoint
        DebugText
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rng(1000,"twister");
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05;
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 1;%2.25;
            app.printCounter = 0;
            app.fd = FlowData05();
            
            app.ParticleDensitygmlEditField.Value = 4.8;
            app.IndividualDiameterEditField.Value = 75 *10^-9;
            app.CeffMotion = 0.00003;
            app.CeffStat = 0.000135;

            %The timers aren't being used as the simulator is just clocked as fast as it will go for this run.

            %Otherwise set up everything basically the same as for the human participants
            %app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.MagneticFieldmTEditField.Value, app.IndividualDiameterEditField.Value, app.ParticleDensitygmlEditField.Value, app.ChainLengthEditField.Value, app.FluidViscocityEditField.Value, app.CeffStat, app.CeffMotion, app.UIAxes.XLim(2));
            
            app.lastUpdate = clock;
            newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "TrainingModel";
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0);
            app.timeLimit = 1;
            app.testNumber = 0;
            app.rotation = 0;
            app.testOrder = 1:480;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.testOrder = [app.testOrder,length(app.testOrder)+1];
            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);

            %saved data stored location. Alternatively use pre-existing
            %'.m' files ("StoredVariables__.m")
            app.DataStoreLocation = "";%D:\EssexDocuments\EXMAR23\TESTDATACLUMP56";
            app.dataFileNameList = [];
            app.writeToFile = false;

            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            DT = delaunayTriangulation(app.polygon.currentPoly); 
            IC = incenter(DT);
            ab = [app.polygon.currentPoly ; IC];
            DT = delaunayTriangulation([app.polygon.currentPoly ; IC]); %Add another loop in if needed
            app.ParticleLocationMesh = DT;

            app.DebugPoint = plot(app.UIAxes,0,0);
            app.DebugText = plot(app.UIAxes,0,0);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %Training is started upon the spacebar being pressed. Only run
            %once as when the space is pressed once then the trainingButton
            %becomes disabled.
            "Starting"
            key = event.Key;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                 set(app.StartTrainingButton,'Text',"Running...");
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                app.StartTrainingButton.Enable = false;
    
                startZone = 1;
                n_particles = 500;

                %Environment Setup
                if(app.testNumber == 0)
                    resetSimForMLTraining(app,1/6,startZone,n_particles);
                end


                %Incase a previous instance of python was still running.
                terminate(pyenv)
                try
                    pyenv("Version","C:\Users\beno1\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                catch
                    try
                        pyenv("Version","C:\Users\bj20907\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                    catch
                        pyenv("Version","C:\Users\Rumi\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                    end
                end
                if count(py.sys.path,'') == 0
                    insert(py.sys.path,int32(0),'');
                end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Plotting:
                % plot(squeeze(summed_reward_histories(:,:,1))') - 1st flow value, over all steps
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                %Decide which runs to show to the user - runs faster without showing
                DRAWTRAIN = false;
                DRAWPLAY = false;
                DRAWWEIGHTS = false;
                DRAWVALUES = true;

                if(DRAWTRAIN)
                    fig1 = figure();
                    axes1 = axes('Parent', fig1);
                end
                if(DRAWPLAY)
                    fig2 = figure();
                    axes2 = axes('Parent', fig2);
                end
                meshToUse = app.ParticleLocationMesh
                save("dt.mat","meshToUse");
                n_states = size(app.particleFunctions.getState(app.particleArrayLocation, 0, 0, 0, app.polygon.currentEndZone,app.ParticleLocationMesh),2); %was 8
                n_actions = 2; %Using regression, so the outputs are just x and y
               % h_Layers = [10,1]; %This is altered later, comment out for clarity
                %flow_rate = 3/6; %Flow rate is a number 1/6 to 6/6. Input for the network detailing the magnitude of flow - network was trained on all flows (or a subset)
                totalLoops = 1;%number of times to run the play/train loop.
                playingLoops = 1;%number of times PER totalLoop to run playing. This runs first.
                trainingLoops = 0;%number of times PER totalLoop to run training. This runs second. %SET THIS TO 1 AT MINIMUM
                traceMin = 1;
                traceStep = 1;
                traceMax = 1;

                batchSize = 49; %7
                ePochs = 1;

                %particles in goal, used to decide to progress through
                %difficulty levels. Currently not functional.
                goalCount = 0;

                summed_reward_histories = zeros(traceMax,totalLoops,6);
                loss_history_val = [];
                loss_history = [];
                lhDrawn = [];
                accDrawn = [];
                lhvDrawn = [];
                accvDrawn = [];
                rewDrawn = [];

                if(DRAWWEIGHTS)
                    weightFig = figure;
                end

                goalLocation = [0.010644161 -0.002909076]; %Use this for distance to goal calculations
    
              %  pyrun("from pythonNNContstructor import *")
              
             %   PyRun2File(app, "from pythonNNContstructor import *")
               % load(fullfile("TrainedDataResults_RandSeed_HiddenLayer.mat"),"summed_reward_histories","loss_history","loss_history_val");


                for(traceStepCount = traceMin:traceStep:traceMax)
  %For now just doing one trace at a time
                    %traceStepCount = 8;


                    %Load in training data and do any conversion necessary
                    [basic_stateList, actionList, basic_val_stateList, val_actionList] = loadStateActionValPairsFromPolicy(app,0.2,0.4);%traceStepCount); %traceStepCount being 2 is re
                    basic_stateList = statePairsToCorrectState(app,basic_stateList, app.ParticleLocationMesh, actionList);
                    basic_val_stateList = statePairsToCorrectState(app,basic_val_stateList, app.ParticleLocationMesh, val_actionList);

                    if(true)
                        expertStates = basic_stateList;
                        expertActions = actionList;
                        valExpertStates = basic_val_stateList;
                        valExpertActions = val_actionList;
                        % for(i = 1:size(expertActions,1))
                        %     plotMyPoints = plot(app.UIAxes, app.particleFunctions.realNum(expertStates(i,:,1)), app.particleFunctions.realNum(expertStates(i,:,2)),'r.', 'markerSize', 23);
                        %     magLine = [0, ; 0, ];
                        %     plotMyLine = plot(app.UIAxes,rotatedMagLine(:,1),rotatedMagLine(:,2),'magenta');
                        %     pause(0.05);
                        %     delete(plotMyPoints);
                        %     delete(plotMyLine);
                        % end

                        exampleDataFileName = "ExampleTunedDataFile.mat";

                        if(isfile(exampleDataFileName))
                            delete(exampleDataFileName);
                        end

                        MFTuned = matfile(exampleDataFileName,"Writable",true);
                        % MFTuned.ES = expertStates;
                        % MFTuned.EA = expertActions;
                        % MFTuned.VS = valExpertStates;
                        % MFTuned.VA = valExpertActions;
                        save(exampleDataFileName,"expertStates", "expertActions", "valExpertStates", "valExpertActions",'-v7.3');
                    end

                    return
                                        
                    %determining the number of nodes in the hidden layer(s)
                   h_Layers = [6*traceStepCount];
                 %    h_Layers = [7];

                    %pyrun("model = NNClass(numStates,numActions,hiddenLayers)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers);
                   % numStates=n_states;numActions=n_actions;hiddenLayers=h_Layers;
                   %extraStr = "numStates="+string(n_states)+",numActions="+string(n_actions)+",hiddenLayers="+string(h_Layers);
             %       PyRun2File(app,["numStates="+string(n_states), "numActions="+string(n_actions), "hiddenLayers="+string(h_Layers)]);
             %       PyRun2File(app,"model = NNClass(numStates,numActions,hiddenLayers)");
             %       PyRun2File(app,"model.model.summary()")

                    %Test the randomness of the network init
                    % stateExample = app.particleFunctions.getState(app.particleArrayLocation, 0, 0, 0, app.polygon.currentEndZone, app.ParticleLocationMesh);
                    % for(i = 1:10)
                    %   stateRand = rand(size(stateExample)); %Try this between -1 and 1
                    %   pyrun("state = stateR",stateR = stateRand);
                    %   randAct = double(pyrun("pred = model.model.predict(np.array([state]),verbose=0, batch_size=1)","pred"))
                    % end

                    for(totalLoopIndex = 1:totalLoops)

                        %Draw out the weights and biases of the network
                        if(DRAWWEIGHTS)
                            measured_biases = [];
                            %Test the drawing
                            for(layer = 1:(1+length(h_Layers)))
                                %draw the weights
                             %   PyRun2File(app,"l="+string(layer)+"-1");
                             %   w = double(PyRun2File(app,"w = model.model.layers[int(l)].get_weights()[0]","w"));%,l=layer-1));
                             %   b = double(PyRun2File(app,"b = model.model.layers[int(l)].get_weights()[1]","b"));%,l=layer-1)); 
                                %b = double(pyrun("w = model.model.layers[int(l)].get_weights()[1]","b",l=layer-1));                                
                                measured_weights = [measured_weights,{w}];
                                measured_biases = [measured_biases,{b}];
                               % measured_weights(end+1) = {double(pyrun("w = model.model.layers[int(l)].get_weights()[0]","w",l=layer))};
                              %  measured_biases(layer,:) = double(pyrun("b = model.model.layers[int(l)].get_weights()[1]","b",l=layer));
                            end
                            close(weightFig);
                            weightFig = DrawNetworkWeights([n_states,h_Layers,n_actions],measured_weights, measured_biases);
                            pause(0.1);
                        end
                        if(DRAWVALUES)   
                            % measured_weights = [];  
                            % for(layer = 1:(1+length(h_Layers)))
                            %     %draw the weights
                            %     w = pyrun("w = model.model.layers[int(l)].get_weights()","w",l=layer-1);
                            %     %b = double(pyrun("b = model.model.layers[int(l)].get_weights()[1]","b",l=layer-1)); 
                            %     %aa = num2cell(w)
                            %     measured_weights(layer) = w;
                            % en                            DrawNetworkValues(true,app.particleFunctions.getState(app.particleArrayLocation, 1/6, 0, 0))
                        end

                        %Do any translation and rotation of training data to
                        %help make network more generalised
                        rot = 0;
                        xShiftTrain = 0;
                        yShiftTrain = 0;                        
                        stateList = translateRotateTrainingData(app, rot, xShiftTrain, yShiftTrain, basic_stateList);
                        val_stateList = translateRotateTrainingData(app, rot, xShiftTrain, yShiftTrain, basic_val_stateList);
        
                        %Step up difficulty level. Currently not functional.
                        if(startZone < 5 && goalCount>25)
                            startZone = startZone + 1;
                            goalCount = 0;
                        end
                        
                        %'Play'
                        "Playing Commencing"
                        %Setup final locals that are used in this Play loop
                        t=0;
                        flowNum = [1/6, 1/3, 0.5, 2/3, 5/6, 1];
                        distanceCount = 0;
                        rewardSum = 0;
                        rSum = zeros(size(flowNum));
                        
                        %different shifts to training data.
                        xShift = 0;
                        yShift = 0;

                        %Get the initial state incase it's needed.
                        matState = app.particleFunctions.getState(app.particleArrayLocation, 1/6, xShift, yShift, app.polygon.currentEndZone, app.ParticleLocationMesh);
                        tStep = 0;
                        correctOutlet = false;
                        for(i = 1:playingLoops)
                        
                            %If the shift needs to be modified, do it
                            %outside of each play loop so that the whole
                            %run is offset the same ammount.
                            xShift = 0;
                            yShift = 0;

                            %Step over each flow velocity.
                            for(flowIndex = 1:6)%length(flowNum))
                                flow_rate = flowNum(flowIndex); %Try each flow one at a time
                                finished = false;
                                resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                                drawTimerCallback(app)

                               pyrun("from trainingNNwithrealdata import *");
                               pyrun("runTraining()");

                                nnnn = pyrun("NNet = keras.models.load_model('E:\\SwarmSimulation\\BestModel')","NNet")

                                %Loop to the end of the level
                               % if(totalLoopIndex==totalLoops||totalLoopIndex==4||totalLoopIndex==8||totalLoopIndex==2||totalLoopIndex==6) %%%REMOVEME
                                    while(app.timePassed <= app.timeLimit && ~finished)                                       
                                        tStep = tStep+1;
                                        [matState,rewardSum,matAction] = stepGame(app,n_actions,[],[],flow_rate,tStep,DRAWPLAY&&i==1,goalLocation, true, xShift, yShift);
                                        if(sum(app.haltParticlesInEndZone)/app.numParticles == 1)   
                                            finished = true;
                                        end
                                        
                                        plotStateOnFig(app,app.particleArrayLocation,matAction); %Plot the state onto the image to see.
                                        pause(0.01);
                                        distanceCount = distanceCount + 1;
                                    end
    
                                    %Record the score/reward obtained
                                    rSum(flowIndex) = rewardSum%rSum(flowIndex)+rewardSum;
                                   % rSum = rewardSum;
    
                                    %Step up difficulty level. Currently not functional.
                                    if(correctOutlet)
                                        goalCount = goalCount + 1;
                                    else
                                        goalCount = goalCount - 1;
                                    end
                                end                                
                           % end
                            summed_reward_histories(traceStepCount,totalLoopIndex,:) = rSum;%./(playingLoops); %AVERAGE number of particles that reached goal FOR EACH FLOW%% over all flows!!()divide by number of flow rates to average across all.
                       
                        end
                        

                        %We've done the playing, now graph the newest results
                   %     summed_reward_histories(i,totalLoopIndex,:) = rSum./(playingLoops); %AVERAGE number of particles that reached goal FOR EACH FLOW%% over all flows!!()divide by number of flow rates to average across all.
                        
                        %delete existing lines so the graphs don't overlap
                        if(DRAWPLAY)
                           % delete(lhDrawn);
                           % delete(lhvDrawn);
                            delete(accDrawn);
                            delete(rewDrawn);
    
                            %aa = squeeze(summed_reward_histories(traceStepCount,:,:));
                            mm = mean(summed_reward_histories,3);
                           rewDrawn = plot(axes2,mean(summed_reward_histories(traceMin:traceStep:traceMax,:,:),3)'); %Plot the current trace (so all fluid flows)
                           % rewDrawn = plot(axes2,squeeze(summed_reward_histories(traceStepCount,:,:))); %Plot the current trace (so all fluid flows)
                            hold on
                            legend(string(flowNum));
                            title(axes2,"Percentage of particles reaching the goal each episode during playing")
                            xlabel(axes2,"Play Iterations");
                            ylabel(axes2,"Percentage of particles reaching the goal");
                            ylim(axes2,[0 100]);
                            pause(0.9);
                        end
                        resetSimForMLTraining(app,flow_rate,startZone,n_particles);

                        %'Train'
                        if(trainingLoops > 0)
                            "Training Commencing"
                            for(trainingIndex = 1:trainingLoops)
                                t = 0;
                                %This step trains the NN 'epochs' number of
                                %times over all data. batchSize determines
                                %after how many data samples the weights are
                                %modified, this helps with keeping the changes
                                %more generalised.
                                
                                pyrun(["print(state)","print(target)","print(bS)","print(ep)","print(xT)","print(yT)"],state = stateList, target = actionList, bS = batchSize, ep = ePochs, xT = val_stateList, yT = val_actionList)
                              %  hist = pyrun("uu = model.model.fit(np.array(state),np.array(target),batch_size=int(bS),epochs=int(ep), validation_data=(np.array(xT), np.array(yT)) ,verbose=0)","uu",state = stateList, target = actionList, bS = batchSize, ep = ePochs, xT = val_stateList, yT = val_actionList);
                                hist = PyRun2File(app,"uu = model.model.fit(np.array(state),np.array(target),batch_size=int(bS),epochs=int(ep), validation_data=(np.array(xT), np.array(yT)) ,verbose=0)","uu");
    
                                
                                %Get the data out to store for graphing in a bit.
                                lh = double(hist.history{'loss'});
                                lhv = double(hist.history{'val_loss'});
                                currentLineEnd = (totalLoopIndex-1) .* ePochs + 1;
                                loss_history(traceStepCount,currentLineEnd:currentLineEnd+ePochs-1) = lh;
                                loss_history_val(traceStepCount,currentLineEnd:currentLineEnd+ePochs-1) = lhv;
    
                                resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                            end
    
                            measured_weights = 0;
                            measured_biases = 0;
                            % if true
                            %     for(layer = 1:(2+length(h_Layers)))
                            %         %draw the weights
                            %         w = pyrun("w = model.model.layers[l].get_weights()","w",l=int(layer));
                            %      %   measured_weights(layer) = w[0];
                            %      %   measured_biases(layer) = w[1];
                            %     end
                            % else
                            %     %print the weights
                            %     pyrun("for layer in model.model.layers: print(layer.get_config(), layer.get_weights())");
                            % end
    
                            %plot the training loss
                            if(DRAWTRAIN)
                                delete(lhDrawn);
                                delete(lhvDrawn);
                             %   delete(accDrawn);
                             %   delete(accvDrawn);
                             %   delete(rewDrawn);
                                lhDrawn = plot(axes1,squeeze(loss_history(traceStepCount,:,:)));
                                hold on
                                lhvDrawn = plot(axes1,squeeze(loss_history_val(traceStepCount,:,:)));
                                hold on
                                legend(axes1,["loss","val-loss"])
                                title(axes1,"loss for training and validation while training")
                                xlabel(axes1,"Train Iterations");
                                ylabel(axes1,"");
                                ylim auto
                                pause(1);
                            end
                        end


%%% save the results to a file - this is done better later.

% save("TrainedDataResults_all.mat","summed_reward_histories","loss_history","loss_history_val");
% if(traceStepCount==1)
%     save("TrainedDataResults_15.mat","summed_reward_histories","loss_history","loss_history_val");
% else 
%     if(traceStepCount==2)
%         save("TrainedDataResults_25.mat","summed_reward_histories","loss_history","loss_history_val");
%     else
%         save("TrainedDataResults_35.mat","summed_reward_histories","loss_history","loss_history_val");
%     end
% end
%   delete(lhDrawn);
%   delete(lhvDrawn);
%   delete(rewDrawn);

%%%save the training parameters incase they're needed later. Not functional
%resetSimForMLTraining(app);
%      app.fileID = fopen("MachineLearningTraining04.txt",'a');%Just appending, don't need to read anything.
%      filedescriptionsdg = "19-05-23 01, n_states = " + num2str(n_states) + ", n_actions = " + num2str(n_actions) + ", h_Layers = " + num2str(h_Layers) + ", e_psilon = " + num2str(e_psilon) + " l_rate = " + num2str(l_rate) + " Gamma = " + num2str(Gamma) + " flow_rate = " + num2str(flow_rate) + " totalSteps = " + num2str(totalSteps) + " trainingLoops = " + num2str(trainingLoops);

%      fprintf(app.fileID, filedescriptionsdg +"\r\n");
%      fprintf(app.fileID, num2str(hDistToPrint) +"\r\n\r\n");
%      fclose(app.fileID);
% figure
% plot(hDistToPrint);
                   end   
                end               
            end
            % try
            %     save("TrainedDataResults_RandSeed_HiddenLayer.mat","summed_reward_histories","loss_history","loss_history_val");
            % catch
            %     "Training data was unable to be saved"
            % end
            "Finished - Training Complete"
            
        end

        % given a state, modify it to provide more generalised data.
        function moddedDataset = translateRotateTrainingData(app, rot, xtr, ytr, dataset)
            if(true) %just skip for now
                moddedDataset = dataset;
                return
            end
            if(size(app.particleFunctions.getState(app.particleArrayLocation, 0, 0, 0, app.polygon.currentEndZone, app.ParticleLocationMesh),2) ~= 8) %changeMe when this functio
                error("Discrepancy with state size and rotational data. " + ...
                    "State size is " + size(app.particleFunctions.getState(app.particleArrayLocation, 0, 0, 0, app.polygon.currentEndZone, app.ParticleLocationMesh),2));
                return
            end
            moddedDataset = dataset;
            %TODO rotate.
            moddedDataset(:,4) = moddedDataset(:,4) + xtr; %4&5 are avg x and y
            moddedDataset(:,5) = moddedDataset(:,5) + ytr;
            moddedDataset(:,7) = squeeze(ones(size(moddedDataset(:,1))) .* xtr);
            moddedDataset(:,8) = squeeze(ones(size(moddedDataset(:,1))) .* ytr);
        end


         %Used for reading state from csv files. This is currently unused,
        %but can be adjusted to work if more data needs to be converted.
        function sl = statePairsToCorrectState(app, stateList, mesh, actionList)
            if(nargin<3)
                warning("No mesh provided (ParticleFunctions)");
                mesh = "";
            end
            if(true)
                sl = [];
                if(size(stateList) == 0)
                    return
                end
                pALocation = squeeze(stateList(:,:,1:2))./100; %divide by 100 as it's saved as * 100 (For data representation)
                pAGoals = squeeze(stateList(:,:,4:8));   
                sl = zeros(size(pALocation,1),size(mesh.Points,1));

                for(dataIndex = 1:size(pALocation,1))
                    sl(dataIndex,:) = zeros(size(mesh.Points,1),1);

                    particlesInAnyGoalZone = any(squeeze(pAGoals(dataIndex,:,:))==1,2);
                  %  OnlyParticlesInPlay = squeeze(pALocation(dataIndex,:,:)); %Use all particle locations
                    OnlyParticlesInPlay = squeeze(pALocation(dataIndex,particlesInAnyGoalZone==0,:)); %Use only particles still in play
                    if(any(size(OnlyParticlesInPlay,1) == 0))
                        warning("State is blank here");
                    else
                        if(size(OnlyParticlesInPlay,2) == 1) %This complexity is brought on by reducing the number of particles being tracked using OnlyParticlesInPlay. To remove complexity consider all particles (pALocation)
                            if(size(OnlyParticlesInPlay,1) == 2)
                                OnlyParticlesInPlay = OnlyParticlesInPlay';
                            else
                                %There's an issue here
                                warning("State is wrong shape: " + size(OnlyParticlesInPlay));
                                continue;
                            end
                        end

                      %  closestNode = nearestNeighbor(mesh,OnlyParticlesInPlay);
                       % twentyPercent = size(OnlyParticlesInPlay,1) * 0.2;
                        % closestNode = knnsearch(mesh.Points,OnlyParticlesInPlay,'K',5,'Distance','euclidean');
                        closestNode = knnsearch(mesh.Points,squeeze(pALocation(dataIndex,:,:)),'K',5,'Distance','euclidean');                        
                        for(neuronIndex = 1:size(mesh.Points,1))
                            %Limit between 0 and 1, and only up to 10% of all particles present. Any more saturates
                            valueToUse = sum(closestNode(:,1) == neuronIndex)./2; 
                            valueToUse = valueToUse + sum(closestNode(:,2) == neuronIndex)./4; 
                            valueToUse = valueToUse + sum(closestNode(:,3) == neuronIndex)./6; 
                            valueToUse = valueToUse + sum(closestNode(:,4) == neuronIndex)./8; 
                            valueToUse = valueToUse + sum(closestNode(:,5) == neuronIndex)./16; 
                            % if(valueToUse > (twentyPercent))
                            %     valueToUse = twentyPercent;
                            % end
                            % sl(dataIndex,neuronIndex) = valueToUse./(twentyPercent); %./(app.numParticles*0.1);
                            sl(dataIndex,neuronIndex) = valueToUse ./ size(OnlyParticlesInPlay,1);

                            % alueToUse = sum(closestNode == neuronIndex)./(app.numParticles*10); %Limit between 0 and 1, and only up to 10% of all particles present. Any more saturates
                            % if(valueToUse > 1)
                            %     valueToUse = 1;
                            % end
                            % sl(dataIndex,neuronIndex) = valueToUse;
                        end    
                    end
                  %  plotStateOnFig(app,squeeze(pALocation(dataIndex,:,:)),squeeze(actionList(dataIndex,:))); %Plot the state onto the image to see.
                  %  pause(0.01);
                end

            else
                if(true) %use more data, MUST be using SavedAllData.mat files
                    sl = [];
                    if(size(stateList) == 0)
                        return
                    end
                    pALocation = squeeze(stateList(:,:,1:2))./100; %divide by 100 as it's saved as * 100 (For data representation)
                    pAGoals = squeeze(stateList(:,:,4:8));
    
                    neuronsPerSide = 20; %This MUST match the number of states within the network
                    histxMin = -0.0095;
                    histxMax = 0.012959844;
                    histyMin = -0.008502081;
                    histyMax = 0.008576092;
                    xhistWidth = histxMax - histxMin + histxMax*0.0001; %add a small extra in order that we don't reach exactly 1, as using floor function
                    yhistWidth = histyMax - histyMin + histyMax*0.0001;                
                    for(dataIndex = 1:size(pALocation,1))
                        %allEndZones = sum(app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,squeeze(pALocation(dataIndex,:,:))),2);%This should be 1 or 0
                        particlesInAnyGoalZone = any(squeeze(pAGoals(dataIndex,:,:))==1,2);
                        OnlyParticlesInPlay = squeeze(pALocation(dataIndex,:,:));%squeeze(pALocation(dataIndex,particlesInAnyGoalZone==0,:)); %Use all particle locations for now...
                        if(any(size(OnlyParticlesInPlay,1) == 0))
                            sl(dataIndex,:) = zeros(1,neuronsPerSide*2); %FIXME!
                            warning("State is blank here");
                        else
                            if(size(OnlyParticlesInPlay,2) == 1) %This complexity is brought on by reducing the number of particles being tracked using OnlyParticlesInPlay. To remove complexity consider all particles (pALocation)
                                if(size(OnlyParticlesInPlay,1) == 2)
                                    OnlyParticlesInPlay = OnlyParticlesInPlay';
                                else
                                    %There's an issue here
                                    warning("State is wrong shape: " + size(OnlyParticlesInPlay));
                                    sl(dataIndex,:) = zeros(1,neuronsPerSide*2); %FIXME!
                                    continue;
                                end
                            end
                            %Histogram of particle locations (X and Y, so a
                            %particle location is assigned to to two neurons at once
                            scaleLocationsX = (OnlyParticlesInPlay(:,1) + histxMin) ./ xhistWidth; %Scale location of particle from -1 to 1
                            scaleLocationsY = (OnlyParticlesInPlay(:,2) + histyMin) ./ yhistWidth;
                            indexX = floor((scaleLocationsX + 1)./ (1/((neuronsPerSide)/2))) + 1; %+1 to scaleLoc to scale always greater tha 0 (hopefully)
                            indexY = floor((scaleLocationsY + 1)./ (1/((neuronsPerSide)/2))) + 1;
                            sumIndexX = zeros(neuronsPerSide,1);
                            sumIndexY = zeros(neuronsPerSide,1);
                            for(neuronIndexCount = 1:neuronsPerSide)
                                sumIndexX(neuronIndexCount) = sum(indexX == neuronIndexCount);
                                sumIndexY(neuronIndexCount) = sum(indexY == neuronIndexCount);
                            end
                            sl(dataIndex,:) = [sumIndexX; sumIndexY];
                        end
                        flowRate(dataIndex) = squeeze(stateList(dataIndex,1,3));%Just need it for one particle, lots of spare data here
                    end
                    %sl = [xSTD, ySTD, covar, xAVG, yAVG, flowRate];
                    return
                else
                    if(true) %This is when particles are being removed from the current state if they are in end zone.
                        pALocation = squeeze(stateList(:,:,1:2));
                        xSTD = zeros(size(pALocation,1),1);
                        ySTD = zeros(size(pALocation,1),1);
                        xAVG = zeros(size(pALocation,1),1);
                        yAVG = zeros(size(pALocation,1),1);
                        covar = zeros(size(pALocation,1),1);
                        flowRate = zeros(size(pALocation,1),1);
                        for(dataIndex = 1:size(pALocation,1))   %zeros(size(stateList,1),1);
                            allEndZones = sum(app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,squeeze(pALocation(dataIndex,:,:))),2);%This should be 1 or 0            
                            OnlyParticlesInPlay = squeeze(pALocation(dataIndex,allEndZones==0,:));
                            if(size(OnlyParticlesInPlay,1) == 0)
                                %matState = [0,0,0,0,0,0]; %FIXME!
                                error("matState is blank here");
                            else
                                aa = sum(OnlyParticlesInPlay(:,1),1)./ size(OnlyParticlesInPlay,2);
                                xSTD(dataIndex) = std(OnlyParticlesInPlay(:,1),0,1);
                                ySTD(dataIndex) = std(OnlyParticlesInPlay(:,2),0,1);
                               % aaaasdgf = size(OnlyParticlesInPlay,2)
                               % asdghdf = OnlyParticlesInPlay(:,1)
                               % asgasgag = sum(OnlyParticlesInPlay(:,1),1)
                                xAVG(dataIndex) = sum(OnlyParticlesInPlay(:,1),1)./ size(OnlyParticlesInPlay,1);
                                yAVG(dataIndex) = sum(OnlyParticlesInPlay(:,2),1)./ size(OnlyParticlesInPlay,1);
                                particleCoV = cov(OnlyParticlesInPlay(:,1),OnlyParticlesInPlay(:,2));
                                covar(dataIndex) = particleCoV(1,2);
                            end
                            flowRate(dataIndex) = squeeze(stateList(dataIndex,1,3));%Just need it for one particle, lots of spare data here
                        end
                        sl = [xSTD, ySTD, covar, xAVG, yAVG, flowRate];
                        return
                    end
                    %This needs to match the getState function.
                    xSTD = std(squeeze(stateList(:,:,1)),0,2);
                    ySTD = std(squeeze(stateList(:,:,2)),0,2);
                    xAVG = sum(squeeze(stateList(:,:,1)),2)./ size(stateList,2);
                    yAVG = sum(squeeze(stateList(:,:,2)),2)./ size(stateList,2);
                    covar = zeros(size(stateList,1),1);
                    for(i = 1:size(stateList,1))
                        particleCoV = cov(squeeze(stateList(i,:,1)),squeeze(stateList(i,:,2)));
                        covar(i) = particleCoV(1,2);
                    end
                    flowRate = squeeze(stateList(:,1,3));%Just need it for one particle, lots of spare data here
                    sl = [xSTD, ySTD, covar, xAVG, yAVG, flowRate];
                end
            end
        end

        %Similar to what is done in NextLevel - reset the global variables
        %so that another round of training/playing can take place
        function resetSimForMLTraining(app,flowRate,startZone,nParticles)
            rng(1,"twister");
            startZone = 7;
            delete(app.particlePoints);
            delete(app.magLine);
            fclose(app.fileID);
            app.ScenarioEditField.Value = "Test " + app.testNumber;
          %  app.polygon = app.polygon.change(2,app.fd);

            app.rotation = 0;
            app.NumberofParticlesEditField.Value = nParticles;
            app.numParticles = app.NumberofParticlesEditField.Value;            
            app.MagForceRestrictTmEditField.Value = 0.5; %was 0
            generateNewParticles = true;
            switch(flowRate)
                case(1/6)
                    app.fd = FlowData10();
                case(2/6)
                    app.fd = FlowData20();
                case(3/6)
                    app.fd = FlowData30();
                case(4/6)
                    app.fd = FlowData40();
                case(5/6)
                    app.fd = FlowData50();
                case(6/6)
                    app.fd = FlowData60();
            end

            app.polygon = app.polygon.change(4,app.fd);
            app.FluidFlowmsEditField.Value = 10;

            %app.FluidFlowmsEditField.Value = flowRate.*6;
            if(ndims(app.polygon.currentStartZone) > 2)
                app.polygon.currentStartZone = squeeze(app.polygon.currentStartZone(1,:,:));
            end            

            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);

            app.goalIndex = 4;
            app.slowDown = 1;
            % % % % % % % minTimeToTravel = 15;
            minTimeToTravel = 2*startZone + 2;
            app.TimeRemainingsEditField.Value = minTimeToTravel.* 5;%2.5;TODO RESET ME
            app.timeLimit = app.TimeRemainingsEditField.Value;
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.ChainLengthEditField.Value = 12 .* app.MagForceRestrictTmEditField.Value.*1000 + 5000;
            app.previousMagforce = 0;
            app.lastUpdate = clock;
            paddedTestNumber = sprintf( '%04d', app.testNumber);
            newFileName = "Test" + paddedTestNumber + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.tMax = 1;        

            app.particleFunctions = app.particleFunctions.ChangeMetaValues(1.25663706e-6, app.MagneticFieldmTEditField.Value, app.IndividualDiameterEditField.Value, app.ParticleDensitygmlEditField.Value, app.ChainLengthEditField.Value, app.FluidViscocityEditField.Value, app.CeffStat, app.CeffMotion, app.UIAxes.XLim(2));
        
            app.mesh = delaunayTriangulation(app.polygon.currentFlowLocations);
            DT = delaunayTriangulation(app.polygon.currentPoly); 
            IC = incenter(DT);
            ab = [app.polygon.currentPoly ; IC];
            DT = delaunayTriangulation([app.polygon.currentPoly ; IC]); %Add another loop in if needed
            app.ParticleLocationMesh = DT; 

            if(generateNewParticles)
                app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);
             end
            %app.particleArrayVelocity = app.particleFunctions.calculateFlow(app.particleArrayLocation,app.polygon.currentFlowValues, app.mesh);% zeros(app.numParticles, 2); %Starting velocity at
            %fluid speed
            
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            
            hold (app.UIAxes, 'on');
            delete(app.polyLine);
            delete(app.endLine);
            delete(app.wrongEndLine); 
            
            rotateMatrix = [cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)];
            rotatedOutline = (rotateMatrix * app.polygon.currentPoly')';
            app.polyLine = plot(app.UIAxes, rotatedOutline(:,1), rotatedOutline(:,2), 'Color','b');          
        
            app.UIAxes.XLim = [min(app.polygon.currentPoly(:,1)) + (min(app.polygon.currentPoly(:,1))/20), max(app.polygon.currentPoly(:,1)) + (max(app.polygon.currentPoly(:,1))/20)];
            app.UIAxes.YLim = [min(app.polygon.currentPoly(:,2)) + (min(app.polygon.currentPoly(:,2))/20), max(app.polygon.currentPoly(:,2)) + (max(app.polygon.currentPoly(:,2))/20)];
              
            for(lineCount = 1:size(app.polygon.currentEndZone,1))
                rotatedEndZone = (rotateMatrix * squeeze(app.polygon.currentEndZone(lineCount,:,:))')';
                app.wrongEndLine(lineCount) = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','r');
                if(lineCount == app.goalIndex)
                    app.endLine = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','g');
                end
            end 
            app.previousMagforce = 0;
            app.X1TmGauge.Value = 0;
            app.Y1TmGauge.Value = 0;
           % app.fd = FlowData();
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0); 
            newStartZone = app.polygon.currentStartZone;
            allStartZones = [];
            allStartZones(1,:,:) = [0.0085 0.00124; 0.0076 0.00112; 0.008 0.001; 0.0088 0.001; 0.0085 0.00124];
            allStartZones(2,:,:) = [0.0045 0.002; 0.0046 0.002; 0.0046 0.0015; 0.0045 0.0015; 0.0045 0.002];
            allStartZones(3,:,:) = [0.0035 0.0038; 0.0029 0.0030; 0.0033 0.0030; 0.0038 0.0035; 0.0035 0.0038];
            allStartZones(4,:,:) = [0.0045 0.0005; 0.0046 0.0005; 0.0046 0; 0.0045 0; 0.0045 0.0005];
            allStartZones(5,:,:) = [-0.0018 0.0021; -0.0017 0.0012; -0.0015 0.0015; -0.0015 0.0025;-0.0018 0.0021];
            allStartZones(6,:,:) = [-0.0045 -0.003; -0.0036 -0.0035; -0.0031 -0.0033; -0.0044 -0.0032;-0.0045 -0.003];
            allStartZones(7,:,:) = [app.polygon.currentStartZone];
            newStartZone = squeeze(allStartZones(startZone,:,:));
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(newStartZone, app.numParticles);
        end

        %The main function to run. Instead of using timers this is called
        %in a loop to run as fast as possible during training, as no human
        %input is required.
        function [state,reward,action] = stepGame(app, n_actions, expertStates, expertActions, flowRate, t, draw, goalLocation, playing, xShift, yShift)
            if(length(expertActions) > 1)  
                action = expertActions;
            else


                action = double(pyrun("pred = NNet.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=app.particleFunctions.getState(app.particleArrayLocation, flowRate, xShift, yShift, app.polygon.currentEndZone, app.ParticleLocationMesh)));
        

                % pyrun(["print(state)"],state=app.particleFunctions.getState(app.particleArrayLocation, flowRate, xShift, yShift, app.polygon.currentEndZone, app.ParticleLocationMesh));
                % %action = double(pyrun("pred = model.model.predict(np.array([state]),verbose=0, batch_size=1)","pred",state=app.particleFunctions.getState(app.particleArrayLocation, flowRate, xShift, yShift, app.polygon.currentEndZone, app.ParticleLocationMesh)));
                % action = double(PyRun2File(app,"pred = model.model.predict(np.array([state]),verbose=0, batch_size=1)","pred"));
            end
            %Set the force determined by either the input or the NN.
            app.X1TmGauge.Value = action(1);%.*2.5;
            app.Y1TmGauge.Value = action(2);%.*2.5;

            timerCallback(app); %Do the action

            %If using expert states to train then the location needs to be
            %set using the information previously recorded.
            if(length(expertStates) > 1)
                %Force the state to be like the data
                app.particleArrayLocation = expertStates;
            end
            %Only draw if we're supposed to. If we are then draw as though
            %running the simulation.
            if(draw)
                drawTimerCallback(app); %Draw it (or don't)
                drawnow limitrate
            end
            %get the state and reward to return to caller function
            state = app.particleFunctions.getState(app.particleArrayLocation, flowRate, xShift, yShift, app.polygon.currentEndZone, app.ParticleLocationMesh);
            reward = app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation(:,:));%This should be 1 or 0
            reward = sum(reward(:,app.goalIndex))./size(app.particleArrayLocation,1).*100;
        end


%%%%%%%%%%%%%%%%%%  From this point it's about loading data. This
%                   will vary for each data structure loaded,
%%%%%%%%%%%%%%%%%%
%                   currently the top function is the one being used.

function [expertStates,expertActions,valExpertStates, valExpertActions,flowRates] = loadStateActionValPairsFromPolicy(app, numberOfValidationTraces,DropLastnPercentFiles)% n_States, goalPoint, minx, miny, maxx, maxy)
            IMPORTEDexpertStates = [];
            IMPORTEDexpertActions = [];
            IMPORTEDFILEDELIMITERS = [];
            IMPORTEDLastPercentages = [];
            expertStates = [];
            expertActions = [];
            valExpertStates = [];
            valExpertActions = [];
            flowRates = [];

            matFileData = [];

            %Check if we need to load from the mat file or not
            % whichFileToUse = "storedTrainingVariables.mat"
            whichFileToUse = "storedAllDataVariables.mat"
            if isfile(whichFileToUse)
                matFileData = matfile(whichFileToUse);
                IMPORTEDexpertStates = matFileData.IMPORTEDexpertStates;
                IMPORTEDexpertActions = matFileData.IMPORTEDexpertActions;
                IMPORTEDFILEDELIMITERS = matFileData.IMPORTEDFILEDELIMITERS;
                IMPORTEDLastPercentages = matFileData.IMPORTEDLastPercentages;
                %load(whichFileToUse,"IMPORTEDexpertStates", "IMPORTEDexpertActions", "IMPORTEDFILEDELIMITERS", "IMPORTEDLastPercentages");
            else
                impExpertStates=[];
                impExpertActions=[];
                impFileDelimiters=[];
                impLastPercentages=[];
               % impFlowRates=[];
                if(isfile("storedTrainingVariables12-25BREAK.mat"))
                    warning("Use this only when individual (pre-read) data files are too big! (Not Implemented)");
                    "reading binary files"
                    % %DataCleaningNumber just relates to the different
                    % %levels of data cleaning applied - >15, >25 or >35
                    % if(DataCleaningNumber==1)
                    fileNames = ["storedTrainingVariables12-1.mat","storedTrainingVariables34-1.mat","storedTrainingVariables56-1.mat"];
                    % else
                    %     if(DataCleaningNumber==2)
                    %         fileNames = ["storedTrainingVariables12-25.mat","storedTrainingVariables34-25.mat","storedTrainingVariables56-25.mat"];
                    %     else
                    %         fileNames = ["storedTrainingVariables12-35.mat","storedTrainingVariables34-35.mat","storedTrainingVariables56-35.mat"];
                    %     end
                    % end
                    for(SomeCounter=1:length(fileNames))
                        load(fileNames(SomeCounter),"IMPORTEDexpertStates", "IMPORTEDexpertActions", "IMPORTEDFILEDELIMITERS", "IMPORTEDLastPercentages");
                        impExpertStates = cat(1,impExpertStates,IMPORTEDexpertStates);
                        impExpertActions = cat(1,impExpertActions,IMPORTEDexpertActions);
                        impFileDelimiters = cat(2,impFileDelimiters,IMPORTEDFILEDELIMITERS);
                        impLastPercentages = cat(2,impLastPercentages,IMPORTEDLastPercentages);
                    end
                    % IMPORTEDexpertStates = impExpertStates;
                    % IMPORTEDexpertActions = impExpertActions;
                    % IMPORTEDFILEDELIMITERS = impFileDelimiters;
                    % IMPORTEDLastPercentages = impLastPercentages;

                    [sortedLastPercentages,unsortedIndexes] = sort(impLastPercentages,'descend');
                    for(indexCount = 1:length(unsortedIndexes))
                        IMPORTEDexpertStates(end+1:end+size(impExpertStates{unsortedIndexes(indexCount)},1),:,:) = impExpertStates{unsortedIndexes(indexCount)};
                        IMPORTEDexpertActions(end+1:end+size(impExpertActions{unsortedIndexes(indexCount)},1),:) = impExpertActions{unsortedIndexes(indexCount)};
                        IMPORTEDFILEDELIMITERS(end+1) = impFileDelimiters(unsortedIndexes(indexCount));
                        IMPORTEDLastPercentages(end+1) = impLastPercentages(unsortedIndexes(indexCount));
                    end

                    clearvars impExpertStates impExpertActions impFileDelimiters impLastPercentages;

                else
                    if(app.DataStoreLocation == "")
                        "Choose a Folder to read"
                        app.DataStoreLocation = uigetdir;
                    end
                    if(length(app.dataFileNameList) < 1)
                        "Reading FileNames"
                        dOuter = dir(app.DataStoreLocation);
                        for dirCount = 1:length(dOuter)
                            innerFolderName = dOuter(dirCount).name;
                            if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                                fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                                for fileCount = 1:length(fileList)
                                    if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                        filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                        app.dataFileNameList{end+1} = filePathToAdd;
                                    end
                                end
                            end
                        end
                    "Done"
                    end               
    

                    allExpertStates = {};
                    allExpertActions = {};
                    allFileDelimitLineCounts = [];
                    allLastPercentages = [];
    
    
                    "Reading the expert files"
                    for(fileCount = 1:length(app.dataFileNameList))
                        chosenFile = string(app.dataFileNameList(fileCount));
                        %Open the file to read
                        flowRate = (split(chosenFile,'\'));
                        flowag = flowRate(end-1);
                        if(strlength(flowag)>1)
                            flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
                        else
                            flowRateNormalised = str2double(flowag);
                        end
                        if(isnan(flowRateNormalised))
                            flowRateNormalised = realmin.*6;
                            "There was some error with reading the flow rate - this data is bad..."
                        end
                        flowRateNormalised = flowRateNormalised./60; %Check this translates between matlab code and actual data - determines the flow velocities used to train with
                        fileToUse = fopen(chosenFile);
                        tline = fgetl(fileToUse);
                        finished = false;
                        lineCount=0;
                        LastPercentage = 0;
                        StatesToAdd = [];
                        ActionsToAdd = [];
                        lastParticleLocations = [];
                        while(ischar(tline) && ~finished)
                            lineCount = lineCount + 1;
                            datas = split(tline,',');
                           % time = datas(1);
                            magForce = datas(2);
                           % goalPercentage = datas(3);
                           LastPercentage = str2double(datas(3));
                           % velocities = datas(4);
                            allPositions = datas(5);
                            goalPositions = datas(6);
    
     %Each is a response to the state of the PREVIOUS state (so state n should result in action n+1)
                            if(isempty(lastParticleLocations))
                                lastParticleLocations = [allPositions];
                                tline = fgetl(fileToUse);
                                continue;
                            end
    
                            
                            allMag = strip(magForce,'[');
                            allMag = strip(allMag,']');
                            allMag = split(allMag,' ');
                            magx = str2double(allMag(1));
                            magy = str2double(allMag(2));
            
                            positions = lastParticleLocations;
                            positions = strip(positions,'[');
                            positions = strip(positions,']');
                            splitPositions = split(positions,';');
                            positionArray = [];
    
                            goals = strip(goalPositions,'[');
                            goals = strip(goals,']');
                            goals = split(goals,';');
    
                            for(lineIndex = 1:length(splitPositions))
                                yPos = split(splitPositions(lineIndex,:)," ");
                                xPos = str2double(yPos(1));
                                yPos = str2double(yPos(2));
                                allGoals = split(goals(lineIndex), " ");
                                g1 = str2double(allGoals(1));
                                g2 = str2double(allGoals(2));
                                g3 = str2double(allGoals(3));
                                g4 = str2double(allGoals(4));
                                g5 = str2double(allGoals(5));
                                positionArray(end+1,:) = [xPos.*100, yPos.*100,flowRateNormalised,g1,g2,g3,g4,g5];
                            end
                            ActionsToAdd(end+1,:) = [magx,magy];
                            StatesToAdd(end+1,:,:) = positionArray;
                            tline = fgetl(fileToUse);

                            %Use to confirm data being read is correct
                            % plotStateOnFig(app,squeeze(positionArray(:,1:2)./100),[magx,magy]); %Plot the state onto the image to see.
                            % pause(0.01);

                            lastParticleLocations = [allPositions];
                        end
    
                        allExpertStates{end+1} = StatesToAdd;
                        allExpertActions{end+1} = ActionsToAdd;
                        allFileDelimitLineCounts(end+1) = lineCount - 1;
                        allLastPercentages(end+1) = LastPercentage;
    
                        fclose(fileToUse);
                        "File" + fileCount + "/" + length(app.dataFileNameList) + " Read"
                    end
                    %We've read all data, now sort based on lastPercentages
                    [sortedLastPercentages,unsortedIndexes] = sort(allLastPercentages,'descend');
                    for(indexCount = 1:length(unsortedIndexes))
                        IMPORTEDexpertStates(end+1:end+size(allExpertStates{unsortedIndexes(indexCount)},1),:,:) = allExpertStates{unsortedIndexes(indexCount)};
                        IMPORTEDexpertActions(end+1:end+size(allExpertActions{unsortedIndexes(indexCount)},1),:) = allExpertActions{unsortedIndexes(indexCount)};
                        IMPORTEDFILEDELIMITERS(end+1) = allFileDelimitLineCounts(unsortedIndexes(indexCount));
                        IMPORTEDLastPercentages(end+1) = allLastPercentages(unsortedIndexes(indexCount));
                    end
    
                    if(~isfile("storedTrainingVariables12-25BREAK.mat")) %https://uk.mathworks.com/help/matlab/ref/matlab.io.matfile.html %TODO USE MATFILE
                        filNam = "storedTrainingVariables.mat";
                        matFileData = matfile(filNam,"Writable",true);
                        % matFileData.ES = IMPORTEDexpertStates;
                        % matFileData.EA = IMPORTEDexpertActions;
                        % matFileData.FD = IMPORTEDFILEDELIMITERS;
                        % matFileData.LP = IMPORTEDLastPercentages;
                        save(filNam,"IMPORTEDexpertStates", "IMPORTEDexpertActions", "IMPORTEDFILEDELIMITERS", "IMPORTEDLastPercentages",'-v7.3');
                    end
                end
            end

            "Removing Last N Percent of traces"
            % IMPORTEDexpertStates IMPORTEDexpertActions IMPORTEDFILEDELIMITERS IMPORTEDLastPercentages
            if(DropLastnPercentFiles > 0)% Only remove total last Number.
                if(DropLastnPercentFiles > 1)
                    warning("DropLastnPercentFiles should be between 0 and 1. Reduce this value to remove files")
                else
                    DelimLength = length(IMPORTEDFILEDELIMITERS);
                    IndexUpTo = DelimLength - floor(DelimLength .* DropLastnPercentFiles); %Get the index we need to include up to
                    if(IndexUpTo < DelimLength)
                        DiscardAfterDelimiterN = 0;
                        for(indexCount = 1:IndexUpTo)
                            DiscardAfterDelimiterN = DiscardAfterDelimiterN + IMPORTEDFILEDELIMITERS(indexCount);
                        end
                        DiscardAfterDelimiterN = DiscardAfterDelimiterN + 1;
                        IMPORTEDexpertStates(DiscardAfterDelimiterN:end,:,:) = [];
                        IMPORTEDexpertActions(DiscardAfterDelimiterN:end,:) = [];
                        IMPORTEDFILEDELIMITERS(IndexUpTo+1 : DelimLength) = [];
                        IMPORTEDLastPercentages(IndexUpTo+1 : DelimLength) = [];
                    end
                end
            end

            "Sorting out Validation"
            if(numberOfValidationTraces<1)
                lengthValidation = floor(numberOfValidationTraces .* length(IMPORTEDFILEDELIMITERS));
            else
                lengthValidation = numberOfValidationTraces;
            end

            validationSet = randi(length(IMPORTEDFILEDELIMITERS),lengthValidation,1);
            fileStartCursor = 1;
            for(fileCount = 1:length(IMPORTEDFILEDELIMITERS))
                if(any(validationSet(:) == fileCount))    
                    % fileCount
                    % if(fileCount == 209 || fileCount == 207 || fileCount == 197)
                    %     "iiiiiii"
                    % end
                    valExpertActions(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:) = squeeze(IMPORTEDexpertActions(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:));
                    valExpertStates(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:,:) = squeeze(IMPORTEDexpertStates(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:,:));
                else                    
                    aaa = squeeze(IMPORTEDexpertActions(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:));
                    expertActions(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:) = squeeze(IMPORTEDexpertActions(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:));
                    expertStates(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:,:) = squeeze(IMPORTEDexpertStates(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:,:));
                end
                fileStartCursor = fileStartCursor+IMPORTEDFILEDELIMITERS(fileCount); %added filecount here
            end   

            if(false)
                save("ExampleTunedDataFile.mat","expertStates", "expertActions", "valExpertStates", "valExpertActions");
            end
        end

        function plotStateOnFig(app,particleLocs, action)
            delete(app.magLine);
            delete(app.particlePoints);
            delete(app.DebugPoint);
            delete(app.DebugText);
            rotatedMagLine = ([0,action(1)./(app.maxForce.*75) ; 0,action(2)./(app.maxForce.*75)])';
            app.magLine = plot(app.UIAxes,rotatedMagLine(:,1),rotatedMagLine(:,2),'magenta');
            rotatedPoints = ([cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)] * particleLocs')';
            app.particlePoints = plot(app.UIAxes, app.particleFunctions.realNum(rotatedPoints(:,1)), app.particleFunctions.realNum(rotatedPoints(:,2)),'r.', 'markerSize', 3);

            currentState = app.particleFunctions.getState(particleLocs, 1/6, 0, 0, app.polygon.currentEndZone, app.ParticleLocationMesh);
            for(i = 1:size(app.ParticleLocationMesh.Points,1))
                plotValue = currentState(i).*50;
                if(plotValue <= 0 | isinf(plotValue) | isnan(plotValue))
                    plotValue = 1;
                end
                app.DebugPoint(end+1) = plot(app.UIAxes,app.ParticleLocationMesh.Points(i,1),app.ParticleLocationMesh.Points(i,2),'c.', 'markerSize', plotValue);             
                app.DebugText(end+1) = text(app.UIAxes,app.ParticleLocationMesh.Points(i,1),app.ParticleLocationMesh.Points(i,2),string(currentState(i)));
            end
        end

         % function plotStateOnFig(app)
         %    delete(app.magLine);
         %    delete(app.particlePoints);
         %    delete(app.DebugPoint);
         %    delete(app.DebugText);
         %    rotatedMagLine = ([cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)] * [0,app.X1TmGauge.Value./(app.maxForce.*75) ; 0,app.Y1TmGauge.Value./(app.maxForce.*75)])';
         %    app.magLine = plot(app.UIAxes,rotatedMagLine(:,1),rotatedMagLine(:,2),'magenta');
         %    rotatedPoints = ([cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)] * app.particleArrayLocation')';
         %    app.particlePoints = plot(app.UIAxes, app.particleFunctions.realNum(rotatedPoints(:,1)), app.particleFunctions.realNum(rotatedPoints(:,2)),'r.', 'markerSize', 3);
         % 
         %    currentState = app.particleFunctions.getState(app.particleArrayLocation, 1/6, 0, 0, app.polygon.currentEndZone, app.ParticleLocationMesh);
         %    for(i = 1:size(app.ParticleLocationMesh.Points,1))
         %        plotValue = currentState(i).*50;
         %        if(plotValue <= 0 | isinf(plotValue) | isnan(plotValue))
         %            plotValue = 1;
         %        end
         %        app.DebugPoint(end+1) = plot(app.UIAxes,app.ParticleLocationMesh.Points(i,1),app.ParticleLocationMesh.Points(i,2),'c.', 'markerSize', plotValue);             
         %        app.DebugText(end+1) = text(app.UIAxes,app.ParticleLocationMesh.Points(i,1),app.ParticleLocationMesh.Points(i,2),string(currentState(i)));
         %    end
         % end


 %%%These aren't used, but were used recently so should be kept.

        function [rewardXY] = loadNewRewardFromPolicy(app,minx,maxx,miny,maxy,binsx,binsy)
            %Make the state space
            rewardXY = zeros(binsx,binsy);

            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            expertStates = [];
            expertActions = [];

            "Reading the expert file"
            %chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %for(i = 1:length(app.dataFileNameList))
            for(i = 1:5)
                chosenFile = string(app.dataFileNameList(i));
                tl = 0;
                app.TimeRemainingsEditField.Value = tl;
                %flowRgfg = flowRatje(length(flowRatje));
                %flowRate = str2double(flowRate)%./6;
                fileToUse = fopen(chosenFile);
                tline = fgetl(fileToUse);
                %Similar to "play" loop above
                FE = 0;
                %FE = [];
                distanceCount = 0;
                finished = false;
                newExpertFE = [];
                while(ischar(tline) && ~finished)
                    datas = split(tline,',');
                    time = datas(1);
                    magForce = datas(2);
                    goalPercentage = datas(3);
                    velocities = datas(4);
                    positions = datas(5);
                    goalPositions = datas(6);
                    
                    allMag = strip(magForce,'[');
                    allMag = strip(allMag,']');
                    allMag = split(allMag,' ');
                    magx = str2double(allMag(1));
                    magy = str2double(allMag(2));
    
                    positions = strip(positions,'[');
                    positions = strip(positions,']');
                    allPositions = split(positions,';');
                    positionArray = [];
                    for(lineIndex = 1:length(allPositions))
                        yPos = split(allPositions(lineIndex)," ");
                        xPos = str2double(yPos(1));
                        yPos = str2double(yPos(2));
                        
                        binx = floor(((xPos - minx) / (maxx - minx))*binsx);
                        biny = floor(((yPos - miny) / (maxy - miny))*binsy);
                        if(binx<=binsx&&biny<=binsy&&binx>0&&biny>0)                            
                            rewardXY(binx,biny) = rewardXY(binx,biny) + 1;
                        end
                    end
    
                    distanceCount = distanceCount + 1;
                    tline = fgetl(fileToUse);
                end
                "File Read"
            end
        end

        function [expertStates,expertActions,flowRates] = loadStateActionPairsFromPolicy(app)% n_States, goalPoint, minx, miny, maxx, maxy)
            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            expertStates = [];
            expertActions = [];
            flowRates = [];

            "Reading the expert file"
            chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %Open the file to read
            flowRate = (split(chosenFile,'\'));
            flowag = flowRate(end-1);
            if(strlength(flowag)>1)
                flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
            else
                flowRateNormalised = str2double(flowag);
            end
            if(isnan(flowRateNormalised))
                flowRateNormalised = realmin.*6;
                "There was some error with reading the flow rate - this data is bad..."
            end
            flowRates(end+1) = flowRateNormalised;
            % tl = 0;
            % switch(flowRateNormalised)
            %     case(1)
            %         tl=15;
            %     case(2)
            %         tl=15;
            %     case(3)
            %         tl=12;
            %     case(4)
            %         tl=12;
            %     case(5)
            %         tl=10;
            %     case(6)
            %         tl=10;
            % end
            % flowRateNormalised = flowRateNormalised./6;
            % app.TimeRemainingsEditField.Value = tl;
            %flowRgfg = flowRatje(length(flowRatje));
            %flowRate = str2double(flowRate)%./6;
            fileToUse = fopen(chosenFile);
            tline = fgetl(fileToUse);
            %Similar to "play" loop above
            %FE = 0;
            %FE = [];
            distanceCount = 0;
            finished = false;
            %newExpertFE = [];
            stateList = [];
            actionList = [];
            while(ischar(tline) && ~finished) %(app.timePassed <= app.timeLimit)
                                
                datas = split(tline,',');
                time = datas(1);
                magForce = datas(2);
                goalPercentage = datas(3);
                velocities = datas(4);
                positions = datas(5);
                goalPositions = datas(6);
                
                allMag = strip(magForce,'[');
                allMag = strip(allMag,']');
                allMag = split(allMag,' ');
                magx = str2double(allMag(1));
                magy = str2double(allMag(2));

                % velocities = strip(velocities,'[');
                % velocities = strip(velocities,']');
                % allVelocities = split(velocities,';');
                 positions = strip(positions,'[');
                 positions = strip(positions,']');
                % goalPositions = strip(goalPositions,'[');
                % goalPositions = strip(goalPositions,']');
                % allGoalPositions = split(goalPositions,';');
                 allPositions = split(positions,';');
                % %matState = [0 0 0 0];
                % sumAdded = 0;
                % xSum = 0;
                % ySum = 0;
                % distancex = 0;
                % distancey = 0;
                positionArray = [];
                 for(lineIndex = 1:length(allPositions))
                     yPos = split(allPositions(lineIndex)," ");
                     xPos = str2double(yPos(1));
                     yPos = str2double(yPos(2));
                     positionArray(end+1,:) = [xPos,yPos].*100;
                %     % if(yPos>0) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                %     %     finished = true;
                %     %     %break;
                %     % end
                    % if(yPos>0||yPos<-0.008) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                    %      finished = true;
                    %  end
                %     % if(all(app.haltParticlesInEndZone))
                %     %     finished = true;
                %     % end
                %     xSum = xSum - xPos;
                %     ySum = ySum - yPos; %TODO!!! REEMEMBER!!! This can't be negative if we're using anything above 0
                % 
                %     distancex = distancex + sqrt((xPos-goalPoint(1)).^2);% + 
                %     distancey = distancey + sqrt((yPos-goalPoint(2)).^2);
                % 
                 end
                % for(lineIndex = 1:length(allPositions))
                %     if(~contains(allGoalPositions(lineIndex),'1'))
                %      xAndYVelocities = split(allVelocities(lineIndex),' ');
                %      xAndYPositions = split(allPositions(lineIndex),' ');
                %      matState = matState + [str2double(xAndYPositions(1)), str2double(xAndYPositions(2)) ,str2double(xAndYVelocities(1)), str2double(xAndYVelocities(2))];
                %      sumAdded = sumAdded + 1;
                %     end
                % end
                % matState = matState ./ sumAdded; %Get the average...
                %matState = [distancex, distancey, flowRateNormalised];
                expertActions(end+1,:) = [magx,magy];
                expertStates(end+1,:,:) = positionArray;

                distanceCount = distanceCount + 1;
                tline = fgetl(fileToUse);
            end
            avgForce = sum(expertActions,1)./size(expertActions,1)
             % if(length(FE) ~= n_States) %This is recursive - make sure to not get stuck here...
             %    newExpertFE = loadNewPolicy(app,n_States,Gamma,goalPoint);
             % end
            "File Read"           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
            end
            try
                f.close(app.fileID);
            catch
                "No file to close";
            end
            close all;
            delete(app)
            clear;
            disp("App Stopped")
        end

        function LogPythonStuff(pyLog)
            fID = fopen("PythonLog",'a');
            fprintf(fID, datetime("now") + ":" + pyLog);
            fclose(fID);
        end
        
        function outpt = PyRun2File(app,pythonCode, pythonOutput)
            if(nargin < 3 )
                pythonOutput = [];
            end
            fID = fopen("PythonLog.txt",'a+');
            for(i = 1:length(pythonCode))
                StringToWrite = string(datetime("now")) + " --- " + pythonCode(i) + " --- " + newline;
                fprintf(fID, StringToWrite);
                if(isempty(pythonOutput))                    
                    pyrun(pythonCode(i));
                else
                    %This MUST be cast to the correct MATLAB type, at the
                    %function call please! (otherwise errors!)
                    outpt = pyrun(pythonCode(i),pythonOutput);
                end
            end
            fclose(fID);
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Callback function
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Callback function
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Callback function
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Callback function
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Callback function
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)

        end

        % Button pushed function: StartTrainingButton
        function StartTrainingOLDButtonValueChanged(app, event)
           
        end

        % Callback function
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData();
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Callback function
        function MagForceRestrictMAM2EditFieldValueChanged(app, event)
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Callback function
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
        import java.awt.*;
        import java.awt.event.*;
        %Create a Robot-object to do the key-pressing
        rob=Robot;
        %Commands for pressing keys:
        % If the text cursor isn't in the edit box allready, then it
        % needs to be placed there for ctrl+a to select the text.
        % Therefore, we make sure the cursor is in the edit box by
        % forcing a mouse button press:
        rob.mousePress(InputEvent.BUTTON1_MASK );
        rob.mouseRelease(InputEvent.BUTTON1_MASK );
        % CONTROL + A : 
        rob.keyPress(KeyEvent.VK_SPACE)
        rob.keyRelease(KeyEvent.VK_SPACE)
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictMAM2EditField.Value > 0 && app.MagForceRestrictMAM2EditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictMAM2EditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1MAGauge.Value = app.mousePosition(1) .* 250;
                app.Y1MAGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            app.MagForceRestrictTmEditFieldLabel = uilabel(app.UIFigure);
            app.MagForceRestrictTmEditFieldLabel.Enable = 'off';
            app.MagForceRestrictTmEditFieldLabel.Text = 'MagForceRestrict (T/m)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.UIFigure);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.HapticForceSlider = uislider(app.UIFigure);
            app.HapticForceSlider.Limits = [1 4];
            app.HapticForceSlider.MajorTicks = [1 2 3 4];
            app.HapticForceSlider.MinorTicks = [1 2 3 4];
            app.HapticForceSlider.Enable = 'off';
            app.HapticForceSlider.Visible = 'off';
            app.HapticForceSlider.Value = 3;
            app.X1TmGaugeLabel = uilabel(app.UIFigure);
            app.X1TmGaugeLabel.Text = 'X1 (T/m)';
            app.X1TmGauge = uigauge(app.UIFigure, 'linear');
            app.X1TmGauge.Limits = [-1 1];
            app.X1TmGauge.MajorTicks = [-1 0 1];
            app.X1TmGauge.MajorTickLabels = {'-1', '.', '1'};
            app.X1TmGauge.MinorTicks = [-0.75 -0.25 0.25 0.75];
            app.Y1TmGaugeLabel = uilabel(app.UIFigure);
            app.Y1TmGaugeLabel.Text = 'Y1 (T/m)';
            app.Y1TmGauge = uigauge(app.UIFigure, 'linear');
            app.Y1TmGauge.Limits = [-1 1];
            app.Y1TmGauge.MajorTicks = [-1 0 1];
            app.Y1TmGauge.MajorTickLabels = {'-1', '.', '1'};
            app.Y1TmGauge.MinorTicks = [-0.75 -0.25 0.25 0.75];
            app.FluidFlowmsEditFieldLabel = uilabel(app.UIFigure);
            app.FluidFlowmsEditFieldLabel.Enable = 'off';
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FluidFlowmsEditField.Editable = 'off';
            app.FluidFlowmsEditField.Enable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofParticlesEditFieldLabel.Enable = 'off';
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Enable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ChainLengthEditFieldLabel = uilabel(app.UIFigure);
            app.ChainLengthEditFieldLabel.Enable = 'off';
            app.ChainLengthEditFieldLabel.Text = 'Chain Length';
            app.ChainLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChainLengthEditField.Editable = 'off';
            app.ChainLengthEditField.Enable = 'off';
            app.ChainLengthEditField.Value = 12000;
            app.IndividualDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.IndividualDiameterEditFieldLabel.Enable = 'off';
            app.IndividualDiameterEditFieldLabel.Text = 'Individual Diameter';
            app.IndividualDiameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndividualDiameterEditField.Editable = 'off';
            app.IndividualDiameterEditField.Enable = 'off';
            app.IndividualDiameterEditField.Value = 7.5e-08;
            app.ParticleDensitygmlEditFieldLabel = uilabel(app.UIFigure);
            app.ParticleDensitygmlEditFieldLabel.Enable = 'off';
            app.ParticleDensitygmlEditFieldLabel.Text = 'Particle Density (g/ml)';
            app.ParticleDensitygmlEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticleDensitygmlEditField.Editable = 'off';
            app.ParticleDensitygmlEditField.Enable = 'off';
            app.ParticleDensitygmlEditField.Value = 4.8;
            app.MagneticFieldmTEditFieldLabel = uilabel(app.UIFigure);
            app.MagneticFieldmTEditFieldLabel.Enable = 'off';
            app.MagneticFieldmTEditFieldLabel.Text = 'Magnetic Field (mT)';
            app.MagneticFieldmTEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagneticFieldmTEditField.Editable = 'off';
            app.MagneticFieldmTEditField.Enable = 'off';
            app.MagneticFieldmTEditField.Value = 3;
            app.FluidViscocityEditFieldLabel = uilabel(app.UIFigure);
            app.FluidViscocityEditFieldLabel.Enable = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Enable = 'off';
            app.FluidViscocityEditField.Value = 0.001;
            app.MagForceRestrictTmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagForceRestrictTmEditField.Editable = 'off';
            app.MagForceRestrictTmEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationTrainModeFFPlayFirstHomePC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end