classdef particleSimulationTrainModeRL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartTrainingButton             matlab.ui.control.Button
        ScenarioEditField               matlab.ui.control.EditField
        ScenarioEditFieldLabel          matlab.ui.control.Label
        CentreMassViewCheckBox          matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ControllerButton                matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        KeyboardButton                  matlab.ui.control.RadioButton
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField            matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel       matlab.ui.control.Label
        MagForceRestrictMAM2EditField   matlab.ui.control.NumericEditField
        MagForceRestrictMAM2EditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PercentageinGoalEditField       matlab.ui.control.NumericEditField
        TimeremainingsLabel             matlab.ui.control.Label
        TimeRemainingsEditField         matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        simulationTimerProperty
        drawTimerProperty
        hapticFeedbackIdleProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        hapticFeedback
        testOrder
        maxForce
        QuestionaireFID
        DataStoreLocation
        dataFileNameList
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           rng(1000,"twister");
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05; %0.05 %Draw was 0.3
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 2.25;%1.6;
            app.printCounter = 0;
%              fourPolygonLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
%             fourMeshValuesLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\BenResult0_02.csv";
% %             fourPolygonLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
% %             fourMeshValuesLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\BenResult0_01.csv";
%             [locX,locY,flowX,flowY] = ReadInFlowDataFromComsolCSV(fourPolygonLocation, fourMeshValuesLocation);
%             saveME = [locX;locY]';
%             writematrix(saveME,'M.csv') 
        %   app.fd = FlowData([locX; locY]', [flowX; flowY]');
            app.fd = FlowData05();
            %general simulation timer
          %  app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
          %  app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
          %  app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            %app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "TrainingModel";
            InitHaptic();
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0);
            app.timeLimit = 1;%120;
            app.testNumber = 0;
            app.rotation = 0;
            app.hapticFeedback = [0,0,0];
            %50 tests
            app.testOrder = 1:120;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.testOrder = [app.testOrder,length(app.testOrder)+1];
            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);
            app.DataStoreLocation = "D:\EssexDocuments\EXMAR23\unrandomised\Combined";
            % app.DataStoreLocation = "";
            app.dataFileNameList = {};

%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
    %        app.mesh =  alphaShape(app.fd.FlowLocations{1});
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            if(false) %Set to true if needed.
                app.polygon = app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
%             prompt = {'Age','Gender','Corrected Eyesight','Right/Left Handed'};
%             dlgtitle = 'Statistical questions';
%             answer = inputdlg(prompt,dlgtitle);
%             app.QuestionaireFID = fopen("Questionaire" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
%             fprintf(app.QuestionaireFID,strcat('\r\n Age,',answer{1}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Gender,',answer{2}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Corrected Eyesight,',answer{3}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Right/Left Handed,',answer{4}));
            %fclose(app.QuestionaireFID);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            value = app.MagForceRestrictMAM2EditField.Value;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                 set(app.StartTrainingButton,'Text',"Running...");
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                app.StartTrainingButton.Enable = false;
    
                %IRL First Test
                %Environment Setup
                if(app.testNumber == 0)
                    resetSimForMLTraining(app);
                end
                terminate(pyenv)
                try
                    pyenv("Version","C:\Users\beno1\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                catch
                    pyenv("Version","C:\Users\bj20907\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                    app.DataStoreLocation = "";
                end
                if count(py.sys.path,'') == 0
                    insert(py.sys.path,int32(0),'');
                end
    
    
                DRAWTRAIN = false;
                DRAWPLAY = true;
                
                n_states = 3;
                n_actions = 2; %Use regression instead here
                h_Layers = [80,160];%[164,150];
                e_psilon = 0.05;
                l_rate = 0.05;
                Gamma = 0.9;
                flow_rate = 0;
                totalSteps = 4;
                trainingLoops = 1;
                h_layers_basic = h_Layers;

                for(i = [41:20:41])
                    buffer = 80;
                    goalLocation = [0.010644161 -0.002909076]; %Use this for distance to goal calculations
        
                    pyrun("from pythontestIRLCustomStartSimple import *")
                   % pyrun("obj1 = QLearning(py_n_states, py_n_actions)", py_n_states = n_states, py_n_actions = n_actions)
                    pyrun("random.seed(1000)");
                    %currentExpertPolicy = [0.51,0.05,0.1,0.1];%test this
                    [currentExpertPolicy,flow_rate] = loadNewPolicy(app,n_states,Gamma,goalLocation);
                    pyrun("irl = IRLClass(numStates,numActions,hiddenLayers,expertFE,epsilon,lr)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers, expertFE=currentExpertPolicy,epsilon = e_psilon, lr = l_rate);
        
                    pyrun("irl.model.summary()")
        
                    stepIndex = 0;
                    hDistToPrint = [];
                    while(stepIndex < totalSteps)
                        % currentExpertPolicy
                      %  try
                            FirstLayerW = pyrun("FirstLayerWeights = irl.Optimise(expertPolicy)","FirstLayerWeights",expertPolicy = currentExpertPolicy);
                            %UpdatePolicyList here
                      %  catch
                      %      stepIndex = 9;
                      %      FirstLayerW = zeros(1,n_states);
                      %  end
        
                        %'Train'
                        "Training Commencing"
                        for(trainingIndex = 1:trainingLoops)
                            t = 0;
                            observe = 1;
                            epsilon = 1-((trainingIndex/trainingLoops)*0.4); %reduce epsilon
                            batchSize = 10;
                            ePochs = 1;
                            %buffer = 50;
                            %trainFrames = 10000;
                            matState = getState(app,flow_rate,goalLocation);
                            replayThingy = [];
                            finished = false;
                            loss_history = [];
                            acc_history = [];
                            episode_reward = 0;


                            action_history = [];
                            state_history = [];
                            state_next_history = [];
                            %done_history = [];
                            rewards_history = [];

                            while(app.timePassed <= app.timeLimit && ~finished)%(t<trainFrames) %Do we need to train for n frames? can this be covered by timePassed?
                                %epsilon = epsilon * 0.999;
                                [next_matState,matReward,matAction] = stepGame(app,epsilon,flow_rate,DRAWTRAIN,goalLocation);
                                
                                action_history(end+1) = matAction;
                                state_history(end+1) = matState;
                                state_next_history(end+1) = next_matState;
                                %done_history(end+1) = 0;
                                rewards_history(end+1) = matReward;
                                episode_reward = episode_reward + matReward;
                                
                                if(size(action_history,1)>batchSize)
                                    randomIndicies = randi(size(action_history,1),1,batchSize)
                                    
                                    state_sample = state_history(randomIndicies,:);
                                    state_next_sample = state_next_history(randomIndicies,:);
                                    rewards_sample = rewards_history(randomIndicies,:);
                                    action_sample = action_history(randomIndicies,:);
                                    %done_sample = done_history(randomIndicies,:);

                                    future_rewards = pyrun("fR = irl.model.predict(state_NS)","fR",state_NS = state_next_sample)
                                    updated_q_values = rewards_sample + gamma .* max(future_rewards,[],1)
                                    %If we're on the final frame do
                                    %something with negating the q value
                                    %Do some other stuff with tape or
                                    %something for BP
                                end


                   %              if(any(app.particleArrayLocation(:,2)>0)||any(app.particleArrayLocation(:,2)<-0.008)) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                   %                  finished = true;
                   %              end
                   %              replayThingy(end+1,:) = [matState,matAction,matReward,next_matState];
                   %              if(t>observe && t > batchSize)% && mod(t,10) == 0) %observe can just view to learn from the actions taken
                   %                  %TRAIN
                   %                  if (length(replayThingy) > buffer)
                   %                      %replay.pop(0)
                   %                      replayThingy = replayThingy(2:end,:); %Trying to 'pop' first item of list
                   %                  end
                   %                  if(epsilon > 0.1)
                   %                      epsilon = epsilon*0.99; %reduce epsilon slowly
                   %                  end
                   %                  %%%%%%%%%%%%%%%%%%%%%%%%%%%
                   %                  %# Randomly sample our experience replay memory
                   %                  %minibatch = random.sample(replay, batchSize)
                   % 
                   %                  minibatch = datasample(replayThingy,batchSize,1);
                   % 
                   %                  %# Get training values.
                   %                  %X_train, y_train = process_minibatch(minibatch, model)
                   % 
                   %                  xTrain = [];
                   %                  yTrain = [];
                   %                  for(batchIndex = 1:size(minibatch,1))
                   %                      old_State=minibatch(batchIndex,1:3);
                   %                      action=minibatch(batchIndex,4:5);
                   %                      reward=minibatch(batchIndex,6);
                   %                      new_State=minibatch(batchIndex,7:9);
                   %                      %# Get prediction on old state.
                   %                      pyrun("old_qval = irl.model.predict(np.array([old_state_m]), batch_size=1, verbose=0)",old_state_m = old_State);
                   %                      %# Get prediction on new state.
                   %                      pyrun("newQ = irl.model.predict(np.array([new_state_m]), batch_size=1, verbose=0)",new_state_m = new_State);
                   %                      %# Get our best move. I think?
                   %                      pyrun("maxQ = np.max(newQ)");
                   %                      pyrun("y = np.zeros((1,int(n_Actions)))",n_Actions=n_actions);
                   %                      pyrun("y[:] = old_qval[:]");
                   %                      %# Check for terminal state.
                   %                      %#if reward_m != -500:  # non-terminal state
                   %                          %#update = (reward_m + (GAMMA * maxQ))
                   %                      %#else:  %# terminal state
                   %                          %#update = reward_m
                   %                  %    if new_state_m[0][7] == 1:  #terminal state
                   %                  %        update = reward_m
                   %                 %     else:  %# non-terminal state
                   %                         uu = pyrun("update = (reward_m + (GAMMA * maxQ))","update",reward_m = reward, GAMMA = Gamma);
                   %                      %# Update the value for the action we took.
                   % %                     yvar=pyrun("y[0][action_m] = update","y",action_m = action);
                   %                      xTrain(end+1,:) = old_State;
                   %                      yTrain(end+1,:) = action; %THIS DOESN't TAKE INTO ACCOUNT REWARD
                   %                  end
                   % 
                   %                  %# Train the model on this batch.
                   %                  %history = LossHistory()
                   %                  %model.fit(X_train, y_train, batch_size=batchSize,nb_epoch=1, verbose=0, callbacks=[history])
                   %                  %loss_log.append(history.losses)
                   %                  hist = pyrun("uu = irl.model.fit(np.squeeze(np.array(x_train)), np.squeeze(np.array(y_train)), batch_size=int(batchSize),epochs=int(epoch), verbose=0)","uu",x_train=xTrain,y_train=yTrain,batchSize = batchSize, epoch = ePochs);
                   %                  %hh = hist.history
                   %                  loss_history(end+1) = double(hist.history{'loss'});
                   %                  acc_history(end+1) = double(hist.history{'accuracy'});
                   %                  %plot(t,hist.history{'loss'});
                   %                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                   %              end
                                t = t + 1;
                                matState = next_matState;
                            end
                            epsilon
                            figure
                            plot(loss_history);
                            hold on
                            plot(acc_history);
                            hold on
                            legend(["loss","accuracy"])
                            Training_Progress = floor(trainingIndex/trainingLoops .*100)+"%"
            
                            resetSimForMLTraining(app);
                        end
        
                        %'Play'
                        "Playing Commencing"
                        distanceCount = 0;
                        matState = getState(app,flow_rate,goalLocation);
                        pyrun("featureExpectations = np.zeros(int(num_States))",num_States = n_states);
                        FE = 0;
                        %FE = [];
                        finished = false;
                        while(app.timePassed <= app.timeLimit && ~finished)
                            [matState,matReward,matAction] = stepGame(app,0,flow_rate,DRAWPLAY,goalLocation);
                             if(any(app.particleArrayLocation(:,2)>0)||any(app.particleArrayLocation(:,2)<-0.008)) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                                 finished = true;
                             end
                            if(distanceCount >5)
                                FE = pyrun("featureExpectations += (GAMMA**(dist-5))*np.array(readings)","featureExpectations",GAMMA=Gamma,dist = distanceCount, readings = matState); %#TODO the 'readings' are the state, aren't they?
                                %FE(end+1,:) = (Gamma^(distanceCount-50))*matState;
                            end
                            distanceCount = distanceCount + 1;
                        end
                        hyprDist = pyrun("newHyperDistance = irl.UpdatePolicyList(FirstLayerWeights,PlayFE,stepI)","newHyperDistance", FirstLayerWeights = FirstLayerW, PlayFE = FE, stepI = stepIndex)
                        hDistToPrint(end+1) = hyprDist;
                        stepIndex = stepIndex+1;
                        if(hyprDist <= e_psilon) %#hyperdistance is the 'error' if you will, so above we are positioning the new data in order of error? I think?
                           break
                        else
                            %Reset NN (this should allow for better randomness?
                            pyrun("irl.model = irl.ConstructNetwork(irl.n_states, irl.n_actions, hiddenLayers)",hiddenLayers=h_Layers);
                        end
                        resetSimForMLTraining(app);
                        %Get a new policy to train
                        % currentExpertPolicy = loadNewPolicy(app,n_states,Gamma,goalLocation);
                    end
                    %resetSimForMLTraining(app);
                    app.fileID = fopen("MachineLearningTraining04.txt",'a');%Just appending, don't need to read anything.
                    filedescriptionsdg = "19-05-23 01, n_states = " + num2str(n_states) + ", n_actions = " + num2str(n_actions) + ", h_Layers = " + num2str(h_Layers) + ", e_psilon = " + num2str(e_psilon) + " l_rate = " + num2str(l_rate) + " Gamma = " + num2str(Gamma) + " flow_rate = " + num2str(flow_rate) + " totalSteps = " + num2str(totalSteps) + " trainingLoops = " + num2str(trainingLoops);
        
                    fprintf(app.fileID, filedescriptionsdg +"\r\n");
                    fprintf(app.fileID, num2str(hDistToPrint) +"\r\n\r\n");
                    fclose(app.fileID);
                    figure
                    plot(hDistToPrint);
                end       
               
            end
            "Finished - Training Complete"
            
        end

        function [newExpertFE,flowRateNormalised] = loadNewPolicy(app,n_States,Gamma, goalPoint)
            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            "Reading the expert file"
            chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %Open the file to read
            flowRate = (split(chosenFile,'\'));
            flowag = flowRate(end-1);
            if(strlength(flowag)>1)
                flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
            else
                sdhgdsa = str2double(flowag);
                flowRateNormalised = str2double(flowag);
            end
            if(isnan(flowRateNormalised))
                flowRateNormalised = realmin.*6;
                "There was some error with reading the flow rate - this data is bad..."
            end
            tl = 0;
            switch(flowRateNormalised)
                case(1)
                    tl=15;
                case(2)
                    tl=15;
                case(3)
                    tl=12;
                case(4)
                    tl=12;
                case(5)
                    tl=10;
                case(6)
                    tl=10;
            end
            flowRateNormalised = flowRateNormalised./6;
            app.TimeRemainingsEditField.Value = tl;
            %flowRgfg = flowRatje(length(flowRatje));
            %flowRate = str2double(flowRate)%./6;
            fileToUse = fopen(chosenFile);
            tline = fgetl(fileToUse);
            %Similar to "play" loop above
            FE = 0;
            %FE = [];
            distanceCount = 0;
            finished = false;
            while(ischar(tline) && ~finished) %(app.timePassed <= app.timeLimit)
                                
                datas = split(tline,',');
                time = datas(1);
                %magForce = datas(2);
                goalPercentage = datas(3);
                velocities = datas(4);
                positions = datas(5);
                goalPositions = datas(6);
                
                velocities = strip(velocities,'[');
                velocities = strip(velocities,']');
                allVelocities = split(velocities,';');
                positions = strip(positions,'[');
                positions = strip(positions,']');
                goalPositions = strip(goalPositions,'[');
                goalPositions = strip(goalPositions,']');
                allGoalPositions = split(goalPositions,';');
                allPositions = split(positions,';');
                %matState = [0 0 0 0];
                sumAdded = 0;
                xSum = 0;
                ySum = 0;
                distancex = 0;
                distancey = 0;
                for(lineIndex = 1:length(allPositions))
                    yPos = split(allPositions(lineIndex)," ");
                    xPos = str2double(yPos(1));
                    yPos = str2double(yPos(2));
                    % if(yPos>0) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                    %     finished = true;
                    %     %break;
                    % end
                    if(yPos>0||yPos<-0.008) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                         finished = true;
                     end
                    xSum = xSum - xPos;
                    ySum = ySum - yPos; %TODO!!! REEMEMBER!!! This can't be negative if we're using anything above 0

                    distancex = distancex + sqrt((xPos-goalPoint(1)).^2);% + 
                    distancey = distancey + sqrt((yPos-goalPoint(2)).^2);
                    
                end
                % for(lineIndex = 1:length(allPositions))
                %     if(~contains(allGoalPositions(lineIndex),'1'))
                %      xAndYVelocities = split(allVelocities(lineIndex),' ');
                %      xAndYPositions = split(allPositions(lineIndex),' ');
                %      matState = matState + [str2double(xAndYPositions(1)), str2double(xAndYPositions(2)) ,str2double(xAndYVelocities(1)), str2double(xAndYVelocities(2))];
                %      sumAdded = sumAdded + 1;
                %     end
                % end
                % matState = matState ./ sumAdded; %Get the average...
                matState = [distancex, distancey, flowRateNormalised];

                %FE = pyrun("featureExpectations += (GAMMA**(dist-101))*np.array(readings)","featureExpectations",GAMMA=Gamma,dist = distanceCount, readings = matState); %#TODO the 'readings' are the state, aren't they?
                if(distanceCount>5)
                    FE = FE + (Gamma.^(distanceCount-5))*matState;
                    %FE(end+1,:) = (Gamma.^(distanceCount-50))*matState;
                end


                distanceCount = distanceCount + 1;
                tline = fgetl(fileToUse);
            end
            %if(size(FE,2) ~= n_States) %This is recursive - make sure to not get stuck here...
             if(length(FE) ~= n_States) %This is recursive - make sure to not get stuck here...
                FE = loadNewPolicy(app,n_States,Gamma,goalPoint);
            end
            newExpertFE = FE;
            "File Read"
        end

        function resetSimForMLTraining(app)
            delete(app.particlePoints);
            delete(app.magLine);
            fclose(app.fileID);
            app.ScenarioEditField.Value = "Test " + app.testNumber;
            app.polygon = app.polygon.change(2,app.fd);
            app.rotation = 0;
            app.NumberofParticlesEditField.Value = 500;
            app.FluidFlowmsEditField.Value = 0.005;
            app.MagForceRestrictMAM2EditField.Value = 0;
            generateNewParticles = true;
            app.hapticFeedback = [0,0,0];
            app.fd = FlowData60();
            app.polygon = app.polygon.change(4,app.fd);
            app.FluidFlowmsEditField.Value = 1;
            if(ndims(app.polygon.currentStartZone) > 2)
                app.polygon.currentStartZone = squeeze(app.polygon.currentStartZone(1,:,:));
            end
            app.goalIndex = 4;
            app.slowDown = 1;
            minTimeToTravel = 15;
            app.TimeRemainingsEditField.Value = minTimeToTravel;% .* 5;%2.5;TODO RESET ME
            app.timeLimit = app.TimeRemainingsEditField.Value;
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.previousMagforce = 0;
            app.lastUpdate = clock;
            paddedTestNumber = sprintf( '%04d', app.testNumber);
            newFileName = "Test" + paddedTestNumber + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.tMax = 1;
        
        
            app.mesh = delaunayTriangulation(app.polygon.currentFlowLocations);
            if(generateNewParticles)
                app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);
             end
            app.particleArrayVelocity = app.particleFunctions.calculateFlow(app.particleArrayLocation,app.polygon.currentFlowValues,app.mesh);% zeros(app.numParticles, 2);
            
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            
            hold (app.UIAxes, 'on');
            delete(app.polyLine);
            delete(app.endLine);
            delete(app.wrongEndLine); 
            
            rotateMatrix = [cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)];
            rotatedOutline = (rotateMatrix * app.polygon.currentPoly')';
            app.polyLine = plot(app.UIAxes, rotatedOutline(:,1), rotatedOutline(:,2), 'Color','b');   


            %set(app.UIAxes,'padded')
            app.UIAxes.XLim = [min(app.polygon.currentPoly(:,1)) + (min(app.polygon.currentPoly(:,1))/20), max(app.polygon.currentPoly(:,1)) + (max(app.polygon.currentPoly(:,1))/20)];
            app.UIAxes.YLim = [min(app.polygon.currentPoly(:,2)) + (min(app.polygon.currentPoly(:,2))/20), max(app.polygon.currentPoly(:,2)) + (max(app.polygon.currentPoly(:,2))/20)];


            for(lineCount = 1:size(app.polygon.currentEndZone,1))
                rotatedEndZone = (rotateMatrix * squeeze(app.polygon.currentEndZone(lineCount,:,:))')';
                app.wrongEndLine(lineCount) = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','r');
                if(lineCount == app.goalIndex)
                    app.endLine = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','g');
                end
            end 
            app.previousMagforce = 0;
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
           % app.fd = FlowData();
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0); 
        end

        function [state,reward,action] = stepGame(app,epsilon, flowRate, draw, goalLocation)
            
            %NUMACTIONS IS HARDCODED TO 8 CURRENTLY

            %epsilon of 0 should be 100% through NN
            if (rand() < epsilon)%(np.random.uniform(0,1) < obj1.epsilon) %epsilon
               % action = randi([1 8]);
               prediction = rand(1,2).*2 - 1;
            else
                prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=getState(app,flowRate,goalLocation)));
                %double(prediction)
                % action = pyrun("act = np.argmax(pred)","act");
            end
            action = prediction;
            app.X1MAGauge.Value = prediction(1);
            app.Y1MAGauge.Value = prediction(2);
            % switch(action) %Go clockwise through n angles - set to a value between 0 and 1, let the program change them to more sensible values.
            %     case(0)
            %         %Quit now - shouldn't get here.
            %         episodeEnd = false;
            %         %break;
            %     case(1)%Up
            %         app.X1MAGauge.Value = 1;
            %         app.Y1MAGauge.Value = 0;
            %         %break;
            %     case(2)
            %         app.X1MAGauge.Value = 0.707;
            %         app.Y1MAGauge.Value = 0.707;
            %         %break;
            %     case(3)%Right
            %         app.X1MAGauge.Value = 0;
            %         app.Y1MAGauge.Value = 1;
            %         %break;
            %     case(4)
            %         app.X1MAGauge.Value = -0.707;
            %         app.Y1MAGauge.Value = 0.707;
            %         %break;
            %     case(5)%Down
            %         app.X1MAGauge.Value = -1;
            %         app.Y1MAGauge.Value = 0;
            %         %break;
            %     case(6)
            %         app.X1MAGauge.Value = -0.707;
            %         app.Y1MAGauge.Value = -0.707;
            %         %break;
            %     case(7)%Left
            %         app.X1MAGauge.Value = 0;
            %         app.Y1MAGauge.Value = -1;
            %         %break;
            %     case(8)
            %         app.X1MAGauge.Value = 0.707;
            %         app.Y1MAGauge.Value = -0.707;
            %         %break;
            % end

            timerCallback(app); %Do the action
            if(draw)
                drawTimerCallback(app); %Draw it (or don't)
                drawnow limitrate
                %pause(0.001);
            end
            state = getState(app,flowRate,goalLocation);
            reward = app.PercentageinGoalEditField.Value ./ 100;
           % reward = sum(app.particleArrayLocation(:,2)>-0.003)/app.numParticles;
        end

        function matState = getState(app,FlowRate,goalPoint)
            UseThis = ~any(app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation),2);
           % app.particleArrayLocation(UseThis,1)
            divNum = sum(UseThis(:));
            distancex = sqrt((app.particleArrayLocation(:,1)-goalPoint(1)).^2);
            distancey = sqrt((app.particleArrayLocation(:,2)-goalPoint(2)).^2);
            matState = [sum(distancex),sum(distancey),FlowRate]; %Sum distances - maybe these should be more normalised?
            %matState = [-sum(app.particleArrayLocation(:,1))./5,-sum(app.particleArrayLocation(:,2))./5,FlowRate]; %Use the second input for flow - here it will permanently be 1.
            %matState = [sum(app.particleArrayLocation(UseThis,1))./divNum, sum(app.particleArrayLocation(UseThis,2))./divNum, sum(app.particleArrayVelocity(UseThis,1))./divNum, sum(app.particleArrayVelocity(UseThis,2))./divNum];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
               CloseHaptic();
            end
            try
                f.close(app.fileID);
            catch
                "No file to close";
            end
            close all;
            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)

        end

        % Button pushed function: StartTrainingButton
        function StartTrainingOLDButtonValueChanged(app, event)
           
        end

        % Value changed function: FluidFlowmsEditField
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData();
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Value changed function: MagForceRestrictMAM2EditField
        function MagForceRestrictMAM2EditFieldValueChanged(app, event)
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            CloseHaptic();
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
                    InitHaptic();
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
        import java.awt.*;
        import java.awt.event.*;
        %Create a Robot-object to do the key-pressing
        rob=Robot;
        %Commands for pressing keys:
        % If the text cursor isn't in the edit box allready, then it
        % needs to be placed there for ctrl+a to select the text.
        % Therefore, we make sure the cursor is in the edit box by
        % forcing a mouse button press:
        rob.mousePress(InputEvent.BUTTON1_MASK );
        rob.mouseRelease(InputEvent.BUTTON1_MASK );
        % CONTROL + A : 
        rob.keyPress(KeyEvent.VK_SPACE)
        rob.keyRelease(KeyEvent.VK_SPACE)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictMAM2EditField.Value > 0 && app.MagForceRestrictMAM2EditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictMAM2EditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1MAGauge.Value = app.mousePosition(1) .* 250;
                app.Y1MAGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Visible = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Editable = 'off';
            app.ParticleDiametermEditField.Visible = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-2.5 2.5];
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-2.5 2.5];
            app.Y1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Editable = 'off';
            app.StaticFrictionCoefficientEditField.Visible = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Visible = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Editable = 'off';
            app.MotionFrictionCoefficientEditField.Visible = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Visible = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Visible = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Visible = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Editable = 'off';
            app.ParticleMasskgEditField.Visible = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.MagForceRestrictMAM2EditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictMAM2EditFieldLabel.Text = 'MagForceRestrict (MA/M^2)';
            app.MagForceRestrictMAM2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictMAM2EditField.ValueChangedFcn = createCallbackFcn(app, @MagForceRestrictMAM2EditFieldValueChanged, true);
            app.MagForceRestrictMAM2EditField.Editable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowmsEditFieldValueChanged, true);
            app.FluidFlowmsEditField.Editable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.LeftPanel);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Workspace')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            zlabel(app.UIAxes_2, 'Z (m)')
            app.UIAxes_2.XLim = [-0.0096 0.0096];
            app.UIAxes_2.YLim = [-0.0096 0.0096];
            app.UIAxes_2.ZLim = [-0.0096 0.0096];
            app.UIAxes_2.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes_2.Tag = 'particleWorskace';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationTrainModeRL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end