classdef particleSimulationTrainModeRLBASIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartTrainingButton             matlab.ui.control.Button
        ScenarioEditField               matlab.ui.control.EditField
        ScenarioEditFieldLabel          matlab.ui.control.Label
        CentreMassViewCheckBox          matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ControllerButton                matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        KeyboardButton                  matlab.ui.control.RadioButton
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField            matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel       matlab.ui.control.Label
        MagForceRestrictMAM2EditField   matlab.ui.control.NumericEditField
        MagForceRestrictMAM2EditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PercentageinGoalEditField       matlab.ui.control.NumericEditField
        TimeremainingsLabel             matlab.ui.control.Label
        TimeRemainingsEditField         matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        simulationTimerProperty
        drawTimerProperty
        hapticFeedbackIdleProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        hapticFeedback
        testOrder
        maxForce
        QuestionaireFID
        DataStoreLocation
        dataFileNameList
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           rng(1000,"twister");
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05; %0.05 %Draw was 0.3
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 2.25;%1.6;
            app.printCounter = 0;
%              fourPolygonLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
%             fourMeshValuesLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\BenResult0_02.csv";
% %             fourPolygonLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
% %             fourMeshValuesLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\BenResult0_01.csv";
%             [locX,locY,flowX,flowY] = ReadInFlowDataFromComsolCSV(fourPolygonLocation, fourMeshValuesLocation);
%             saveME = [locX;locY]';
%             writematrix(saveME,'M.csv') 
        %   app.fd = FlowData([locX; locY]', [flowX; flowY]');
            app.fd = FlowData05();
            %general simulation timer
          %  app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
          %  app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
          %  app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            %app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "TrainingModel";
            InitHaptic();
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0);
            app.timeLimit = 1;%120;
            app.testNumber = 0;
            app.rotation = 0;
            app.hapticFeedback = [0,0,0];
            %50 tests
            app.testOrder = 1:120;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.testOrder = [app.testOrder,length(app.testOrder)+1];
            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);
            app.DataStoreLocation = "D:\EssexDocuments\EXMAR23\unrandomised\Combined";
            app.DataStoreLocation = "D:\EssexDocuments\EXMAR23\TESTDATA1";
            % app.DataStoreLocation = "";
            %app.dataFileNameList = {"D:\EssexDocuments\EXMAR23\TESTDATA\1\Test0012_11_3_results.csv"};
            app.dataFileNameList = {};

%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
    %        app.mesh =  alphaShape(app.fd.FlowLocations{1});
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            if(false) %Set to true if needed.
                app.polygon = app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
%             prompt = {'Age','Gender','Corrected Eyesight','Right/Left Handed'};
%             dlgtitle = 'Statistical questions';
%             answer = inputdlg(prompt,dlgtitle);
%             app.QuestionaireFID = fopen("Questionaire" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
%             fprintf(app.QuestionaireFID,strcat('\r\n Age,',answer{1}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Gender,',answer{2}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Corrected Eyesight,',answer{3}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Right/Left Handed,',answer{4}));
            %fclose(app.QuestionaireFID);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            value = app.MagForceRestrictMAM2EditField.Value;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                 set(app.StartTrainingButton,'Text',"Running...");
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                app.StartTrainingButton.Enable = false;
    
                startZone = 1;
                n_particles = 4;

                %IRL First Test
                %Environment Setup
                if(app.testNumber == 0)
                    resetSimForMLTraining(app,1/6,startZone,n_particles);
                end
                terminate(pyenv)
                try
                    pyenv("Version","C:\Users\beno1\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                catch
                    pyenv("Version","C:\Users\bj20907\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                    app.DataStoreLocation = "";
                end
                if count(py.sys.path,'') == 0
                    insert(py.sys.path,int32(0),'');
                end
    
    
                DRAWTRAIN = false;
                DRAWPLAY = false;
                
                n_states = 1;
                n_actions = 2; %Use regression instead here %Using classification to test this
                %h_Layers = [80,160];%[164,150];
                %h_Layers = [200,400];
                h_Layers = [4,15];
                % e_psilon = 0.05;
                % l_rate = 0.01;
                Gamma = 0.5;
                flow_rate = 0;
                totalSteps = 1000000000;
                trainingLoops = 1;
                h_layers_basic = h_Layers;

                for(i = [1:2])
                    bestPerformance = realmin;
                    buffer = 80;
                    goalLocation = [0.010644161 -0.002909076]; %Use this for distance to goal calculations
        
                    pyrun("from pythontestIRLCustomRL import *")
                   % pyrun("obj1 = QLearning(py_n_states, py_n_actions)", py_n_states = n_states, py_n_actions = n_actions)
                    pyrun("random.seed(1000)");
                    %currentExpertPolicy = [0.51,0.05,0.1,0.1];%test this
                    %[currentExpertStates, currentExpertActions,flow_rate] = loadNewPolicy(app,n_states,Gamma,goalLocation);
                    minx = -0.012;
                    maxx = 0.012;
                    miny = -0.012;
                    maxy = 0.012;
                    binCount =240;
                    % rewardSpace = loadNewRewardFromPolicy(app,minx,maxx,miny,maxy,binCount,binCount);
                    % rewardSpace = medfilt1(rewardSpace,5);
                    % mm = max(max(rewardSpace));
                    % rewardSpace = rewardSpace./max(max(rewardSpace));
                 %   mesh(rewardSpace)
                    app.FluidFlowmsEditField.Value = flow_rate*6;
                    %pyrun("irl = IRLClass(numStates,numActions,hiddenLayers,expertFE,epsilon,lr)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers, expertFE=currentExpertPolicy,epsilon = e_psilon, lr = l_rate);
                    pyrun("irl = IRLClass(numStates,numActions,hiddenLayers)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers);
                    %pyrun("rlTraining = IRLClass(numStates,numActions,hiddenLayers,epsilon,lr)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers,epsilon = e_psilon, lr = l_rate);
        
                    pyrun("irl.model.summary()")
        %            pyrun("rlTraining.model.summary()")
        
                    summed_reward_histories = [];
                    stepIndex = 0;
                    hDistToPrint = [];
                    loss_history = [];
                    acc_history = [];
                    lhDrawn = [];
                    accDrawn = [];
                    rewDrawn = [];

                    action_history = [];
                    state_history = [];
                    state_next_history = [];
                    done_history = [];
                    rewards_history = [];
                    buffer = 100;
                    batchSize = 15;

                    while(stepIndex < totalSteps)
                        resetCount = 0;
                        % currentExpertPolicy
                      %  try
                            %FirstLayerW = pyrun("FirstLayerWeights = irl.Optimise(expertPolicy)","FirstLayerWeights",expertPolicy = currentExpertPolicy);
                            %UpdatePolicyList here
                      %  catch
                      %      stepIndex = 9;
                      %      FirstLayerW = zeros(1,n_states);
                      %  end
        
                        %'Train'
                     %   "Training Commencing"
                        for(trainingIndex = 1:trainingLoops)
                            t = 0;
                            % observe = 1;
                            % %epsilon = 1-((trainingIndex/trainingLoops)*0.4); %reduce epsilon
                            % batchSize = 10;
                            % ePochs = 1;
                            % buffer = 40;
                            % %trainFrames = 10000;
                            % %matState = getState(app,flow_rate,1,goalLocation);


                            % replayThingy = [];
                            finished = false;
                            % loss_history = [];
                            % acc_history = [];
                            % episode_reward = 0;


                            % quarterFunction = [0.25,0.5,0.75,1];

                            innerLoop = 0;
                            %useExpert = (rand() < 0.5); %Seeded RL
                            % useExpert = false; %Just RL
                            matState = randi(8)./10+0.1;
                            %reducedReward = rewardSpace -0.01; % -1 for negative reward everywhere the particles shouldn't go!
                            while(innerLoop<12 && ~finished)%app.timePassed <= app.timeLimit && ~finished)%(t<trainFrames) %Do we need to train for n frames? can this be covered by timePassed?
                                stepIndex = stepIndex + 1;
                                innerLoop = innerLoop + 1;
                                %epsilon = epsilon * 0.999;

                                % matState = quarterFunction <= (mod(innerLoop,4)./4); %Then use first false
                                % matAction =  ~(matState==(quarterFunction <= (mod(innerLoop+1,4)./4))).*1;
                                % matState = matState.*1;


                                % matState =  zeros(size(quarterFunction));
                                % matState(mod(innerLoop-1,4)+1) = 1;
                                % 
                                % prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=1)","pred", state=matState));
                                % EASIERTOREAD = prediction./sum(prediction);
                                % probcs = cumsum(prediction./sum(prediction));
                                % randindex = rand();
                                % indicies = probcs<randindex; %Then use first false
                                % matAction = zeros(size(prediction));
                                % for(action=1:length(probcs))%Streamline this
                                %     %action=i;
                                %     if(~indicies(action))
                                %         matAction(action) = 1;
                                %         break;
                                %     end
                                % end
                                % 
                                % reward = sum((quarterFunction<=(action/4)) == matState);

                                if(true)
                                    [next_matState,reward,action] = stepGame(app,matState,n_actions,[],[],1,innerLoop,DRAWTRAIN,1,false);
                                    finished = reward==-1;

                                    action_history(end+1) = action;
                                    state_history(end+1) = matState;
                                    state_next_history(end+1) = next_matState;
                                    done_history(end+1) = finished;
                                    rewards_history(end+1) = reward;
                                    
                                    if(length(action_history)>buffer) %size(action_history,1)
                                        action_history = action_history(2:end);
                                        state_history = state_history(2:end);
                                        state_next_history = state_next_history(2:end);
                                        done_history = done_history(2:end);
                                        rewards_history = rewards_history(2:end);
                                    end

                                    if(length(action_history)>batchSize)
                                        %randomIndicies = randi(size(action_history,1),1,batchSize);
                                        randomIndicies = randi(length(action_history),1,batchSize);
    
                                        state_sample = state_history(randomIndicies); %,: in brackets for multiDim
                                        state_next_sample = state_next_history(randomIndicies);
                                        rewards_sample = rewards_history(randomIndicies);
                                        done_sample = done_history(randomIndicies);
                                        action_sample = action_history(randomIndicies);
    
                                        nextStatePred = double(pyrun("pred = irl.model.predict(np.array(state_NS),verbose=0)","pred",state_NS = squeeze(state_next_sample)));
                                        target_f = double(pyrun("targetPred = irl.model.predict(np.array(state_S),verbose=0)","targetPred",state_S = squeeze(state_sample)));
                                        %aa = max(nextStatePred,[],2)
                                        target = (rewards_sample + Gamma .* max(nextStatePred,[],2)');
                                        target(done_sample == 1) = rewards_sample(done_sample == 1); % for the end cases, don't look to the next state as there is none... kinda
                                        for(i = 1:length(target))
                                            target_f(i, squeeze(action_sample(i))) = target(i);
                                        end
    
                                        hist = pyrun("uu = irl.model.fit(np.array(state),np.array(targetF),batch_size=int(bS),epochs=1,verbose=0)","uu",state = state_sample', targetF = target_f, bS = batchSize);
    
                                        loss_history(end+1) = double(hist.history{'loss'});
                                        acc_history(end+1) = double(hist.history{'accuracy'});
                                    end

                                    % nextStatePred = double(pyrun("pred = irl.model.predict(np.array([state_NS]),verbose=0)","pred",state_NS = squeeze(next_matState)));
                                    % target_f = double(pyrun("targetPred = irl.model.predict(np.array([state_S]),verbose=0)","targetPred",state_S = squeeze(matState)));
                                    % target = (squeeze(reward) + Gamma .* max(nextStatePred)); %Don't like this line here...
                                    % target_f(squeeze(action)) = target;
                                    % % if(sum(abs(target_f))>1)
                                    % %     target_f = target_f./sum(abs(target_f));
                                    % % end
                                    % %tt = cumsum(target_f);
                                    % hist = pyrun("uu = irl.model.fit(np.array([state]),np.array([targetF]),epochs=1,verbose=0)","uu",state = matState.*1, targetF = target_f);
                                else
                                    %matState = randi(8)./10+0.1;
                                    next_matState = matState;
                                    expertAction = [0,0];
                                     if(matState < 0.5)
                                        action = 1;
                                        expertAction(1) = 1;
                                    else
                                        action = 2;
                                        expertAction(2) = 1;
                                    end
                                    % [next_matState,reward,action] = stepGame(app,matState,n_actions,[],expertAction,1,innerLoop,DRAWTRAIN,1);
                                    hist = pyrun("uu = irl.model.fit(np.array([state]),np.array([targetF]),epochs=1,verbose=0)","uu",state = matState.*1, targetF = expertAction);
                                end
                                %next_matState(mod(innerLoop-1,4)+1) = 1;

                                %hist = pyrun("uu = irl.model.fit(np.array([state]),np.array([targetF]),epochs=1,verbose=1)","uu",state = matAction, targetF = matAction);%, bS = 1);

                                % prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=matState));
                                % probsTOVIEW = prediction./sum(prediction);
                                % probcs = cumsum(prediction./sum(prediction));
                                % randindex = rand();
                                % indicies = probcs<randindex; %Then use first false
                                % matAction = zeros(size(matState));
                                % chosenAction=1;
                                % for(i=1:length(probcs))%Streamline this
                                %     matAction(i) = 1;
                                %     chosenAction = i;
                                %     if(~indicies(i))
                                %         break;
                                %     end
                                % end
                                % % for(matAction=1:length(quarterFunction))%Streamline this
                                % %     %action=i;
                                % %     if(~quarterFunction(matAction))
                                % %         break;
                                % %     end
                                % % end
                                % next_matState = matAction;
                                % asdg = (mod(innerLoop+1,4)./4);
                                % nnk = next_matState == (quarterFunction < (mod(innerLoop+1,4)./4));
                                % %reward = sum(next_matState == (quarterFunction < (mod(innerLoop+1,4)./4)));
                                % reward = sum(next_matState == (quarterFunction < (mod(innerLoop+1,4)./4)));

                                % nextStatePred = double(pyrun("pred = irl.model.predict(np.array([state_NS]),verbose=0)","pred",state_NS = squeeze(next_matState))).*0.999;
                                % target_f = double(pyrun("targetPred = irl.model.predict(np.array([state_S]),verbose=0)","targetPred",state_S = squeeze(matState))).*0.999;
                                % target = (squeeze(reward) + Gamma .* max(nextStatePred)); %Don't like this line here...
                                % for(i = 1:length(target))
                                %     target_f(i, squeeze(matAction(i))) = target(i);
                                % end
                                %hist = pyrun("uu = irl.model.fit(np.array([state]),np.array([targetF]),epochs=1,verbose=1)","uu",state = matState.*1, targetF = target_f);%, bS = 1);

                                % loss_history(end+1) = double(hist.history{'loss'});
                                % acc_history(end+1) = double(hist.history{'accuracy'});
                                % delete(lhDrawn);
                                % delete(accDrawn);
                                % delete(rewDrawn);
                                % lhDrawn = plot(loss_history);
                                % hold on
                                % accDrawn = plot(acc_history);
                                % hold on
                                % legend(["loss","accuracy"])
                                % pause(0.1);


                                % if (useExpert)
                                %     [next_matState,matReward,matAction] = stepGame(app,n_actions ,squeeze(currentExpertStates(innerLoop,:,:)),squeeze(currentExpertActions(innerLoop,:)),flow_rate,innerLoop,DRAWTRAIN,goalLocation);
                                % else
                                %     [next_matState,matReward,matAction] = stepGame(app,n_actions ,[],[],flow_rate,innerLoop,DRAWTRAIN,goalLocation);
                                % end
                                
                                % locationReward = 0;
                                % %for(i = 1:app.numParticles)
                                %     binx = floor(((app.particleArrayLocation(1,1) - minx) / (maxx - minx))*binCount); %1 is i
                                %     biny = floor(((app.particleArrayLocation(1,2) - miny) / (maxy - miny))*binCount); %1 is i
                                %     if(binx>binCount)
                                %         binx = binCount;
                                %     end
                                %     if(biny>binCount)
                                %         biny = binCount;
                                %     end
                                %     try
                                %         locationReward = locationReward + rewardSpace(binx,biny);
                                %     catch
                                %         ParticleInWrongPlace = [binx,biny]  
                                %     end
                                %    % locationReward = locationReward + reducedReward(binx,biny);
                                %    % reducedReward(binx,biny) = reducedReward(binx,biny)-1; %reduce reward as particles stay there - persuade particles to move!
                                % %end
                                % 
                                % matReward = matReward + locationReward/100;
                                % if(matReward == 0)
                                %     matReward = -0.001; %Give a small negative reward here - nothing to reward, so limit these actions!
                                % end

                                % action_history(end+1,:) = matAction;
                                % state_history(end+1,:) = matState;
                                % state_next_history(end+1,:) = next_matState;
                                % %done_history(end+1) = 0;
                                % rewards_history(end+1,:) = matReward;
                                % episode_reward = episode_reward + matReward;
                                % 
                                % % Do traceback for ONLY the new reward,
                                % % it'll work out
                                % if(size(rewards_history,1) > 2 && true)
                                %     reward_decay = matReward;
                                %     if(matReward > 0) %Only propagate positive reward - not nulls/0s
                                %         for(rewardsIndex = (size(rewards_history,1)-1):-1:1)
                                %             reward_decay = reward_decay.*0.999; %Decay factor, change this to be more aggressive (greater) or retain the info for longer(smaller).
                                %             rewards_history(rewardsIndex) = rewards_history(rewardsIndex) + reward_decay;
                                %         end
                                %     end
                                % end
                                % 
                                % if(size(action_history,1)>batchSize)
                                %     if(size(action_history,1)>buffer)
                                %         action_history = action_history(2:end,:);
                                %         state_history = state_history(2:end,:);
                                %         state_next_history = state_next_history(2:end,:);
                                %         rewards_history = rewards_history(2:end,:);
                                % 
                                %     end
                                % 
                                %     randomIndicies = randi(size(action_history,1),1,batchSize);
                                % 
                                %     state_reducer = 0.999;%9999999;
                                %     %state_reducer = 1;
                                % 
                                %     state_sample = state_history(randomIndicies,:);
                                %     state_next_sample = state_next_history(randomIndicies,:);
                                %     rewards_sample = rewards_history(randomIndicies,:);
                                %     action_sample = action_history(randomIndicies,:);

                                    % nextStatePred = double(pyrun("pred = irl.model.predict(np.array(state_NS),verbose=0)","pred",state_NS = squeeze(state_next_sample))) .* state_reducer;
                                    % target_f = double(pyrun("targetPred = irl.model.predict(np.array(state_S),verbose=0)","targetPred",state_S = squeeze(state_sample))) .* state_reducer;
                                    % target = (squeeze(rewards_sample .* state_reducer) + Gamma .* max(nextStatePred,[],2));
                                    % for(i = 1:length(target))
                                    %     target_f(i, squeeze(action_sample(i))) = target(i);
                                    % end
                                    % 
                                    % hist = pyrun("uu = irl.model.fit(np.array(state),np.array(targetF),batch_size=int(bS),epochs=1,verbose=0)","uu",state = state_sample, targetF = target_f, bS = batchSize);
                                    % 
                                    % loss_history(end+1) = double(hist.history{'loss'});
                                    % acc_history(end+1) = double(hist.history{'accuracy'});
                                % end
                                % partsInEndZone = app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation(1,:));%This should be 1 or 0
                                % if(any(partsInEndZone))
                                %     finished = true;
                                % end
                                % if(app.particleArrayLocation(1,1)<-0.008)
                                %     finished = true;
                                % end
                                % 
                                % if(finished && false)
                                %     %Run a few loops more training, to
                                %     %ensure we train on the last frame(s)
                                % 
                                %     future_rewards = double(pyrun("fR = irl.model.predict(np.array(state_NS))","fR",state_NS = squeeze(state_next_history))) .* state_reducer;
                                %     updated_q_values = squeeze(rewards_history .* state_reducer) + Gamma .* max(future_rewards,[],2);
                                %     try
                                %         hist = pyrun("uu = irl.model.fit(np.array(state),np.array(targetF),batch_size=int(bS),epochs=1,verbose=0)","uu",state = state_history, targetF = updated_q_values,bS = batchSize); 
                                %         loss_history(end+1) = double(hist.history{'loss'});
                                %         acc_history(end+1) = double(hist.history{'accuracy'});
                                %     catch
                                %         "Something went wrong"
                                %     end
                                % end
                  
                                t = t + 1;
                                matState = next_matState;
                            end                   
            
                            resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                        end
                        % epsilon
                        % figure
                        % plot(rewards_history);
                        % % legend(["reward"])
                        % %figure
                        delete(lhDrawn);
                        delete(accDrawn);
                        delete(rewDrawn);
                        lhDrawn = plot(loss_history);
                        hold on
                        accDrawn = plot(acc_history);
                        hold on
                        legend(["loss","accuracy"])
                        title("Loss vs Accuracy during training")
                        xlabel("Train Iterations");
                        ylabel("");
                        pause(0.1);
                        %Training_Progress = floor(trainingIndex/trainingLoops .*100)+"%"         

                        %print the weights for me!
                       % pyrun("for layer in irl.model.layers: print(layer.get_config(), layer.get_weights())");
        
                        answer = [];
                        %'Play'
                       % "Playing Commencing"
                        % distanceCount = 0;
                        rewardSum = 0;
                        % matState = getState(app,flow_rate,t,goalLocation);
                        % %pyrun("featureExpectations = np.zeros(int(num_States))",num_States = n_states);
                        % reducedReward = rewardSpace - 0.01;
                        finished = false;
                        tStep = 0;
                        matState = randi(5)./10 + 0.2;
                        while(tStep<20 && ~finished)%(false || app.timePassed <= app.timeLimit && ~finished)
                            tStep = tStep+1;
                            %Pick action from network...
                            [next_matState,reward,action] = stepGame(app,matState,n_actions,[],[],1,tStep,false,1,true);
                            finished = (reward == -1);
                            rewardSum = rewardSum + reward;
                            matState = next_matState;
                            % states = quarterFunction <= mod(tStep,4)/4;
                            % 
                            % matAction =  zeros(size(states));
                            % matAction(mod(tStep-1,4)+1) = 1;
                            % prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=1)","pred", state=matAction));
                            % EASIERTOREAD = prediction./sum(prediction);
                            % probcs = cumsum(prediction./sum(prediction));
                            % randindex = rand();
                            % indicies = probcs<randindex; %Then use first false
                            % for(action=1:length(probcs))%Streamline this
                            %     %action=i;
                            %     if(~indicies(action))
                            %         break;
                            %     end
                            % end
                            % answer(end+1,:) = [mod(tStep-1,4)+1,action]
                            % rewardSum = rewardSum + sum((quarterFunction<=(action/4)) == matAction);
                        %     [matState,matReward,matAction] = stepGame(app,n_actions,[],[],flow_rate,tStep,DRAWPLAY,goalLocation);
                        %     locationReward = 0;
                        %     %for(i = 1:app.numParticles)
                        %         binx = floor(((app.particleArrayLocation(1,1) - minx) / (maxx - minx))*binCount); %1 is i
                        %         biny = floor(((app.particleArrayLocation(1,2) - miny) / (maxy - miny))*binCount); %1 is i
                        %         if(binx>binCount)
                        %             binx = binCount;
                        %         end
                        %         if(biny>binCount)
                        %             biny = binCount;
                        %         end
                        %         try
                        % 
                        %             locationReward = locationReward + rewardSpace(binx,biny);
                        % 
                        %             % locationReward = locationReward + reducedReward(binx,biny);
                        %             % reducedReward(binx,biny) = reducedReward(binx,biny)-1; %reduce reward as particles stay there - persuade particles to move!
                        %         catch
                        %             ParticleInWrongPlace = [binx,biny]
                        %         end
                        %     %end
                        %     %Do traceback for ONLY the new reward,
                        %     %it'll work out
                        %     if(size(rewards_history,1) > 2 && true)
                        %         reward_decay = matReward;
                        %         for(rewardsIndex = (size(rewards_history,1)-1):-1:1)
                        %             reward_decay = reward_decay.*0.9; %Decay factor, change this to be more aggressive (greater) or retain the info for longer(smaller).
                        %             rewards_history(rewardsIndex) = rewards_history(rewardsIndex) + reward_decay;
                        %         end
                        %     end
                        % 
                        %     matReward = matReward + locationReward/100;
                        %     rewardSum = rewardSum + matReward;
                        %     % if(any(app.particleArrayLocation(:,2)>0)||any(app.particleArrayLocation(:,2)<-0.008)) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                        %     %     finished = true;
                        %     % end
                        %     % if(distanceCount >5)
                        %     %     %FE = pyrun("featureExpectations += (GAMMA**(dist-5))*np.array(readings)","featureExpectations",GAMMA=Gamma,dist = distanceCount, readings = matState); %#TODO the 'readings' are the state, aren't they?
                        %     %     %FE(end+1,:) = (Gamma^(distanceCount-50))*matState;
                        %     % end
                        %     % if(sum(app.haltParticlesInEndZone)/app.numParticles > 0.95)
                        %     %     finished = true;
                        %     % end
                        %     partsInEndZone = app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation(1,:));%This should be 1 or 0
                        %     if(any(partsInEndZone))
                        %         finished = true;
                        %     end
                        %     if(app.particleArrayLocation(1,1)<-0.008)
                        %         finished = true;
                        %     end
                        %     distanceCount = distanceCount + 1;
                        % end
                        % 
                        % 
                        % if(rewardSum > bestPerformance)% || resetCount > length(summed_reward_histories)^0.7)
                        %     bestPerformance = rewardSum;
                        %     %resetCount = 0;
                        %     %pyrun("irl.model = rlTraining.model");                            
                        %     %
                        %     sss = pyrun("res = irl.model.save('E:\SwarmSimulation\BestModel')","res");
                        % %else
                        %     %resetCount = resetCount + 1;
                        %     %pyrun("rlTraining.model = irl.model");
                        % end
                        end
                        summed_reward_histories(end+1) = 0 + rewardSum;
                        
                        delete(lhDrawn);
                        delete(accDrawn);
                        delete(rewDrawn);
                        rewDrawn = plot(summed_reward_histories);
                        hold on
                        title("Sum of reward for each episode played during training")
                        legend("Sum Of Reward");
                        xlabel("Play Iterations");
                        ylabel("Reward");
                        pause(0.1);
                        %hyprDist = pyrun("newHyperDistance = irl.UpdatePolicyList(FirstLayerWeights,PlayFE,stepI)","newHyperDistance", FirstLayerWeights = FirstLayerW, PlayFE = FE, stepI = stepIndex)
                        %hDistToPrint(end+1) = hyprDist;
                        %stepIndex = stepIndex+1;
                        % if(hyprDist <= e_psilon) %#hyperdistance is the 'error' if you will, so above we are positioning the new data in order of error? I think?
                        %    break
                        % else
                        %     %Reset NN (this should allow for better randomness?
                        %     pyrun("irl.model = irl.ConstructNetwork(irl.n_states, irl.n_actions, hiddenLayers)",hiddenLayers=h_Layers);
                        % end
                        if(app.PercentageinGoalEditField.Value > 60 && startZone < 5)                            
                            startZone = startZone+1;
                        else
                            if(app.PercentageinGoalEditField.Value < 2 && startZone > 1)
                                startZone = startZone - 1;
                            end
                        end
                        resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                        %Get a new policy to train
                        % currentExpertPolicy = loadNewPolicy(app,n_states,Gamma,goalLocation);
                    end
                    %resetSimForMLTraining(app);
                    app.fileID = fopen("MachineLearningTraining04.txt",'a');%Just appending, don't need to read anything.
                    filedescriptionsdg = "19-05-23 01, n_states = " + num2str(n_states) + ", n_actions = " + num2str(n_actions) + ", h_Layers = " + num2str(h_Layers) + ", e_psilon = " + num2str(e_psilon) + " l_rate = " + num2str(l_rate) + " Gamma = " + num2str(Gamma) + " flow_rate = " + num2str(flow_rate) + " totalSteps = " + num2str(totalSteps) + " trainingLoops = " + num2str(trainingLoops);
        
                    fprintf(app.fileID, filedescriptionsdg +"\r\n");
                    fprintf(app.fileID, num2str(hDistToPrint) +"\r\n\r\n");
                    fclose(app.fileID);
                    % figure
                    % plot(hDistToPrint);
                end       
               
            end
            "Finished - Training Complete"
            
        end

        function [rewardXY] = loadNewRewardFromPolicy(app,minx,maxx,miny,maxy,binsx,binsy)
            %Make the state space
            rewardXY = zeros(binsx,binsy);

            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            expertStates = [];
            expertActions = [];

            "Reading the expert file"
            %chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %for(i = 1:length(app.dataFileNameList))
            for(i = 1:5)
                chosenFile = string(app.dataFileNameList(i));
                tl = 0;
                app.TimeRemainingsEditField.Value = tl;
                %flowRgfg = flowRatje(length(flowRatje));
                %flowRate = str2double(flowRate)%./6;
                fileToUse = fopen(chosenFile);
                tline = fgetl(fileToUse);
                %Similar to "play" loop above
                FE = 0;
                %FE = [];
                distanceCount = 0;
                finished = false;
                newExpertFE = [];
                while(ischar(tline) && ~finished)
                    datas = split(tline,',');
                    time = datas(1);
                    magForce = datas(2);
                    goalPercentage = datas(3);
                    velocities = datas(4);
                    positions = datas(5);
                    goalPositions = datas(6);
                    
                    allMag = strip(magForce,'[');
                    allMag = strip(allMag,']');
                    allMag = split(allMag,' ');
                    magx = str2double(allMag(1));
                    magy = str2double(allMag(2));
    
                    positions = strip(positions,'[');
                    positions = strip(positions,']');
                    allPositions = split(positions,';');
                    positionArray = [];
                    for(lineIndex = 1:length(allPositions))
                        yPos = split(allPositions(lineIndex)," ");
                        xPos = str2double(yPos(1));
                        yPos = str2double(yPos(2));
                        
                        binx = floor(((xPos - minx) / (maxx - minx))*binsx);
                        biny = floor(((yPos - miny) / (maxy - miny))*binsy);
                        if(binx<=binsx&&biny<=binsy&&binx>0&&biny>0)                            
                            rewardXY(binx,biny) = rewardXY(binx,biny) + 1;
                        end
                    end
    
                    distanceCount = distanceCount + 1;
                    tline = fgetl(fileToUse);
                end
                "File Read"
            end
        end

        function [expertStates,expertActions,flowRateNormalised] = loadNewPolicy(app,n_States,Gamma, goalPoint)
            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            expertStates = [];
            expertActions = [];

            "Reading the expert file"
            chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %Open the file to read
            flowRate = (split(chosenFile,'\'));
            flowag = flowRate(end-1);
            if(strlength(flowag)>1)
                flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
            else
                flowRateNormalised = str2double(flowag);
            end
            if(isnan(flowRateNormalised))
                flowRateNormalised = realmin.*6;
                "There was some error with reading the flow rate - this data is bad..."
            end
            tl = 0;
            switch(flowRateNormalised)
                case(1)
                    tl=15;
                case(2)
                    tl=15;
                case(3)
                    tl=12;
                case(4)
                    tl=12;
                case(5)
                    tl=10;
                case(6)
                    tl=10;
            end
            flowRateNormalised = flowRateNormalised./6;
            app.TimeRemainingsEditField.Value = tl;
            %flowRgfg = flowRatje(length(flowRatje));
            %flowRate = str2double(flowRate)%./6;
            fileToUse = fopen(chosenFile);
            tline = fgetl(fileToUse);
            %Similar to "play" loop above
            FE = 0;
            %FE = [];
            distanceCount = 0;
            finished = false;
            newExpertFE = [];
            while(ischar(tline) && ~finished) %(app.timePassed <= app.timeLimit)
                                
                datas = split(tline,',');
                time = datas(1);
                magForce = datas(2);
                goalPercentage = datas(3);
                velocities = datas(4);
                positions = datas(5);
                goalPositions = datas(6);
                
                allMag = strip(magForce,'[');
                allMag = strip(allMag,']');
                allMag = split(allMag,' ');
                magx = str2double(allMag(1));
                magy = str2double(allMag(2));

                % velocities = strip(velocities,'[');
                % velocities = strip(velocities,']');
                % allVelocities = split(velocities,';');
                 positions = strip(positions,'[');
                 positions = strip(positions,']');
                % goalPositions = strip(goalPositions,'[');
                % goalPositions = strip(goalPositions,']');
                % allGoalPositions = split(goalPositions,';');
                 allPositions = split(positions,';');
                % %matState = [0 0 0 0];
                % sumAdded = 0;
                % xSum = 0;
                % ySum = 0;
                % distancex = 0;
                % distancey = 0;
                positionArray = [];
                 for(lineIndex = 1:length(allPositions))
                     yPos = split(allPositions(lineIndex)," ");
                     xPos = str2double(yPos(1));
                     yPos = str2double(yPos(2));
                     positionArray(end+1,:) = [xPos,yPos];
                %     % if(yPos>0) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                %     %     finished = true;
                %     %     %break;
                %     % end
                    if(yPos>0||yPos<-0.008) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                         finished = true;
                     end
                %     % if(all(app.haltParticlesInEndZone))
                %     %     finished = true;
                %     % end
                %     xSum = xSum - xPos;
                %     ySum = ySum - yPos; %TODO!!! REEMEMBER!!! This can't be negative if we're using anything above 0
                % 
                %     distancex = distancex + sqrt((xPos-goalPoint(1)).^2);% + 
                %     distancey = distancey + sqrt((yPos-goalPoint(2)).^2);
                % 
                 end
                % for(lineIndex = 1:length(allPositions))
                %     if(~contains(allGoalPositions(lineIndex),'1'))
                %      xAndYVelocities = split(allVelocities(lineIndex),' ');
                %      xAndYPositions = split(allPositions(lineIndex),' ');
                %      matState = matState + [str2double(xAndYPositions(1)), str2double(xAndYPositions(2)) ,str2double(xAndYVelocities(1)), str2double(xAndYVelocities(2))];
                %      sumAdded = sumAdded + 1;
                %     end
                % end
                % matState = matState ./ sumAdded; %Get the average...
                %matState = [distancex, distancey, flowRateNormalised];
                expertActions(end+1,:) = [magx,magy];
                expertStates(end+1,:,:) = positionArray;

                distanceCount = distanceCount + 1;
                tline = fgetl(fileToUse);
            end
             % if(length(FE) ~= n_States) %This is recursive - make sure to not get stuck here...
             %    newExpertFE = loadNewPolicy(app,n_States,Gamma,goalPoint);
             % end
            "File Read"
        end

        function resetSimForMLTraining(app,flowRate,startZone,nParticles)
            delete(app.particlePoints);
            delete(app.magLine);
            fclose(app.fileID);
            app.ScenarioEditField.Value = "Test " + app.testNumber;
            app.polygon = app.polygon.change(2,app.fd);
            app.rotation = 0;
            app.NumberofParticlesEditField.Value = nParticles;
            app.MagForceRestrictMAM2EditField.Value = 0;
            generateNewParticles = true;
            app.hapticFeedback = [0,0,0];
            app.fd = FlowData60();
            app.polygon = app.polygon.change(4,app.fd);
            app.FluidFlowmsEditField.Value = flowRate.*6;
            if(ndims(app.polygon.currentStartZone) > 2)
                app.polygon.currentStartZone = squeeze(app.polygon.currentStartZone(1,:,:));
            end
            app.goalIndex = 4;
            app.slowDown = 1;
            minTimeToTravel = 15;
            app.TimeRemainingsEditField.Value = minTimeToTravel;% .* 5;%2.5;TODO RESET ME
            app.timeLimit = app.TimeRemainingsEditField.Value;
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.previousMagforce = 0;
            app.lastUpdate = clock;
            paddedTestNumber = sprintf( '%04d', app.testNumber);
            newFileName = "Test" + paddedTestNumber + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.tMax = 1;
        
        
            app.mesh = delaunayTriangulation(app.polygon.currentFlowLocations);
            if(generateNewParticles)
                app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);
             end
            app.particleArrayVelocity = app.particleFunctions.calculateFlow(app.particleArrayLocation,app.polygon.currentFlowValues,app.mesh);% zeros(app.numParticles, 2);
            
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            
            hold (app.UIAxes, 'on');
            delete(app.polyLine);
            delete(app.endLine);
            delete(app.wrongEndLine); 
            
            rotateMatrix = [cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)];
            rotatedOutline = (rotateMatrix * app.polygon.currentPoly')';
            app.polyLine = plot(app.UIAxes, rotatedOutline(:,1), rotatedOutline(:,2), 'Color','b');   


            %set(app.UIAxes,'padded')
            app.UIAxes.XLim = [min(app.polygon.currentPoly(:,1)) + (min(app.polygon.currentPoly(:,1))/20), max(app.polygon.currentPoly(:,1)) + (max(app.polygon.currentPoly(:,1))/20)];
            app.UIAxes.YLim = [min(app.polygon.currentPoly(:,2)) + (min(app.polygon.currentPoly(:,2))/20), max(app.polygon.currentPoly(:,2)) + (max(app.polygon.currentPoly(:,2))/20)];


            for(lineCount = 1:size(app.polygon.currentEndZone,1))
                rotatedEndZone = (rotateMatrix * squeeze(app.polygon.currentEndZone(lineCount,:,:))')';
                app.wrongEndLine(lineCount) = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','r');
                if(lineCount == app.goalIndex)
                    app.endLine = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','g');
                end
            end 
            app.previousMagforce = 0;
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
           % app.fd = FlowData();
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0); 
            newStartZone = app.polygon.currentStartZone;
            switch(startZone)
                case(1)%closest
                    newStartZone = [0.0085 0.00124; 0.0076 0.00112; 0.008 0.001; 0.0088 0.001];
                case(2)
                    newStartZone = [0.0035 0.0038; 0.0029 0.0030; 0.0033 0.0030; 0.0038 0.0035];
                case(3)
                    newStartZone = [-0.0018 0.0021; -0.0017 0.0012; -0.0015 0.0015; -0.0015 0.0025];
                case(4)
                    newStartZone = [-0.0045 -0.003; -0.0036 -0.0035; -0.0031 -0.0033; -0.0044 -0.0032];
                case(5)
                    newStartZone = app.polygon.currentStartZone;

            end
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(newStartZone, app.numParticles);
        end

        function [state,reward,action] = stepGame(app,oldState, n_actions, expertStates, expertActions, flowRate, t, draw, goalLocation,playing)
            if(length(expertActions) > 1)  
                if(expertActions(1))
                    action = 1;
                else
                    action = 2;
                end
            else
                prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=oldState));

                if(playing)
                    action = (prediction(2) > prediction(1)) + 1;
                else
                    probcs = cumsum(prediction./sum(prediction));
                    randindex = rand();
                    indicies = probcs<=randindex; %Then use first false
                    stateAction = zeros(n_actions);%TODO what size is this?
                    for(action=1:length(probcs))%Streamline this
                        if(~indicies(action))
                            break;
                        end
                    end
                end
            end
            state = oldState;
            if(oldState>0.9 || oldState < 0.1)
                reward = -1;
            else
                switch(action)
                    case(1)
                        state = state + 0.1;
                    case(2)
                        state = state - 0.1;
                end
                reward = 2*(0.5-abs(state-0.5));
                %reward = sqrt(0.5-abs(state-0.5));
                %reward = state==0.5;
            end
        end
        %     if(length(expertActions) > 1)  
        %         action = expertActions;
        %     else
        %         prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=getState(app,flowRate,t,goalLocation)));
        %         probcs = cumsum(prediction./sum(prediction));
        %         randindex = rand();
        %         indicies = probcs<randindex; %Then use first false
        %         for(action=1:length(probcs))%Streamline this
        %             %action=i;
        %             if(~indicies(action))
        %                 break;
        %             end
        %         end
        %     end
        % 
        %     % %action 1 = 12 & a little bit o clock - "0"x, +y
        %     % %action n/2 = 6 o clock - 0x, -y
        %     % %action n = 12 o clock - 0x, +y
        %     % quarter = n_actions/4;
        %     % half = n_actions/2;
        %     % yval = (quarter - action)./quarter;
        %     % if(yval < -1)
        %     %     yval  = (action - quarter*3)./quarter;
        %     % end
        %     % xval = (half - action)./half;
        %     % if(xval>1)
        %     %     xval = 2-xval;
        %     % else
        %     %     if(xval<-1)
        %     %         xval = -2 -xval;
        %     %     end
        %     % end
        %     switch(action)
        %         case(1)
        %             app.X1MAGauge.Value = 0;
        %             app.Y1MAGauge.Value = 1;
        %         case(2)
        %             app.X1MAGauge.Value = 1;
        %             app.Y1MAGauge.Value = 0;
        %         case(3)
        %             app.X1MAGauge.Value = 0;
        %             app.Y1MAGauge.Value = -1;
        %         case(4)
        %             app.X1MAGauge.Value = -1;
        %             app.Y1MAGauge.Value = 0;
        %     end
        % 
        %     %app.X1MAGauge.Value = xval;
        %     %app.Y1MAGauge.Value = yval;
        % 
        %     timerCallback(app); %Do the action
        % 
        %     if(length(expertStates) > 1)
        %         %Force the state to be like the data
        %         app.particleArrayLocation = expertStates;
        %     end
        %     if(draw)
        %         drawTimerCallback(app); %Draw it (or don't)
        %         drawnow limitrate
        %         %pause(0.001);
        %     end
        %     state = getState(app,flowRate,t,goalLocation);
        %     reward = app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation(1,:));%This should be 1 or 0
        %     reward = reward(app.goalIndex) .* (350-t);
        % end

        function matState = getState(app,FlowRate,t,goalPoint)
            distancex = sqrt((app.particleArrayLocation(:,1)-goalPoint(1)).^2);
            distancey = sqrt((app.particleArrayLocation(:,2)-goalPoint(2)).^2);
            
            matState = [distancex(1), distancey(1), app.particleArrayLocation(1,1).*100,app.particleArrayLocation(1,2).*100,t./100];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
               CloseHaptic();
            end
            try
                f.close(app.fileID);
            catch
                "No file to close";
            end
            close all;
            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)

        end

        % Button pushed function: StartTrainingButton
        function StartTrainingOLDButtonValueChanged(app, event)
           
        end

        % Value changed function: FluidFlowmsEditField
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData();
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Value changed function: MagForceRestrictMAM2EditField
        function MagForceRestrictMAM2EditFieldValueChanged(app, event)
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            CloseHaptic();
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
                    InitHaptic();
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
        import java.awt.*;
        import java.awt.event.*;
        %Create a Robot-object to do the key-pressing
        rob=Robot;
        %Commands for pressing keys:
        % If the text cursor isn't in the edit box allready, then it
        % needs to be placed there for ctrl+a to select the text.
        % Therefore, we make sure the cursor is in the edit box by
        % forcing a mouse button press:
        rob.mousePress(InputEvent.BUTTON1_MASK );
        rob.mouseRelease(InputEvent.BUTTON1_MASK );
        % CONTROL + A : 
        rob.keyPress(KeyEvent.VK_SPACE)
        rob.keyRelease(KeyEvent.VK_SPACE)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictMAM2EditField.Value > 0 && app.MagForceRestrictMAM2EditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictMAM2EditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1MAGauge.Value = app.mousePosition(1) .* 250;
                app.Y1MAGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Visible = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Editable = 'off';
            app.ParticleDiametermEditField.Visible = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-2.5 2.5];
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-2.5 2.5];
            app.Y1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Editable = 'off';
            app.StaticFrictionCoefficientEditField.Visible = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Visible = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Editable = 'off';
            app.MotionFrictionCoefficientEditField.Visible = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Visible = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Visible = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Visible = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Editable = 'off';
            app.ParticleMasskgEditField.Visible = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.MagForceRestrictMAM2EditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictMAM2EditFieldLabel.Text = 'MagForceRestrict (MA/M^2)';
            app.MagForceRestrictMAM2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictMAM2EditField.ValueChangedFcn = createCallbackFcn(app, @MagForceRestrictMAM2EditFieldValueChanged, true);
            app.MagForceRestrictMAM2EditField.Editable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowmsEditFieldValueChanged, true);
            app.FluidFlowmsEditField.Editable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.LeftPanel);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Workspace')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            zlabel(app.UIAxes_2, 'Z (m)')
            app.UIAxes_2.XLim = [-0.0096 0.0096];
            app.UIAxes_2.YLim = [-0.0096 0.0096];
            app.UIAxes_2.ZLim = [-0.0096 0.0096];
            app.UIAxes_2.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes_2.Tag = 'particleWorskace';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationTrainModeRLBASIC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end