classdef particleSimulationTrainModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartTrainingButton             matlab.ui.control.Button
        ScenarioEditField               matlab.ui.control.EditField
        ScenarioEditFieldLabel          matlab.ui.control.Label
        CentreMassViewCheckBox          matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ControllerButton                matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        KeyboardButton                  matlab.ui.control.RadioButton
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField            matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel       matlab.ui.control.Label
        MagForceRestrictMAM2EditField   matlab.ui.control.NumericEditField
        MagForceRestrictMAM2EditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PercentageinGoalEditField       matlab.ui.control.NumericEditField
        TimeremainingsLabel             matlab.ui.control.Label
        TimeRemainingsEditField         matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        simulationTimerProperty
        drawTimerProperty
        hapticFeedbackIdleProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        hapticFeedback
        testOrder
        maxForce
        QuestionaireFID
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           rng(1000,"twister");
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05; %0.05 %Draw was 0.3
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 2.25;%1.6;
            app.printCounter = 0;
%              fourPolygonLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
%             fourMeshValuesLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\BenResult0_02.csv";
% %             fourPolygonLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
% %             fourMeshValuesLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\BenResult0_01.csv";
%             [locX,locY,flowX,flowY] = ReadInFlowDataFromComsolCSV(fourPolygonLocation, fourMeshValuesLocation);
%             saveME = [locX;locY]';
%             writematrix(saveME,'M.csv') 
        %   app.fd = FlowData([locX; locY]', [flowX; flowY]');
            app.fd = FlowData05();
            %general simulation timer
          %  app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
          %  app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
          %  app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            %app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "TrainingModel";
            InitHaptic();
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0);
            app.timeLimit = 1;%120;
            app.testNumber = 0;
            app.rotation = 0;
            app.hapticFeedback = [0,0,0];
            %50 tests
            app.testOrder = 1:120;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.testOrder = [app.testOrder,length(app.testOrder)+1];
            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);

%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
    %        app.mesh =  alphaShape(app.fd.FlowLocations{1});
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            if(false) %Set to true if needed.
                app.polygon = app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
%             prompt = {'Age','Gender','Corrected Eyesight','Right/Left Handed'};
%             dlgtitle = 'Statistical questions';
%             answer = inputdlg(prompt,dlgtitle);
%             app.QuestionaireFID = fopen("Questionaire" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
%             fprintf(app.QuestionaireFID,strcat('\r\n Age,',answer{1}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Gender,',answer{2}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Corrected Eyesight,',answer{3}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Right/Left Handed,',answer{4}));
            %fclose(app.QuestionaireFID);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            value = app.MagForceRestrictMAM2EditField.Value;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                 set(app.StartTrainingButton,'Text',"Running...");
            app.startTime = clock;
            set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
            app.lastUpdate = clock;
            app.StartTrainingButton.Enable = false;

            %Environment Setup
        %    pyenv("Version","C:\Users\bj20907\AppData\Local\Programs\Python\Python310\python.exe");
            pyenv("Version","C:\Users\beno1\AppData\Local\Programs\Python\Python310\python.exe");
            if count(py.sys.path,'') == 0
                insert(py.sys.path,int32(0),'');
            end

            n_states = 2000; %Too big!!
            n_actions = 8;

            pyrun("from pythontestModified import *")
           % pyrun("obj1 = QLearning(py_n_states, py_n_actions)", py_n_states = n_states, py_n_actions = n_actions)
            pyrun("obj1 = QLearning(4,8)")
            epsilon = pyrun("eps = obj1.epsilon","eps");
            episodeEnd = false;
         %   sdfhsh = pyrun("eps = obj1.n_states","eps")
           %pyrun("obs = obj1.env.reset()")
          %  pyrun("obj1.prev_state = obj1.Discrete(obs[0])") %Setup the initial state - so like, pass in the particle states here
            pyrun("obj1.prev_state = obj1.Discrete(matState)",matState = [sum(app.particleArrayLocation(:,1))./app.numParticles, sum(app.particleArrayLocation(:,2))./app.numParticles, sum(app.particleArrayVelocity(:,1))./app.numParticles, sum(app.particleArrayVelocity(:,2))./app.numParticles])
            while(~ episodeEnd)
                exit_Loop = false;
                while(~ exit_Loop)
                    if (rand() < epsilon)%(np.random.uniform(0,1) < obj1.epsilon) %epsilon
                       % action = pyrun("act = obj1.env.action_space.sample()","act"); %TODO what's this? - random action?
                       action = randi([1 n_actions]);
                    else
                        action = pyrun("act = np.argmax(obj1.q_table[obj1.prev_state])","act");
                    end
                    %This is the action - this is where MATLAB sits - so the above action is passed in, and then the below is called.
                  %  pyrun("res = obj1.env.step(act)", act = action);
                  
                    switch(action) %Go clockwise through n angles - set to a value between 0 and 1, let the program change them to more sensible values.
                        case(0)
                            %Quit now - shouldn't get here.
                            episodeEnd = false;
                            %break;
                        case(1)%Up
                            app.X1MAGauge.Value = 1;
                            app.Y1MAGauge.Value = 0;
                            %break;
                        case(2)
                            app.X1MAGauge.Value = 0.707;
                            app.Y1MAGauge.Value = 0.707;
                            %break;
                        case(3)%Right
                            app.X1MAGauge.Value = 0;
                            app.Y1MAGauge.Value = 1;
                            %break;
                        case(4)
                            app.X1MAGauge.Value = -0.707;
                            app.Y1MAGauge.Value = 0.707;
                            %break;
                        case(5)%Down
                            app.X1MAGauge.Value = -1;
                            app.Y1MAGauge.Value = 0;
                            %break;
                        case(6)
                            app.X1MAGauge.Value = -0.707;
                            app.Y1MAGauge.Value = -0.707;
                            %break;
                        case(7)%Left
                            app.X1MAGauge.Value = 0;
                            app.Y1MAGauge.Value = -1;
                            %break;
                        case(8)
                            app.X1MAGauge.Value = 0.707;
                            app.Y1MAGauge.Value = -0.707;
                            %break;
                    end
                    %TODO - sort all timer stuff here.
                    timerCallback(app);
                    drawTimerCallback(app);
                    pause(0.001);
                    %TODO generate res
                    
                    %50% is closeness to goal state, 50% is the percentagee in the goal
                   % intialDist = pdist([-0.0095 -0.0035;0.00941 -0.00269],'euclidian');
                   %dist = sqrt((x2-x1)^2+(y2-y1)^2)
                   initialDist = sqrt((-0.0095 - 0.00941)^2 + (-0.0035 +0.00269)^2);
                   newDist = sqrt((sum(app.particleArrayLocation(:,1))./app.numParticles - 0.00941)^2 + (sum(app.particleArrayLocation(:,2))./app.numParticles +0.00269)^2);
                   distDiff = abs(newDist/initialDist);
                   if(distDiff < 0)
                       distDiff = 0;
                   end
                   if(distDiff > 1)
                       distDiff = 1;
                   end
                   reward = distDiff/2 + app.PercentageinGoalEditField.Value./200;
                   % newDist = [sum(app.particleArrayLocation(:,1))./app.numParticles, sum(app.particleArrayLocation(:,2))./app.numParticles] - [0.00941 -0.00269];
                   % reward = 

                    exit_Loop = pyrun("el = obj1.Q_LearningActionLoop(matState,matReward,matDone,matInfo)","el", matState = [sum(app.particleArrayLocation(:,1))./app.numParticles, sum(app.particleArrayLocation(:,2))./app.numParticles, sum(app.particleArrayVelocity(:,1))./app.numParticles, sum(app.particleArrayVelocity(:,2))./app.numParticles],matReward = reward,matDone = false,matInfo = false);   
                    %   Above we actually return the action to take, and let matlab determine if we're done or not (res[3])
                    %   Matlab will actually do this loop, we're just contstructing the model in python and accessing it in matlab
                    exit_Loop = app.timePassed > app.timeLimit;
                end    
                episodeEnd = true;
     %           episodeEnd = episodeEnd || pyrun("ee = obj1.episode > obj1.episodes","ee"); %if we need to quit from above, then quit anyway
                %pyrun("obj1.env.reset()");
            end
            %Run general end of level stuff.
          %  stop(app.simulationTimerProperty);
          %  stop(app.drawTimerProperty);
            app.StartTrainingButton.Enable = true;
            set(app.StartTrainingButton,'Text',"Start Next Level");
            set(app.StartTrainingButton,'BackgroundColor',[1,0.7,0.7]);
            fprintf("Time Up! You got: " + app.PercentageinGoalEditField.Value + "/%% of the particles in the goal outlet\r\n");
            delete(app.magLine);
            app.testNumber = app.testNumber + 1;
            NextLevel(app);
          %  start(app.hapticFeedbackIdleProperty);
            else
                addValue = 0.1;
                maxValue = 2.3;
                if value ~= 0
                    addValue = value;
                    maxValue = addValue;
                end
                maxValueNegative = maxValue * -1;
                switch key
                    case 'q'
                        if(app.X1MAGauge.Value < maxValue)
                            app.X1MAGauge.Value = app.X1MAGauge.Value + addValue;
                        end
                    case 'w'
                        if(app.X1MAGauge.Value > maxValueNegative)
                            app.X1MAGauge.Value = app.X1MAGauge.Value - addValue;
                        end
                    case 'e'
                        if(app.X2MAGauge.Value < maxValue)
                            app.X2MAGauge.Value = app.X2MAGauge.Value + addValue;
                        end
                    case 'r'
                        if(app.X2MAGauge.Value > maxValueNegative)
                            app.X2MAGauge.Value = app.X2MAGauge.Value - addValue;
                        end
                    case 'u'
                        if(app.Y1MAGauge.Value < maxValue)
                            app.Y1MAGauge.Value = app.Y1MAGauge.Value + addValue;
                        end
                    case 'i'
                        if(app.Y1MAGauge.Value > maxValueNegative)
                            app.Y1MAGauge.Value = app.Y1MAGauge.Value - addValue;
                        end
                    case 'o'
                        if(app.Y2MAGauge.Value < maxValue)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value + addValue;
                        end
                    case 'p'
                        if(app.Y2MAGauge.Value > maxValueNegative)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value - addValue;
                        end
                    case 'y'
                        app.X1MAGauge.Value = 0;
                        app.X2MAGauge.Value = 0;
                        app.Y1MAGauge.Value = 0;
                        app.Y1MAGauge.Value = 0;
                        app.Z1MAGauge.Value = 0;
                        app.Z2MAGauge.Value = 0;
                        close all;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
               CloseHaptic();
            end
            fclose(app.fileID);
            close all;
            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)

        end

        % Button pushed function: StartTrainingButton
        function StartTrainingOLDButtonValueChanged(app, event)
           
        end

        % Value changed function: FluidFlowmsEditField
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData();
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Value changed function: MagForceRestrictMAM2EditField
        function MagForceRestrictMAM2EditFieldValueChanged(app, event)
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            CloseHaptic();
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
                    InitHaptic();
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
        import java.awt.*;
        import java.awt.event.*;
        %Create a Robot-object to do the key-pressing
        rob=Robot;
        %Commands for pressing keys:
        % If the text cursor isn't in the edit box allready, then it
        % needs to be placed there for ctrl+a to select the text.
        % Therefore, we make sure the cursor is in the edit box by
        % forcing a mouse button press:
        rob.mousePress(InputEvent.BUTTON1_MASK );
        rob.mouseRelease(InputEvent.BUTTON1_MASK );
        % CONTROL + A : 
        rob.keyPress(KeyEvent.VK_SPACE)
        rob.keyRelease(KeyEvent.VK_SPACE)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictMAM2EditField.Value > 0 && app.MagForceRestrictMAM2EditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictMAM2EditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1MAGauge.Value = app.mousePosition(1) .* 250;
                app.Y1MAGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Visible = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Editable = 'off';
            app.ParticleDiametermEditField.Visible = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-2.5 2.5];
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-2.5 2.5];
            app.Y1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Editable = 'off';
            app.StaticFrictionCoefficientEditField.Visible = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Visible = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Editable = 'off';
            app.MotionFrictionCoefficientEditField.Visible = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Visible = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Visible = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Visible = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Editable = 'off';
            app.ParticleMasskgEditField.Visible = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.MagForceRestrictMAM2EditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictMAM2EditFieldLabel.Text = 'MagForceRestrict (MA/M^2)';
            app.MagForceRestrictMAM2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictMAM2EditField.ValueChangedFcn = createCallbackFcn(app, @MagForceRestrictMAM2EditFieldValueChanged, true);
            app.MagForceRestrictMAM2EditField.Editable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowmsEditFieldValueChanged, true);
            app.FluidFlowmsEditField.Editable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.LeftPanel);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationTrainModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end