classdef particleSimulationValidation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ScenarioEditField               matlab.ui.control.EditField
        ScenarioEditFieldLabel          matlab.ui.control.Label
        StartTrainingButton             matlab.ui.control.Button
        FluidFlowmsEditField            matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel       matlab.ui.control.Label
        MagForceRestrictMAM2EditField   matlab.ui.control.NumericEditField
        MagForceRestrictMAM2EditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Y2MAGauge                       matlab.ui.control.LinearGauge
        Y2MAGaugeLabel                  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X2MAGauge                       matlab.ui.control.LinearGauge
        X2MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PercentageinGoalEditField       matlab.ui.control.NumericEditField
        PercentageinGoalEditFieldLabel  matlab.ui.control.Label
        TimeRemainingsEditField         matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

     
    properties (Access = public)
        numParticles
        particleFunctions  
        simulationTimerProperty
        drawTimerProperty        
        particleArrayPreviousLocation
        particleArrayBaseVelocity 
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce 
        fileID
        polygon
        polyLine
        wrongEndLine
        endLine
        goalIndex 
        particlePoints
        loopComplete
        tMax 
        timestep
        timePassed
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        rotation        
        simTimerPeriod
        previousMagforce
        slowDown
        maxForce
        printCounter
        writeToFile
        timeLimit
        timeLag
        magLine
        testNumber
        testOrder
        hapticFeedback
        hapticFeedbackIdleProperty
        UseNetworkForHaptic
        QuestionaireFID
        previousDelta
        lastUpdate
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Setup globals
            rng(1000,"twister");

            app.numParticles = 3; 
            app.simTimerPeriod = 0.05;
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf(app.fileID,'%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.fd = FlowData05();
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
            app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));            
           % app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);     
            app.particleArrayLocation = [-0.0095  -0.00325; -0.0095 -0.0035; -0.0095 -0.00375];
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2); 
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            hold (app.UIAxes, 'on');
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1) 
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timestep = 0;%.0001;
            app.timePassed = 0;
            app.joyStick = 0;
            app.controlMethod = "Keyboard";
            app.mousePosition = [0 0];
            app.rotation = 0;       
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 2.25;
            app.printCounter = 0;
            app.goalIndex = 1;
             app.writeToFile = false;
             app.timeLimit = 1;
            app.timeLag = 0;
            app.magLine = plot(app.UIAxes,0,0);
            app.testNumber = 0;
            app.testOrder = 1:120;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.hapticFeedback = [0,0,0];

            if(false) %Write polygon values to file on PC
                app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
            app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.writeToFile = true;
            app.UseNetworkForHaptic = false;
            app.previousDelta = 0;
            app.testNumber = app.testNumber + 1;
            NextLevel(app);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %Keyboard input. This is limited to space to start the level,
            %or movement keys if that control method is selected
            key = event.Key;
            value = app.MagForceRestrictMAM2EditField.Value;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                %Prepare and start the first level. "NextLevel" sorts the
                %changes from this point on.
                %stop(app.hapticFeedbackIdleProperty);
                set(app.StartTrainingButton,'Text',"Running..."); 
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                start(app.simulationTimerProperty);
                start(app.drawTimerProperty);
                app.StartTrainingButton.Enable = false;
            else
                addValue = 0.1;
                maxValue = 2.3;
                if value ~= 0
                    addValue = value;
                    maxValue = addValue;
                end
                maxValueNegative = maxValue * -1;
                switch key
                    case 'q'
                        if(app.X1MAGauge.Value < maxValue)
                            app.X1MAGauge.Value = app.X1MAGauge.Value + addValue;
                        end
                    case 'w'
                        if(app.X1MAGauge.Value > maxValueNegative)
                            app.X1MAGauge.Value = app.X1MAGauge.Value - addValue;
                        end
                    case 'e'
                        if(app.X2MAGauge.Value < maxValue)
                            app.X2MAGauge.Value = app.X2MAGauge.Value + addValue;
                        end
                    case 'r'
                        if(app.X2MAGauge.Value > maxValueNegative)
                            app.X2MAGauge.Value = app.X2MAGauge.Value - addValue;
                        end
                    case 'u'
                        if(app.Y1MAGauge.Value < maxValue)
                            app.Y1MAGauge.Value = app.Y1MAGauge.Value + addValue;
                        end
                    case 'i'
                        if(app.Y1MAGauge.Value > maxValueNegative)
                            app.Y1MAGauge.Value = app.Y1MAGauge.Value - addValue;
                        end
                    case 'o'
                        if(app.Y2MAGauge.Value < maxValue)
                            app.Y2MAGauge.Value = app.Y2MAGauge.Value + addValue;
                        end
                    case 'p' 
                        if(app.Y2MAGauge.Value > maxValueNegative)
                             app.Y2MAGauge.Value = app.Y2MAGauge.Value - addValue;
                        end
                    case 'y'
                        app.X1MAGauge.Value = 0;
                        app.X2MAGauge.Value = 0;
                        app.Y1MAGauge.Value = 0;
                        app.Y1MAGauge.Value = 0;
                        app.Z1MAGauge.Value = 0;
                        app.Z2MAGauge.Value = 0;
                        close all;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %stop(app.hapticFeedbackIdleProperty)
            stop(app.simulationTimerProperty)
            stop(app.drawTimerProperty)
            % if(app.ButtonGroup.SelectedObject.Text == "Controller")
            %    CloseHaptic();
            % end
            fclose(app.fileID);
            close all;

            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            %Performs initialisation on the globals when the level changes
            app.PlayPauseButton.Value = 0;
            app.PlayPauseButtonValueChanged([]);
            value = app.ScenarioDropDown.Value;
            if(value == "Experiment 1")
                app.polygon = app.polygon.change(2);
            else 
                if(value == "Experiment 2")
                    app.polygon = app.polygon.change(3);
                else 
                    if(value == "Experiment 3")
                        app.polygon = app.polygon.change(4);
                    else
                        app.polygon = app.polygon.change(1);
                    end
                end
            end
            delete(app.polyLine);
            delete(app.endLine);
            hold (app.UIAxes, 'on');            
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            %reset particle position & velocity to start area
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, length(app.particleArrayLocation));
            app.particleArrayVelocity = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayForce = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayPreviousAcceleration = zeros(length(app.particleArrayLocation), 2);
            
        end

        % Callback function
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if(value)
                
                %This function is used to start the validation going.
                %The timestep is limited in here.      
                
                set(app.PlayPauseButton,'BackgroundColor',[0.7,1,0.7]);                
                
                  %Do other things with timing here, must set tMax for example.
                app.timestep = 0.01;
                
                forces = [0.45, 0.5, 0.75, 1, 1.5, 2, 2.25];
                
                fprintf(app.fileID, "MASS: " + app.ParticleMasskgEditField.Value + " SIZE: " + app.ParticleDiametermEditField.Value + "\r\n");
                    
                %Loop over each parameter being checked - example below is
                %maximum magnitude of magnetic force
                  %loop over each force
                for index = 1:length(forces)
                    app.particleArrayVelocity = [1 0;1 0; 1 0] .* 0; %seed velocity here
                    app.startTime = posixtime(datetime(datestr(now)));
                    app.lastUpdate = clock;
                    fprintf(app.fileID, "FORCE:" + forces(index) + "\r\n");
                      %Set force in the x direction
                    app.X1MAGauge.Value = forces(index);
                    loopIndex = 1;
                    
                    while(~any(app.haltParticlesInEndZone) & (app.timePassed < 8))
                        app.lastUpdate(6) = app.lastUpdate(6) - app.timestep;%0.0001; 
                        timerCallback(app);
                        if(mod(loopIndex,1) == 0)      %Higher modulo number for faster                      
                            drawTimerCallback(app);
                            pause(0.1);
                        end
                        loopIndex = loopIndex + 1;
                    end
                    app.timePassed = 0;
                    app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, length(app.particleArrayLocation));
                    app.particleArrayVelocity = zeros(length(app.particleArrayLocation), 2);
                    app.particleArrayForce = zeros(length(app.particleArrayLocation), 2);
                    app.particleArrayPreviousLocation = app.particleArrayLocation;
                    app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
                    app.particleArrayBaseVelocityStartTime = clock;
                    app.particleArrayPreviousDragForce = zeros(length(app.particleArrayLocation), 2);
                    app.particleArrayPreviousAcceleration = zeros(length(app.particleArrayLocation), 2);
                    app.haltParticlesInEndZone = 0 .* app.haltParticlesInEndZone;
                end

                %Now close the app
                fclose(app.fileID);
                close all;
                delete(app)
                disp("App Stopped")
            else
                set(app.PlayPauseButton,'BackgroundColor',[1,0.7,0.7]);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Enable = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Enable = 'off';
            app.ParticleDiametermEditField.Value = 5.769e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-2.5 2.5];
            app.X1MAGauge.MajorTicks = [-2.5 0 2.5];
            app.X1MAGauge.MajorTickLabels = {'-2.5', '0', '2.5'};
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.X2MAGaugeLabel = uilabel(app.LeftPanel);
            app.X2MAGaugeLabel.Enable = 'off';
            app.X2MAGaugeLabel.Visible = 'off';
            app.X2MAGaugeLabel.Text = 'X2 (MA)';
            app.X2MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X2MAGauge.Limits = [-1 1];
            app.X2MAGauge.Enable = 'off';
            app.X2MAGauge.Visible = 'off';
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-2.5 2.5];
            app.Y1MAGauge.MajorTicks = [-2.5 0 2.5];
            app.Y1MAGauge.MajorTickLabels = {'-2.5', '0', '2.5'};
            app.Y1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y2MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y2MAGaugeLabel.Enable = 'off';
            app.Y2MAGaugeLabel.Visible = 'off';
            app.Y2MAGaugeLabel.Text = 'Y2 (MA)';
            app.Y2MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y2MAGauge.Limits = [-1 1];
            app.Y2MAGauge.Enable = 'off';
            app.Y2MAGauge.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Enable = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Enable = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Enable = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Enable = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Enable = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Enable = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Enable = 'off';
            app.ParticleMasskgEditField.Value = 2.2619e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training', 'Experiment 1', 'Experiment 2', 'Experiment 3'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training';
            app.MagForceRestrictMAM2EditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictMAM2EditFieldLabel.Text = 'MagForceRestrict (MA/M^2)';
            app.MagForceRestrictMAM2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictMAM2EditField.Editable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.Editable = 'off';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.Text = 'Start Training';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.00096 0.00096];
            app.UIAxes.YLim = [-0.00096 0.00096];
            app.UIAxes.ZLim = [-0.00096 0.00096];
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.PercentageinGoalEditFieldLabel = uilabel(app.RightPanel);
            app.PercentageinGoalEditFieldLabel.Text = {'Percentage'; 'in Goal'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.Editable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofParticlesEditFieldLabel.Enable = 'off';
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Enable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationValidation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end